classdef BZNavi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AppTitle                      matlab.ui.control.Label
        CLOSEFIGS_Button              matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        RESIZEGUI_Button              matlab.ui.control.Button
        ARPESGUI_Button               matlab.ui.control.Button
        BrilluoinZoneExtractionPanel  matlab.ui.container.Panel
        Extract2DBZ_Button            matlab.ui.control.Button
        Extract3DBZ_Button            matlab.ui.control.Button
        CrystalDropDownLabel          matlab.ui.control.Label
        Crystal_DropDown              matlab.ui.control.DropDown
        PlaneSlicehklDropDownLabel    matlab.ui.control.Label
        PlaneSlice_DropDown           matlab.ui.control.DropDown
        aAngLabel                     matlab.ui.control.Label
        a_EditField                   matlab.ui.control.NumericEditField
        bAngLabel                     matlab.ui.control.Label
        b_EditField                   matlab.ui.control.NumericEditField
        cAngLabel                     matlab.ui.control.Label
        c_EditField                   matlab.ui.control.NumericEditField
        alphadegLabel                 matlab.ui.control.Label
        alpha_EditField               matlab.ui.control.NumericEditField
        betadegLabel                  matlab.ui.control.Label
        beta_EditField                matlab.ui.control.NumericEditField
        gammadegLabel                 matlab.ui.control.Label
        gamma_EditField               matlab.ui.control.NumericEditField
        BrilluoinZoneNavigationPanel  matlab.ui.container.Panel
        V000eVLabel                   matlab.ui.control.Label
        V000_EditField                matlab.ui.control.NumericEditField
        ARPESgeometryPanel            matlab.ui.container.Panel
        ePhieVEditFieldLabel          matlab.ui.control.Label
        ePhi_EditField                matlab.ui.control.NumericEditField
        alphadegEditField_2Label      matlab.ui.control.Label
        alpha2_EditField              matlab.ui.control.NumericEditField
        thtMdegEditFieldLabel         matlab.ui.control.Label
        thtM_EditField                matlab.ui.control.NumericEditField
        tltMdegEditFieldLabel         matlab.ui.control.Label
        tltM_EditField                matlab.ui.control.NumericEditField
        eBrefeVLabel                  matlab.ui.control.Label
        eBref_EditField               matlab.ui.control.NumericEditField
        thtArefdegLabel               matlab.ui.control.Label
        thtAref_EditField             matlab.ui.control.NumericEditField
        kxrefAng1EditFieldLabel       matlab.ui.control.Label
        kxref_EditField               matlab.ui.control.NumericEditField
        ExtractARPES_Button           matlab.ui.control.Button
        hveVEditFieldLabel            matlab.ui.control.Label
        hv_EditField                  matlab.ui.control.EditField
        IMFPAngLabel                  matlab.ui.control.Label
        IMFP_EditField                matlab.ui.control.NumericEditField
        dKzAng1Label                  matlab.ui.control.Label
        dKz_EditField                 matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
        SAVE_Button                   matlab.ui.control.Button
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
    end

    % Properties that correspond to public variables within the app
    properties (Access = public)
        % Defining the argument structures
        crystal_args = cell(1,4);
        navi_args = struct();
        % Defining the main data structure
        myData = struct();
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 1 - Initialisation of variables
            % - Crystal arguments
            app.crystal_args{1}         = app.Crystal_DropDown.Value;
            app.crystal_args{2}         = app.PlaneSlice_DropDown.Value;
            app.crystal_args{3}(1)      = app.a_EditField.Value;
            app.crystal_args{3}(2)      = app.b_EditField.Value;
            app.crystal_args{3}(3)      = app.c_EditField.Value;
            app.crystal_args{4}(1)      = app.alpha_EditField.Value;
            app.crystal_args{4}(2)      = app.beta_EditField.Value;
            app.crystal_args{4}(3)      = app.gamma_EditField.Value;
            % - Navigation arguments
            app.navi_args.ePhi          = app.ePhi_EditField.Value;
            app.navi_args.alpha         = app.alpha2_EditField.Value;
            app.navi_args.thtM          = app.thtM_EditField.Value;
            app.navi_args.tltM          = app.tltM_EditField.Value;
            app.navi_args.eBref         = app.eBref_EditField.Value;
            app.navi_args.thtAref       = app.thtAref_EditField.Value;
            app.navi_args.kxref         = app.kxref_EditField.Value;
            app.navi_args.V000          = app.V000_EditField.Value;
            app.navi_args.hv            = round(sort(str2double(split(app.hv_EditField.Value,':'))), 4);
            % - Updating IMFP and dKz
            % - Determination of IMFP and Kz broadening
            eKE = app.navi_args.hv - app.navi_args.ePhi + app.navi_args.eBref;
            imfp = 10 .* (143 ./ eKE .^2 + 0.054.*sqrt(eKE));
            dkz = 1 ./ imfp;
            app.IMFP_EditField.Value = round(mean(imfp), 5);
            app.dKz_EditField.Value = round(mean(dkz), 5);
%             %% 2 - Plotting the ARPES geometry figure
%             IS = imread('arpes_geometry.png');
%             image(app.ARPESgeometry_Axes, IS);
%             axis(app.ARPESgeometry_Axes, 'off', 'image');
        end

        % Button pushed function: ARPESGUI_Button
        function ARPESGUI_ButtonPushed(app, event)
            run ARPESGUI;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            %% 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            %% 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            %% Remove the GUI handle and restart
            close(gcbf); run BZNavi;
        end

        % Button pushed function: RESIZEGUI_Button
        function RESIZEGUI_ButtonPushed(app, event)
            %% Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 510;
            screen_size(4) = 550;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'arpes_data', app.myData);
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('APP : '); disp(app);
            disp('DATA (app.myData) : '); disp(app.myData);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = app.myData; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.myData);
            close(wbar);
        end

        % Value changed function: Crystal_DropDown
        function Crystal_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Crystal_DropDown.Value;
            
            %% 2 - Changing the GUI elements based on the selection
            % For 'CUB', 'BCC' and 'FCC' crystal
            if value == "CUB-cP-Oh" || value == "CUB-cI-Oh" || value == "CUB-cF-Oh"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'HEX' crystal
            elseif value == "HEX-hP-D6h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'RHL' crystal
            elseif value == "RHL-hR-D3d"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'TET' and 'BCT' crystal
            elseif value == "TET-tP-D4h" || value == "TET-tI-D4h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif value == "ORC-oP-D2h" || value == "ORC-oS-D2h" || value == "ORC-oI-D2h" || value == "ORC-oF-D2h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'MCL' and 'MCLC' crystal
            elseif value == "MCL-mP-C2h" || value == "MCL-mS-C2h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'off';
            % For 'TRI' crystal
            elseif value == "TRI-aP-Ci" 
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'on';
            end
            % - Setting the value of the argument
            app.crystal_args{1} = value;
            
            %% 3 - Run through all crystallographic inputs to update their values
            a_EditFieldValueChanged(app);
            b_EditFieldValueChanged(app);
            c_EditFieldValueChanged(app);
            alpha_EditFieldValueChanged(app);
            beta_EditFieldValueChanged(app);
            gamma_EditFieldValueChanged(app);
            
            %% 4 - Printing the change that has occurred
            fprintf("--> Crystal: " + app.crystal_args{1} + " \n");
        end

        % Value changed function: PlaneSlice_DropDown
        function PlaneSlice_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.PlaneSlice_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.crystal_args{2} = value;
            fprintf("--> Plane Slice: " + app.crystal_args{2} + " \n");
        end

        % Value changed function: a_EditField
        function a_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.a_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-cP-Oh" || app.crystal_args{1} == "CUB-cI-Oh" || app.crystal_args{1} == "CUB-cF-Oh"
                % - a == b & c
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-hP-D6h"
                % - a == b ONLY
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-hR-D3d"
                % - a == b & c
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-tP-D4h" || app.crystal_args{1} == "TET-tI-D4h"
                % - a == b AND a =/= c
                if value == app.crystal_args{3}(3); value = value - 0.001; end
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-oP-D2h" || app.crystal_args{1} == "ORC-oS-D2h" || app.crystal_args{1} == "ORC-oI-D2h" || app.crystal_args{1} == "ORC-oF-D2h"
                % - a =/= b AND a =/= c
                if value == app.crystal_args{3}(2); value = value - 0.001; end
                if value == app.crystal_args{3}(3); value = value - 0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-mP-C2h" || app.crystal_args{1} == "MCL-mS-C2h"
                % - a =/= c ONLY
                if value == app.crystal_args{3}(3); value = value - 0.001; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-aP-Ci"
                % - No conditions for the 'TRI' crystal for a
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(1) = value;
            fprintf("--> a (Ang): " + app.crystal_args{3}(1) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: b_EditField
        function b_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.b_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-cP-Oh" || app.crystal_args{1} == "CUB-cI-Oh" || app.crystal_args{1} == "CUB-cF-Oh"
                % - b == a & c
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-hP-D6h"
                % - b == a ONLY
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-hR-D3d"
                % - b == a & c
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-tP-D4h" || app.crystal_args{1} == "TET-tI-D4h"
                % - b == a AND b =/= c
                if value == app.crystal_args{3}(3); value = value-0.001; end
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-oP-D2h" || app.crystal_args{1} == "ORC-oS-D2h" || app.crystal_args{1} == "ORC-oI-D2h" || app.crystal_args{1} == "ORC-oF-D2h"
                % - b =/= a AND b =/= c
                if value == app.crystal_args{3}(1); value = value-0.001; end
                if value == app.crystal_args{3}(3); value = value-0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-mP-C2h" || app.crystal_args{1} == "MCL-mS-C2h"
                % - No conditions for the 'MCL' or 'MCLC' crystal for b
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-aP-Ci"
                 % - No conditions for the 'TRI' crystal for b
            end
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(2) = value;
            fprintf("--> b (Ang): " + app.crystal_args{3}(2) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: c_EditField
        function c_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.c_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-cP-Oh" || app.crystal_args{1} == "CUB-cI-Oh" || app.crystal_args{1} == "CUB-cF-Oh"
                % - c == a & b
                if value ~= app.crystal_args{2}(1); app.crystal_args{2}(1) = value; end
                if value ~= app.crystal_args{2}(2); app.crystal_args{2}(2) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-hP-D6h"
                % - No conditions for the 'HEX' crystal for c(nm)
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-hR-D3d"
                % - c == a & b
                if value ~= app.crystal_args{2}(1); app.crystal_args{2}(1) = value; end
                if value ~= app.crystal_args{2}(2); app.crystal_args{2}(2) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-tP-D4h" || app.crystal_args{1} == "TET-tI-D4h"
                % - c =/= a
                if value == app.crystal_args{2}(1); value = value-0.001; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-oP-D2h" || app.crystal_args{1} == "ORC-oS-D2h" || app.crystal_args{1} == "ORC-oI-D2h" || app.crystal_args{1} == "ORC-oF-D2h"
                % - c =/= a
                if value == app.crystal_args{3}(1); value = value-0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-mP-C2h" || app.crystal_args{1} == "MCL-mS-C2h"
                % - c =/= a
                if value == app.crystal_args{3}(1); value = value-0.001; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-aP-Ci"
                % - No conditions for the 'TRI' crystal for c(nm)
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(3) = value;
            fprintf("--> c (Ang): " + app.crystal_args{3}(3) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
            
        end

        % Value changed function: alpha_EditField
        function alpha_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.alpha_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-cP-Oh" || app.crystal_args{1} == "CUB-cI-Oh" || app.crystal_args{1} == "CUB-cF-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-hP-D6h"
                % - Applying condition that alpha must be 90degrees and equal to beta
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-hR-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-tP-D4h" || app.crystal_args{1} == "TET-tI-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-oP-D2h" || app.crystal_args{1} == "ORC-oS-D2h" || app.crystal_args{1} == "ORC-oI-D2h" || app.crystal_args{1} == "ORC-oF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-mP-C2h" || app.crystal_args{1} == "MCL-mS-C2h"
                % - Applying condition that alpha must be 90degrees and equal to gamma
                value = 90;
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-aP-Ci"
                % - No conditions for the 'TRI' crystal for alpha
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(1) = value;
            fprintf("--> alpha (deg): " + app.crystal_args{4}(1) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Value changed function: beta_EditField
        function beta_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.beta_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-cP-Oh" || app.crystal_args{1} == "CUB-cI-Oh" || app.crystal_args{1} == "CUB-cF-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-hP-D6h"
                % - Applying condition that alpha must be 90degrees and equal to beta
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-hR-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-tP-D4h" || app.crystal_args{1} == "TET-tI-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-oP-D2h" || app.crystal_args{1} == "ORC-oS-D2h" || app.crystal_args{1} == "ORC-oI-D2h" || app.crystal_args{1} == "ORC-oF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-mP-C2h" || app.crystal_args{1} == "MCL-mS-C2h"
                % - Applying condition that beta must not be 90 degrees
                if value == 90; value = value - 1; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-aP-Ci"
                % - No conditions for the 'TRI' crystal for beta
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(2) = value;
            fprintf("--> beta (deg): " + app.crystal_args{4}(2) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Value changed function: gamma_EditField
        function gamma_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.gamma_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-cP-Oh" || app.crystal_args{1} == "CUB-cI-Oh" || app.crystal_args{1} == "CUB-cF-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-hP-D6h"
                % - Applying condition that gamma must be equal to 120 degrees
                value = 120;
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-hR-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-tP-D4h" || app.crystal_args{1} == "TET-tI-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-oP-D2h" || app.crystal_args{1} == "ORC-oS-D2h" || app.crystal_args{1} == "ORC-oI-D2h" || app.crystal_args{1} == "ORC-oF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-mP-C2h" || app.crystal_args{1} == "MCL-mS-C2h"
                % - Applying condition that alpha must be equal to gamma and 90 degrees
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-aP-Ci"
                % - No conditions for the 'TRI' crystal for beta
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(3) = value;
            fprintf("--> gamma (deg): " + app.crystal_args{4}(3) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Button pushed function: Extract2DBZ_Button
        function Extract2DBZ_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            app.myData.crystal_args = app.crystal_args;
            [app.myData.realStr, app.myData.reciStr] = extract_lattice({app.myData.crystal_args{1}, app.myData.crystal_args{3}, app.myData.crystal_args{4}});
            %% 2 - Extracting the 2D Brilluoin Zone planar slice
            app.myData.planeStr = extract_bz_overlay(app.myData.reciStr, app.myData.crystal_args{2}, 1);
        end

        % Button pushed function: Extract3DBZ_Button
        function Extract3DBZ_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            app.myData.crystal_args = app.crystal_args;
            [app.myData.realStr, app.myData.reciStr] = extract_lattice({app.myData.crystal_args{1}, app.myData.crystal_args{3}, app.myData.crystal_args{4}}, 1);
        end

        % Value changed function: ePhi_EditField
        function ePhi_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ePhi_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.ePhi = value;
            fprintf("--> ePhi (eV): " + app.navi_args.ePhi + " \n");
        end

        % Value changed function: alpha2_EditField
        function alpha2_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.alpha2_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.alpha = value;
            fprintf("--> alpha (deg.): " + app.navi_args.alpha + " \n");
        end

        % Value changed function: thtM_EditField
        function thtM_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.thtM_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.thtM = value;
            fprintf("--> thtM (deg.): " + app.navi_args.thtM + " \n");
        end

        % Value changed function: tltM_EditField
        function tltM_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.tltM_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.tltM = value;
            fprintf("--> tltM (deg.): " + app.navi_args.tltM + " \n");
        end

        % Value changed function: eBref_EditField
        function eBref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.eBref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.eBref = value;
            fprintf("--> eBref (eV): " + app.navi_args.eBref + " \n");
        end

        % Value changed function: thtAref_EditField
        function thtAref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.thtAref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.thtAref = value;
            fprintf("--> thtAref (deg.): " + app.navi_args.thtAref + " \n");
        end

        % Value changed function: kxref_EditField
        function kxref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kxref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.kxref = value;
            fprintf("--> kxref (Ang^-1): " + app.navi_args.kxref + " \n");
        end

        % Value changed function: V000_EditField
        function V000_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.V000_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.V000 = value;
            fprintf("--> V000 (eV): " + app.navi_args.V000 + " \n");
        end

        % Value changed function: hv_EditField
        function hv_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.hv_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Validity check on input
            minVal = 8;
            maxVal = 1e5;
            % - If no entry is made, default to a single hv value
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = 400;
            % - If a single entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 1
                if isnan(data_entry(1)); data_entry(1) = 400; end
                if data_entry(1) < minVal; data_entry(1) = minVal; end
                if data_entry(1) > maxVal; data_entry(1) = maxVal; end
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                if data_entry(1) < minVal ||  data_entry(1) > maxVal || isnan(data_entry(1)); data_entry(1) = minVal; end
                if data_entry(2) < minVal ||  data_entry(2) > maxVal || isnan(data_entry(1)); data_entry(2) = maxVal; end
            end
            %% 3 - Assigning and printing the output
            app.navi_args.hv = data_entry;
            if length(app.navi_args.hv) == 1
                app.hv_EditField.Value = string(app.navi_args.hv); 
                fprintf("--> hv (eV): " + string(app.navi_args.hv) + " \n");
            elseif length(app.navi_args.hv) == 2
                app.hv_EditField.Value = string(app.navi_args.hv(1) + ":" + app.navi_args.hv(2)); 
                fprintf("--> hv (eV): " + string(app.navi_args.hv(1) + ":" + app.navi_args.hv(2)) + " \n");
            end
            %% 4 - Setting the IMFP and Kz broadening
            % - Determination of IMFP and Kz broadening
            eKE = app.navi_args.hv - app.navi_args.ePhi + app.navi_args.eBref;
            imfp = 10 .* (143 ./ eKE .^2 + 0.054.*sqrt(eKE));
            dkz = 1 ./ imfp;
            app.IMFP_EditField.Value = round(mean(imfp), 5);
            app.dKz_EditField.Value = round(mean(dkz), 5);
        end

        % Button pushed function: ExtractARPES_Button
        function ExtractARPES_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            app.myData.crystal_args = app.crystal_args;
            [app.myData.realStr, app.myData.reciStr] = extract_lattice({app.myData.crystal_args{1}, app.myData.crystal_args{3}, app.myData.crystal_args{4}});
            %% 2 - Extracting the 2D Brilluoin Zone planar slice
            app.myData.planeStr = extract_bz_overlay(app.myData.reciStr, app.myData.crystal_args{2});
            %% 3 - Extracting and viewing the ARPES cut that is taken
            app.myData.navi_args    = app.navi_args;
            app.myData.arpesStr     = extract_hv_overlay(app.navi_args.hv, app.myData.navi_args, app.myData.planeStr);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'Brilluoin Zone Navigation';
            app.CLOSEFIGS_Button = uibutton(app.UIFigure, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Closes all open figures, but leaves the current one open.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.UIFigure, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Restarts the app to its default state.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEGUI_Button = uibutton(app.UIFigure, 'push');
            app.RESIZEGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEGUI_ButtonPushed, true);
            app.RESIZEGUI_Button.Tooltip = {'Resizes the app to its default size.'};
            app.RESIZEGUI_Button.Text = 'RESIZE GUI';
            app.ARPESGUI_Button = uibutton(app.UIFigure, 'push');
            app.ARPESGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESGUI_ButtonPushed, true);
            app.ARPESGUI_Button.Tooltip = {'Back to the main ARPESGUI menu.'};
            app.ARPESGUI_Button.Text = 'ARPESGUI (main)';
            app.BrilluoinZoneExtractionPanel = uipanel(app.UIFigure);
            app.BrilluoinZoneExtractionPanel.Title = 'Brilluoin Zone Extraction';
            app.Extract2DBZ_Button = uibutton(app.BrilluoinZoneExtractionPanel, 'push');
            app.Extract2DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @Extract2DBZ_ButtonPushed, true);
            app.Extract2DBZ_Button.Tooltip = {'Extract the 2D slice through the Brilluoin Zone of the crystal structure defined through the plane slice (hkl).'};
            app.Extract2DBZ_Button.Text = 'Extract / View 2D BZ';
            app.Extract3DBZ_Button = uibutton(app.BrilluoinZoneExtractionPanel, 'push');
            app.Extract3DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @Extract3DBZ_ButtonPushed, true);
            app.Extract3DBZ_Button.Tooltip = {'Extract the 3D Brilluoin Zone of the crystal structure defined.'};
            app.Extract3DBZ_Button.Text = 'Extract / View 3D BZ';
            app.CrystalDropDownLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.CrystalDropDownLabel.Text = 'Crystal:';
            app.Crystal_DropDown = uidropdown(app.BrilluoinZoneExtractionPanel);
            app.Crystal_DropDown.Items = {'CUB-cP-Oh', 'CUB-cI-Oh', 'CUB-cF-Oh', 'HEX-hP-D6h', 'RHL-hR-D3d', 'TET-tP-D4h', 'TET-tI-D4h', 'ORC-oP-D2h', 'ORC-oS-D2h', 'ORC-oI-D2h', 'ORC-oF-D2h', 'MCL-mP-C2h', 'MCL-mS-C2h', 'TRI-aP-Ci'};
            app.Crystal_DropDown.ValueChangedFcn = createCallbackFcn(app, @Crystal_DropDownValueChanged, true);
            app.Crystal_DropDown.Tooltip = {'Real-space crystal structure.'};
            app.Crystal_DropDown.Value = 'CUB-cF-Oh';
            app.PlaneSlicehklDropDownLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.PlaneSlicehklDropDownLabel.Text = 'Plane Slice (hkl):';
            app.PlaneSlice_DropDown = uidropdown(app.BrilluoinZoneExtractionPanel);
            app.PlaneSlice_DropDown.Items = {'(001)', '(010)', '(100)', '(110)'};
            app.PlaneSlice_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneSlice_DropDownValueChanged, true);
            app.PlaneSlice_DropDown.Tooltip = {'Miller place slice through the real-space crystal.'};
            app.PlaneSlice_DropDown.Value = '(001)';
            app.aAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.aAngLabel.Text = 'a (Ang.):';
            app.a_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.a_EditField.Limits = [0 Inf];
            app.a_EditField.ValueDisplayFormat = '%.4f';
            app.a_EditField.ValueChangedFcn = createCallbackFcn(app, @a_EditFieldValueChanged, true);
            app.a_EditField.Tooltip = {'Lattice constant in Angstroms along the x-axis (or crystal vector a).'};
            app.a_EditField.Value = 5.431;
            app.bAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.bAngLabel.Text = 'b (Ang.):';
            app.b_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.b_EditField.Limits = [0 Inf];
            app.b_EditField.ValueDisplayFormat = '%.4f';
            app.b_EditField.ValueChangedFcn = createCallbackFcn(app, @b_EditFieldValueChanged, true);
            app.b_EditField.Enable = 'off';
            app.b_EditField.Tooltip = {'Lattice constant in Angstroms along the y-axis (or crystal vector b).'};
            app.b_EditField.Value = 5.431;
            app.cAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.cAngLabel.Text = 'c(Ang.):';
            app.c_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.c_EditField.Limits = [0 Inf];
            app.c_EditField.ValueDisplayFormat = '%.4f';
            app.c_EditField.ValueChangedFcn = createCallbackFcn(app, @c_EditFieldValueChanged, true);
            app.c_EditField.Enable = 'off';
            app.c_EditField.Tooltip = {'Lattice constant in Angstroms along the z-axis (or crystal vector c).'};
            app.c_EditField.Value = 5.431;
            app.alphadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.alphadegLabel.Text = 'alpha (deg.):';
            app.alpha_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.alpha_EditField.Limits = [-180 180];
            app.alpha_EditField.ValueDisplayFormat = '%.4f';
            app.alpha_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_EditFieldValueChanged, true);
            app.alpha_EditField.Enable = 'off';
            app.alpha_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector a, relative to the x-axis.'};
            app.alpha_EditField.Value = 90;
            app.betadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.betadegLabel.Text = 'beta (deg.):';
            app.beta_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.beta_EditField.Limits = [-180 180];
            app.beta_EditField.ValueDisplayFormat = '%.4f';
            app.beta_EditField.ValueChangedFcn = createCallbackFcn(app, @beta_EditFieldValueChanged, true);
            app.beta_EditField.Enable = 'off';
            app.beta_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector b, relative to the y-axis.'};
            app.beta_EditField.Value = 90;
            app.gammadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.gammadegLabel.Text = 'gamma (deg.):';
            app.gamma_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.gamma_EditField.Limits = [-180 180];
            app.gamma_EditField.ValueDisplayFormat = '%.4f';
            app.gamma_EditField.ValueChangedFcn = createCallbackFcn(app, @gamma_EditFieldValueChanged, true);
            app.gamma_EditField.Enable = 'off';
            app.gamma_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector c, relative to the z-axis.'};
            app.gamma_EditField.Value = 90;
            app.BrilluoinZoneNavigationPanel = uipanel(app.UIFigure);
            app.BrilluoinZoneNavigationPanel.Title = 'Brilluoin Zone Navigation';
            app.V000eVLabel = uilabel(app.BrilluoinZoneNavigationPanel);
            app.V000eVLabel.Text = 'V000 (eV):';
            app.V000_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'numeric');
            app.V000_EditField.Limits = [0 Inf];
            app.V000_EditField.ValueDisplayFormat = '%.4f';
            app.V000_EditField.ValueChangedFcn = createCallbackFcn(app, @V000_EditFieldValueChanged, true);
            app.V000_EditField.Tooltip = {'Inner potential which is usually tuned for each sample so that the ARPES states match the Brilluoin zone.'};
            app.V000_EditField.Value = 12.57;
            app.ARPESgeometryPanel = uipanel(app.BrilluoinZoneNavigationPanel);
            app.ARPESgeometryPanel.Title = 'ARPES geometry';
            app.ePhieVEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.ePhieVEditFieldLabel.Text = 'ePhi (eV):';
            app.ePhi_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.ePhi_EditField.Limits = [0 Inf];
            app.ePhi_EditField.ValueDisplayFormat = '%.4f';
            app.ePhi_EditField.ValueChangedFcn = createCallbackFcn(app, @ePhi_EditFieldValueChanged, true);
            app.ePhi_EditField.Tooltip = {'Sample work function.'};
            app.ePhi_EditField.Value = 4.5;
            app.alphadegEditField_2Label = uilabel(app.ARPESgeometryPanel);
            app.alphadegEditField_2Label.Text = 'alpha (deg.):';
            app.alpha2_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.alpha2_EditField.Limits = [-180 180];
            app.alpha2_EditField.ValueDisplayFormat = '%.4f';
            app.alpha2_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha2_EditFieldValueChanged, true);
            app.alpha2_EditField.Tooltip = {'Incline of the analyser axis'};
            app.alpha2_EditField.Value = 20;
            app.thtMdegEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.thtMdegEditFieldLabel.Text = 'thtM (deg.):';
            app.thtM_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.thtM_EditField.Limits = [-180 180];
            app.thtM_EditField.ValueDisplayFormat = '%.4f';
            app.thtM_EditField.ValueChangedFcn = createCallbackFcn(app, @thtM_EditFieldValueChanged, true);
            app.thtM_EditField.Tooltip = {'Primary manipulator rotation (generally 0).'};
            app.tltMdegEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.tltMdegEditFieldLabel.Text = 'tltM (deg.):';
            app.tltM_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.tltM_EditField.Limits = [-180 180];
            app.tltM_EditField.ValueDisplayFormat = '%.4f';
            app.tltM_EditField.ValueChangedFcn = createCallbackFcn(app, @tltM_EditFieldValueChanged, true);
            app.tltM_EditField.Tooltip = {'Primary manipulator tilt (generally 0).'};
            app.eBrefeVLabel = uilabel(app.ARPESgeometryPanel);
            app.eBrefeVLabel.Text = 'eB ref (eV):';
            app.eBref_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.eBref_EditField.Limits = [0 Inf];
            app.eBref_EditField.ValueDisplayFormat = '%.4f';
            app.eBref_EditField.ValueChangedFcn = createCallbackFcn(app, @eBref_EditFieldValueChanged, true);
            app.eBref_EditField.Tooltip = {'Binding energy reference of a known ARPES state.'};
            app.thtArefdegLabel = uilabel(app.ARPESgeometryPanel);
            app.thtArefdegLabel.Text = 'thtA ref (deg.):';
            app.thtAref_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.thtAref_EditField.Limits = [-180 180];
            app.thtAref_EditField.ValueDisplayFormat = '%.4f';
            app.thtAref_EditField.ValueChangedFcn = createCallbackFcn(app, @thtAref_EditFieldValueChanged, true);
            app.thtAref_EditField.Tooltip = {'Angle along the analyser slit to a reference ARPES feature (equivalent to the angle in an ARPES scan).'};
            app.kxrefAng1EditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.kxrefAng1EditFieldLabel.Text = 'kx ref (Ang.^-1):';
            app.kxref_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.kxref_EditField.Limits = [-180 180];
            app.kxref_EditField.ValueDisplayFormat = '%.4f';
            app.kxref_EditField.ValueChangedFcn = createCallbackFcn(app, @kxref_EditFieldValueChanged, true);
            app.kxref_EditField.Tooltip = {'Kx value of the angle reference in the ARPES feature of thtA.'};
            app.ExtractARPES_Button = uibutton(app.BrilluoinZoneNavigationPanel, 'push');
            app.ExtractARPES_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractARPES_ButtonPushed, true);
            app.ExtractARPES_Button.Tooltip = {'Extract the ARPES Brilluoin Zone slice over a given photon energy range.'};
            app.ExtractARPES_Button.Text = 'Extract ARPES BZ Slice';
            app.hveVEditFieldLabel = uilabel(app.BrilluoinZoneNavigationPanel);
            app.hveVEditFieldLabel.Text = 'hv (eV):';
            app.hv_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'text');
            app.hv_EditField.ValueChangedFcn = createCallbackFcn(app, @hv_EditFieldValueChanged, true);
            app.hv_EditField.Tooltip = {'Photon energy. Either a single value x or range from xmin:xmax.'};
            app.hv_EditField.Value = '400';
            app.IMFPAngLabel = uilabel(app.BrilluoinZoneNavigationPanel);
            app.IMFPAngLabel.Text = 'IMFP (Ang.):';
            app.IMFP_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'numeric');
            app.IMFP_EditField.Limits = [0 Inf];
            app.IMFP_EditField.ValueDisplayFormat = '%.4f';
            app.IMFP_EditField.Editable = 'off';
            app.IMFP_EditField.Tooltip = {'Estimate of the IMFP of electrons at the given photon energy.'};
            app.dKzAng1Label = uilabel(app.BrilluoinZoneNavigationPanel);
            app.dKzAng1Label.Text = 'dKz (Ang.^-1):';
            app.dKz_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'numeric');
            app.dKz_EditField.Limits = [0 Inf];
            app.dKz_EditField.ValueDisplayFormat = '%.4f';
            app.dKz_EditField.Editable = 'off';
            app.dKz_EditField.Tooltip = {'Estimation of the kz broadening associated with the given photon energy.'};
            app.Image = uiimage(app.BrilluoinZoneNavigationPanel);
            app.Image.ImageSource = 'arpes_geometry.png';
            app.SAVE_Button = uibutton(app.UIFigure, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB structure file.'};
            app.SAVE_Button.Text = 'SAVE';
            app.TRANSFER_Button = uibutton(app.UIFigure, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.APPINFO_Button = uibutton(app.UIFigure, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BZNavi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end