classdef ARPESGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AppTitle                matlab.ui.control.Label
        LCNToolsPanel           matlab.ui.container.Panel
        BZNavi_Button           matlab.ui.control.Button
        DataProcessing_Button   matlab.ui.control.Button
        kFAnalysis_Button       matlab.ui.control.Button
        IsoEAnalysis_Button     matlab.ui.control.Button
        EbkStateFitting_Button  matlab.ui.control.Button
        LCNToolsText_Label      matlab.ui.control.Label
        LCNToolsTitle_Label     matlab.ui.control.Label
        MAToolsPanel            matlab.ui.container.Panel
        ARPESView_Button        matlab.ui.control.Button
        KNavi_Button            matlab.ui.control.Button
        MAToolsText_Label       matlab.ui.control.Label
        MAToolsTitle_Label      matlab.ui.control.Label
        CLOSEFIGS_Button        matlab.ui.control.Button
        RESTART_Button          matlab.ui.control.Button
        RESIZEGUI_Button        matlab.ui.control.Button
        VIEWIMFP_Button         matlab.ui.control.Button
        InfoPanel               matlab.ui.container.Panel
        Info_Label              matlab.ui.control.Label
    end

    % Properties that correspond to private variables within the app
    properties (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            %% 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            %% 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            %% Remove the GUI handle and restart
            close(gcbf); run ARPESGUI;
        end

        % Button pushed function: RESIZEGUI_Button
        function RESIZEGUI_ButtonPushed(app, event)
            %% Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 540;
            screen_size(4) = 300;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: VIEWIMFP_Button
        function VIEWIMFP_ButtonPushed(app, event)
            %% 1 - Extracting the theoretical IMFP curve
            eKE = linspace(1, 1e5, 1e5);
            imfp_th = 10 .* (143 ./ eKE .^2 + 0.054.*sqrt(eKE));
            %% 2 - Plotting a summary of the IMFP curves
            figIMFP = figure(1001); 
            clf(figIMFP,'reset'); set(figIMFP, 'Name', 'IMFP curve');
            loglog(eKE, imfp_th, '-', 'LineWidth', 3, 'color', [0,0,0]);
            hold on; grid on; grid minor;
            line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
            line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
            % - Figure formatting
            ax = gca;
            ax.FontName = 'Helvetica'; ax.FontSize = 12;
            ax.XLabel.String = 'E_{KE} (eV)'; ax.YLabel.String = 'IMFP (Angstrom)';
            ax.XLim = [1, 1e4]; ax.YLim = [1, 1e3];
            % -  Adding custom legends
            h = zeros(1, 1);
            h(1) = plot(NaN,NaN,'.-', 'linewidth', 5, 'color', [0,0,0]);
            legend(h, {' IMFP_{theory} '}, 'fontsize', 10);
        end

        % Button pushed function: BZNavi_Button
        function BZNavi_ButtonPushed(app, event)
            run BZNavi;
        end

        % Button pushed function: DataProcessing_Button
        function DataProcessing_ButtonPushed(app, event)
            run ARPESprocess;
        end

        % Button pushed function: kFAnalysis_Button
        function kFAnalysis_ButtonPushed(app, event)
            run ARPESkf;
        end

        % Button pushed function: IsoEAnalysis_Button
        function IsoEAnalysis_ButtonPushed(app, event)
            run ARPESisoe;
        end

        % Button pushed function: EbkStateFitting_Button
        function EbkStateFitting_ButtonPushed(app, event)
            run ARPESfitter;
        end

        % Button pushed function: ARPESView_Button
        function ARPESView_ButtonPushed(app, event)
            run ARPESView;
        end

        % Button pushed function: KNavi_Button
        function KNavi_ButtonPushed(app, event)
            run KNavi;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'ARPESGUI';
            app.LCNToolsPanel = uipanel(app.UIFigure);
            app.LCNToolsPanel.Title = 'LCNTools';
            app.BZNavi_Button = uibutton(app.LCNToolsPanel, 'push');
            app.BZNavi_Button.ButtonPushedFcn = createCallbackFcn(app, @BZNavi_ButtonPushed, true);
            app.BZNavi_Button.Tooltip = {'(Brilluoin Zone Navigation) Allows the user to define any crystal geometry and to determine the Wigner-Seitz cell in real-space and the First Brilluoin-Zone in reciprocal space.'};
            app.BZNavi_Button.Text = 'BZNavi';
            app.DataProcessing_Button = uibutton(app.LCNToolsPanel, 'push');
            app.DataProcessing_Button.ButtonPushedFcn = createCallbackFcn(app, @DataProcessing_ButtonPushed, true);
            app.DataProcessing_Button.Tooltip = {'Process the raw data taken from the ADRESS beamline.'};
            app.DataProcessing_Button.Text = 'Data Processing';
            app.kFAnalysis_Button = uibutton(app.LCNToolsPanel, 'push');
            app.kFAnalysis_Button.ButtonPushedFcn = createCallbackFcn(app, @kFAnalysis_ButtonPushed, true);
            app.kFAnalysis_Button.Tooltip = {'Allows the user to determine how the Fermi wave-vector varies as a function of either the photon energy or tilt angle.'};
            app.kFAnalysis_Button.Text = 'kF Analysis';
            app.IsoEAnalysis_Button = uibutton(app.LCNToolsPanel, 'push');
            app.IsoEAnalysis_Button.ButtonPushedFcn = createCallbackFcn(app, @IsoEAnalysis_ButtonPushed, true);
            app.IsoEAnalysis_Button.Tooltip = {'(*Data must be parsed and saved through Data Processing first) Allows the user to analyse iso-energetic slices of the processed ARPES data.'};
            app.IsoEAnalysis_Button.Text = 'IsoE Analysis';
            app.EbkStateFitting_Button = uibutton(app.LCNToolsPanel, 'push');
            app.EbkStateFitting_Button.ButtonPushedFcn = createCallbackFcn(app, @EbkStateFitting_ButtonPushed, true);
            app.EbkStateFitting_Button.Tooltip = {'(*Data must be parsed and saved through Data Processing first) Allows the user to fit parabolic features within ARPES spectra.'};
            app.EbkStateFitting_Button.Text = 'Eb(k) State Fitting';
            app.LCNToolsText_Label = uilabel(app.LCNToolsPanel);
            app.LCNToolsText_Label.Text = {'Procopios Constantinou,  '; 'Paul Scherrer Institut (PSI).  '; 'email: procopios.constantinou@psi.ch'};
            app.LCNToolsTitle_Label = uilabel(app.LCNToolsPanel);
            app.LCNToolsTitle_Label.Text = 'LCNTools (v7.0-Euston-13-Jul-2021)';
            app.MAToolsPanel = uipanel(app.UIFigure);
            app.MAToolsPanel.Title = 'MATools';
            app.ARPESView_Button = uibutton(app.MAToolsPanel, 'push');
            app.ARPESView_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESView_ButtonPushed, true);
            app.ARPESView_Button.Tooltip = {'Run ARPESView from MATools software package developed at PSI.'};
            app.ARPESView_Button.Text = 'ARPESView';
            app.KNavi_Button = uibutton(app.MAToolsPanel, 'push');
            app.KNavi_Button.ButtonPushedFcn = createCallbackFcn(app, @KNavi_ButtonPushed, true);
            app.KNavi_Button.Tooltip = {'Run KNavi from MATools software package developed at PSI.'};
            app.KNavi_Button.Text = 'KNavi';
            app.MAToolsText_Label = uilabel(app.MAToolsPanel);
            app.MAToolsText_Label.Text = {'Vladimir Strocov,  '; 'Paul Scherrer Institute (PSI),  '; 'email: vladimir.strocov@psi.ch'};
            app.MAToolsTitle_Label = uilabel(app.MAToolsPanel);
            app.MAToolsTitle_Label.Text = 'MATools (GreenHill, 15-May-2021) ';
            app.CLOSEFIGS_Button = uibutton(app.UIFigure, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Closes all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.UIFigure, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Restarts the app to its default state.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEGUI_Button = uibutton(app.UIFigure, 'push');
            app.RESIZEGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEGUI_ButtonPushed, true);
            app.RESIZEGUI_Button.Tooltip = {'Resizes the app to its default size.'};
            app.RESIZEGUI_Button.Text = 'RESIZE GUI';
            app.VIEWIMFP_Button = uibutton(app.UIFigure, 'push');
            app.VIEWIMFP_Button.ButtonPushedFcn = createCallbackFcn(app, @VIEWIMFP_ButtonPushed, true);
            app.VIEWIMFP_Button.Tooltip = {'View the theoretical Inelastic Mean Free Path (IMFP) curve of electrons as a function of their kinetic energy.'};
            app.VIEWIMFP_Button.Text = 'VIEW IMFP';
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.Info_Label = uilabel(app.InfoPanel);
            app.Info_Label.Text = {'MATLAB version; 2020a'; 'MATLAB add-ons; Curve Fitting Toolbox.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARPESGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end