classdef PES_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        UICONTROL_GridLayout    matlab.ui.container.GridLayout
        ExportData_Button       matlab.ui.control.Button
        CLOSEFIGS_Button        matlab.ui.control.Button
        RESTART_Button          matlab.ui.control.Button
        RESIZEUI_Button         matlab.ui.control.Button
        PeriodicTableSingle_ButtonGroup  matlab.ui.container.ButtonGroup
        Lr_Button               matlab.ui.control.ToggleButton
        No_Button               matlab.ui.control.ToggleButton
        Md_Button               matlab.ui.control.ToggleButton
        Fm_Button               matlab.ui.control.ToggleButton
        Es_Button               matlab.ui.control.ToggleButton
        Cf_Button               matlab.ui.control.ToggleButton
        Bk_Button               matlab.ui.control.ToggleButton
        Cm_Button               matlab.ui.control.ToggleButton
        Am_Button               matlab.ui.control.ToggleButton
        Pu_Button               matlab.ui.control.ToggleButton
        Np_Button               matlab.ui.control.ToggleButton
        U_Button                matlab.ui.control.ToggleButton
        Pa_Button               matlab.ui.control.ToggleButton
        Th_Button               matlab.ui.control.ToggleButton
        Lu_Button               matlab.ui.control.ToggleButton
        Yb_Button               matlab.ui.control.ToggleButton
        Tm_Button               matlab.ui.control.ToggleButton
        Er_Button               matlab.ui.control.ToggleButton
        Ho_Button               matlab.ui.control.ToggleButton
        Dy_Button               matlab.ui.control.ToggleButton
        Tb_Button               matlab.ui.control.ToggleButton
        Gd_Button               matlab.ui.control.ToggleButton
        Eu_Button               matlab.ui.control.ToggleButton
        Sm_Button               matlab.ui.control.ToggleButton
        Pm_Button               matlab.ui.control.ToggleButton
        Nd_Button               matlab.ui.control.ToggleButton
        Pr_Button               matlab.ui.control.ToggleButton
        Ce_Button               matlab.ui.control.ToggleButton
        Og_Button               matlab.ui.control.ToggleButton
        Ts_Button               matlab.ui.control.ToggleButton
        Lv_Button               matlab.ui.control.ToggleButton
        Mc_Button               matlab.ui.control.ToggleButton
        Fl_Button               matlab.ui.control.ToggleButton
        Nh_Button               matlab.ui.control.ToggleButton
        Cn_Button               matlab.ui.control.ToggleButton
        Rg_Button               matlab.ui.control.ToggleButton
        Ds_Button               matlab.ui.control.ToggleButton
        Mt_Button               matlab.ui.control.ToggleButton
        Hs_Button               matlab.ui.control.ToggleButton
        Bh_Button               matlab.ui.control.ToggleButton
        Sg_Button               matlab.ui.control.ToggleButton
        Db_Button               matlab.ui.control.ToggleButton
        Rf_Button               matlab.ui.control.ToggleButton
        Ac_Button               matlab.ui.control.ToggleButton
        Ra_Button               matlab.ui.control.ToggleButton
        Fr_Button               matlab.ui.control.ToggleButton
        Rn_Button               matlab.ui.control.ToggleButton
        At_Button               matlab.ui.control.ToggleButton
        Po_Button               matlab.ui.control.ToggleButton
        Bi_Button               matlab.ui.control.ToggleButton
        Pb_Button               matlab.ui.control.ToggleButton
        Tl_Button               matlab.ui.control.ToggleButton
        Hg_Button               matlab.ui.control.ToggleButton
        Au_Button               matlab.ui.control.ToggleButton
        Pt_Button               matlab.ui.control.ToggleButton
        Ir_Button               matlab.ui.control.ToggleButton
        Os_Button               matlab.ui.control.ToggleButton
        Re_Button               matlab.ui.control.ToggleButton
        W_Button                matlab.ui.control.ToggleButton
        Ta_Button               matlab.ui.control.ToggleButton
        Hf_Button               matlab.ui.control.ToggleButton
        La_Button               matlab.ui.control.ToggleButton
        Ba_Button               matlab.ui.control.ToggleButton
        Cs_Button               matlab.ui.control.ToggleButton
        Xe_Button               matlab.ui.control.ToggleButton
        I_Button                matlab.ui.control.ToggleButton
        Te_Button               matlab.ui.control.ToggleButton
        Sb_Button               matlab.ui.control.ToggleButton
        Sn_Button               matlab.ui.control.ToggleButton
        In_Button               matlab.ui.control.ToggleButton
        Cd_Button               matlab.ui.control.ToggleButton
        Ag_Button               matlab.ui.control.ToggleButton
        Pd_Button               matlab.ui.control.ToggleButton
        Rh_Button               matlab.ui.control.ToggleButton
        Ru_Button               matlab.ui.control.ToggleButton
        Tc_Button               matlab.ui.control.ToggleButton
        Mo_Button               matlab.ui.control.ToggleButton
        Nb_Button               matlab.ui.control.ToggleButton
        Zr_Button               matlab.ui.control.ToggleButton
        Y_Button                matlab.ui.control.ToggleButton
        Sr_Button               matlab.ui.control.ToggleButton
        Rb_Button               matlab.ui.control.ToggleButton
        Kr_Button               matlab.ui.control.ToggleButton
        Br_Button               matlab.ui.control.ToggleButton
        Se_Button               matlab.ui.control.ToggleButton
        As_Button               matlab.ui.control.ToggleButton
        Ge_Button               matlab.ui.control.ToggleButton
        Ga_Button               matlab.ui.control.ToggleButton
        Zn_Button               matlab.ui.control.ToggleButton
        Cu_Button               matlab.ui.control.ToggleButton
        Ni_Button               matlab.ui.control.ToggleButton
        Co_Button               matlab.ui.control.ToggleButton
        Fe_Button               matlab.ui.control.ToggleButton
        Mn_Button               matlab.ui.control.ToggleButton
        Cr_Button               matlab.ui.control.ToggleButton
        V_Button                matlab.ui.control.ToggleButton
        Ti_Button               matlab.ui.control.ToggleButton
        Sc_Button               matlab.ui.control.ToggleButton
        Ca_Button               matlab.ui.control.ToggleButton
        K_Button                matlab.ui.control.ToggleButton
        Ar_Button               matlab.ui.control.ToggleButton
        Cl_Button               matlab.ui.control.ToggleButton
        S_Button                matlab.ui.control.ToggleButton
        P_Button                matlab.ui.control.ToggleButton
        Si_Button               matlab.ui.control.ToggleButton
        Al_Button               matlab.ui.control.ToggleButton
        Mg_Button               matlab.ui.control.ToggleButton
        Na_Button               matlab.ui.control.ToggleButton
        Ne_Button               matlab.ui.control.ToggleButton
        F_Button                matlab.ui.control.ToggleButton
        O_Button                matlab.ui.control.ToggleButton
        N_Button                matlab.ui.control.ToggleButton
        C_Button                matlab.ui.control.ToggleButton
        B_Button                matlab.ui.control.ToggleButton
        Be_Button               matlab.ui.control.ToggleButton
        Li_Button               matlab.ui.control.ToggleButton
        He_Button               matlab.ui.control.ToggleButton
        H_Button                matlab.ui.control.ToggleButton
        Material_GridLayout     matlab.ui.container.GridLayout
        Material_EditField      matlab.ui.control.EditField
        MaterialEditFieldLabel  matlab.ui.control.Label
        BE_UITable              matlab.ui.control.Table
        AppTitle                matlab.ui.control.Label
        XSect_UIAxes            matlab.ui.control.UIAxes
        Asym_UIAxes             matlab.ui.control.UIAxes
        XPS_UIAxes              matlab.ui.control.UIAxes
    end

    % Private properties available to the app only
    properties (Access = private)
        element          % single selected element
    end
    % Private methods available to the app only
    methods (Access = private)
        function myData = extract_element_props(app)
            % 1 - Extracting the PIXSAD properties from the Database
            [hv, xsect, asymmetry]  = get_pixsad_props(app.element);
            % 2 - Extracting the PIEFD properties from the Database
            piefd_props = get_piefd_props(app.element);
            piefd_BE = table2cell(piefd_props); 
            piefd_BE(1:5) = []; piefd_BE(25:end) = [];
            % 3 - Assigning relevant properties to the data-structure
            myData                  = struct();
            myData.element          = app.element;
            myData.hv               = table2array(hv);
            myData.xsect_label      = xsect.Properties.VariableNames;
            myData.xsect            = table2array(xsect); myData.xsect(myData.xsect<0)= NaN;
            myData.asymm_label      = asymmetry.Properties.VariableNames;
            myData.asymm            = table2array(asymmetry);
            myData.be               = cell2mat(piefd_BE);
            myData.be_label = {...
                '1s',...                                                                        % K1
                '2s', '2p(1/2)', '2p(3/2)',...                                                  % L1,L2,L3
                '3s', '3p(1/2)', '3p(3/2)', '3d(3/2)','3d(5/2)',...                             % M1,M2,M3,M4,M5
                '4s', '4p(1/2)', '4p(3/2)', '4d(3/2)','4d(5/2)', '4f(5/2)', '4f(7/2)',...       % N1,N2,N3,N4,N5,N6,N7
                '5s', '5p(1/2)', '5p(3/2)', '5d(3/2)','5d(5/2)',...                             % O1,O2,O3,O4,O5
                '6s', '6p(1/2)', '6p(3/2)',...                                                  % P1,P2,P3
                };
            % 4 - Cropping the parameters to non-zero components only
            indx0 = isnan(myData.be);
            myData.be(indx0) = []; myData.be_label(indx0) = [];
            for i = 1:length(myData.xsect_label)
                myData.xsect_label{i} = myData.xsect_label{i}(1:2);
                myData.asymm_label{i} = myData.asymm_label{i}(1:2);
            end
            % 5 - Transforming BE to negative values
            myData.be = -1*myData.be;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1 - Default parameters upon startup
            % 2 - First Tab Initialisation
            % -- Initialising the UI table
            app.BE_UITable.Data = []; 
            s = uistyle('HorizontalAlignment','left'); addStyle(app.BE_UITable,s);
            % -- Updating the table and figures
            PeriodicTableSingle_ButtonGroupSelectionChanged(app);
        end

        % Selection changed function: PeriodicTableSingle_ButtonGroup
        function PeriodicTableSingle_ButtonGroupSelectionChanged(app, event)
            % 1 - Extracting the input defined by the user
            selectedButton  = app.PeriodicTableSingle_ButtonGroup.SelectedObject;
            app.element     = selectedButton.Text;
            % 2 - Extracting the photoionisation parameters from the materials database
            myData = extract_element_props(app);
            % 3 - Updating the relevant UI components
            app.Material_EditField.Value = app.element;
            % -- Update UITable for BE spectrum
            app.BE_UITable.Data = [];
            for i = 1:length(myData.be_label)
                app.BE_UITable.Data{i,1} = myData.be_label{i};
                app.BE_UITable.Data{i,2} = round(myData.be(i),1);
            end
            % -- Update UIFigure for XSect
            ax = app.XSect_UIAxes; cla(ax); hold(ax,'on');
            for i = 1:length(myData.xsect_label)
                plot(ax, myData.hv, myData.xsect(:,i), '.-');
            end
            legend(ax,myData.xsect_label, 'location', 'best');
            title(ax,'Cross-section (sigma)');
            xlabel(ax,'$$ \bf Photon\ Energy\ (eV) $$', 'interpreter', 'latex');
            ylabel(ax,'$$ \bf Cross\ Section\ [Mbarn] $$', 'interpreter', 'latex');
            ax.YScale = 'log';
            % -- Update UIFigure for Asym
            ax = app.Asym_UIAxes; cla(ax); hold(ax,'on');
            for i = 1:length(myData.asymm_label)
                plot(ax, myData.hv, myData.asymm(:,i), '.-');
            end
            legend(ax,myData.asymm_label, 'location', 'best');
            title(ax,'Asymmetry Parameter (beta)');
            xlabel(ax,'$$ \bf Photon\ Energy\ (eV) $$', 'interpreter', 'latex');
            ylabel(ax,'$$ \bf Asymmetry $$', 'interpreter', 'latex');
            % -- Update UIFigure for BE spectrum
            ax = app.XPS_UIAxes; cla(ax); hold(ax,'on');
            % -- Plotting the data
            max_y = 0.90;
            stem(ax, myData.be, myData.be*0+max_y, 'k-', 'linewidth', 2.0, 'marker', 'none');
            plot(ax, myData.be, myData.be*0+max_y, 'ko', 'linewidth', 1.0, 'marker', 'o', 'markersize', 4, 'markerfacecolor', 'b', 'MarkerEdgeColor', 'b');
            ax.XLim = [-1500 0]; ax.YLim = [0 1.1];
            % -- Adding labels to each component
            for i = 1:length(myData.be)
                text(ax, myData.be(i), max_y+0.03, myData.be_label{i}, 'Rotation',45,'FontSize',12);
            end
            ax.XTick = -1e4:100:1e4;
            title(ax,'Binding Energy Spectrum');
            xlabel(ax, '$$ \bf Binding\ Energy\ (eV) $$', 'interpreter', 'latex');
            ylabel(ax, '$$ \bf Intensity $$', 'interpreter', 'latex');
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            close(gcbf); run PES_UI;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 895;
            screen_size(4) = 900;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: ExportData_Button
        function ExportData_ButtonPushed(app, event)
            % 1 - Define the filename and pathname of data
            filter = {'*.txt'};
            fname = string(app.element);
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', fname);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % 2 - Construct a table with all relevant parameters
            myData = extract_element_props(app);
            % -- Binding Energies
            T_BE = table(myData.be_label', myData.be');
            T_BE.Properties.VariableNames{1} = 'Level';
            T_BE.Properties.VariableNames{2} = 'BE_eV';
            % -- XSect
            T_XSect = table(myData.hv);
            T_XSect.Properties.VariableNames{1} = 'HV_eV';
            for i = 1:length(myData.xsect_label)
                T_XSect = [T_XSect,table(myData.xsect(:,i), 'VariableNames', {myData.xsect_label{i}})];
            end
            % -- Asymm
            T_Asymm = table(myData.hv);
            T_Asymm.Properties.VariableNames{1} = 'HV_eV';
            for i = 1:length(myData.asymm_label)
                T_Asymm = [T_Asymm,table(myData.asymm(:,i), 'VariableNames', {myData.asymm_label{i}})];
            end
            % 3 - Save the data
            writetable(T_BE,char(string(save_fullfile(1:end-4)) + "_BE.txt"),'Delimiter','\t');
            writetable(T_XSect,char(string(save_fullfile(1:end-4)) + "_XSECT.txt"),'Delimiter','\t');
            writetable(T_Asymm,char(string(save_fullfile(1:end-4)) + "_ASYM.txt"),'Delimiter','\t');
            waitbar(1,'Save complete!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.XPS_UIAxes = uiaxes(app.GridLayout);
            title(app.XPS_UIAxes, 'Title')
            xlabel(app.XPS_UIAxes, 'X')
            ylabel(app.XPS_UIAxes, 'Y')
            zlabel(app.XPS_UIAxes, 'Z')
            app.Asym_UIAxes = uiaxes(app.GridLayout);
            title(app.Asym_UIAxes, 'Title')
            xlabel(app.Asym_UIAxes, 'X')
            ylabel(app.Asym_UIAxes, 'Y')
            zlabel(app.Asym_UIAxes, 'Z')
            app.XSect_UIAxes = uiaxes(app.GridLayout);
            title(app.XSect_UIAxes, 'Title')
            xlabel(app.XSect_UIAxes, 'X')
            ylabel(app.XSect_UIAxes, 'Y')
            zlabel(app.XSect_UIAxes, 'Z')
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'Photoionisation Energy and Cross-Section UI';
            app.Material_GridLayout = uigridlayout(app.GridLayout);
            app.BE_UITable = uitable(app.Material_GridLayout);
            app.BE_UITable.ColumnName = {'Level'; 'BE (eV)'};
            app.BE_UITable.RowName = {};
            app.BE_UITable.ColumnSortable = [false true];
            app.BE_UITable.Multiselect = 'off';
            app.MaterialEditFieldLabel = uilabel(app.Material_GridLayout);
            app.MaterialEditFieldLabel.Text = 'Material:';
            app.Material_EditField = uieditfield(app.Material_GridLayout, 'text');
            app.Material_EditField.Value = 'Si';
            app.PeriodicTableSingle_ButtonGroup = uibuttongroup(app.GridLayout);
            app.PeriodicTableSingle_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PeriodicTableSingle_ButtonGroupSelectionChanged, true);
            app.PeriodicTableSingle_ButtonGroup.Tooltip = {'Select an element.'};
            app.H_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.H_Button.Text = 'H';
            app.He_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.He_Button.Text = 'He';
            app.Li_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Li_Button.Text = 'Li';
            app.Be_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Be_Button.Text = 'Be';
            app.B_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.B_Button.Text = 'B';
            app.C_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.C_Button.Text = 'C';
            app.N_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.N_Button.Text = 'N';
            app.O_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.O_Button.Text = 'O';
            app.F_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.F_Button.Text = 'F';
            app.Ne_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ne_Button.Text = 'Ne';
            app.Na_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Na_Button.Text = 'Na';
            app.Mg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mg_Button.Text = 'Mg';
            app.Al_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Al_Button.Text = 'Al';
            app.Si_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Si_Button.Text = 'Si';
            app.Si_Button.Value = true;
            app.P_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.P_Button.Text = 'P';
            app.S_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.S_Button.Text = 'S';
            app.Cl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cl_Button.Text = 'Cl';
            app.Ar_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ar_Button.Text = 'Ar';
            app.K_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.K_Button.Text = 'K';
            app.Ca_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ca_Button.Text = 'Ca';
            app.Sc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sc_Button.Text = 'Sc';
            app.Ti_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ti_Button.Text = 'Ti';
            app.V_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.V_Button.Text = 'V';
            app.Cr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cr_Button.Text = 'Cr';
            app.Mn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mn_Button.Text = 'Mn';
            app.Fe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fe_Button.Text = 'Fe';
            app.Co_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Co_Button.Text = 'Co';
            app.Ni_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ni_Button.Text = 'Ni';
            app.Cu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cu_Button.Text = 'Cu';
            app.Zn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zn_Button.Text = 'Zn';
            app.Ga_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ga_Button.Text = 'Ga';
            app.Ge_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ge_Button.Text = 'Ge';
            app.As_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.As_Button.Text = 'As';
            app.Se_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Se_Button.Text = 'Se';
            app.Br_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Br_Button.Text = 'Br';
            app.Kr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Kr_Button.Text = 'Kr';
            app.Rb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rb_Button.Text = 'Rb';
            app.Sr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sr_Button.Text = 'Sr';
            app.Y_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Y_Button.Text = 'Y';
            app.Zr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Zr_Button.Text = 'Zr';
            app.Nb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nb_Button.Text = 'Nb';
            app.Mo_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mo_Button.Text = 'Mo';
            app.Tc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tc_Button.Text = 'Tc';
            app.Ru_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ru_Button.Text = 'Ru';
            app.Rh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rh_Button.Text = 'Rh';
            app.Pd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pd_Button.Text = 'Pd';
            app.Ag_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ag_Button.Text = 'Ag';
            app.Cd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cd_Button.Text = 'Cd';
            app.In_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.In_Button.Text = 'In';
            app.Sn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sn_Button.Text = 'Sn';
            app.Sb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sb_Button.Text = 'Sb';
            app.Te_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Te_Button.Text = 'Te';
            app.I_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.I_Button.Text = 'I';
            app.Xe_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Xe_Button.Text = 'Xe';
            app.Cs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cs_Button.Text = 'Cs';
            app.Ba_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ba_Button.Text = 'Ba';
            app.La_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.La_Button.Text = 'La';
            app.Hf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hf_Button.Text = 'Hf';
            app.Ta_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ta_Button.Text = 'Ta';
            app.W_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.W_Button.Text = 'W';
            app.Re_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Re_Button.Text = 'Re';
            app.Os_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Os_Button.Text = 'Os';
            app.Ir_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ir_Button.Text = 'Ir';
            app.Pt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pt_Button.Text = 'Pt';
            app.Au_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Au_Button.Text = 'Au';
            app.Hg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hg_Button.Text = 'Hg';
            app.Tl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tl_Button.Text = 'Tl';
            app.Pb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pb_Button.Text = 'Pb';
            app.Bi_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bi_Button.Text = 'Bi';
            app.Po_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Po_Button.Text = 'Po';
            app.At_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.At_Button.Text = 'At';
            app.Rn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rn_Button.Text = 'Rn';
            app.Fr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fr_Button.Text = 'Fr';
            app.Ra_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ra_Button.Text = 'Ra';
            app.Ac_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ac_Button.Text = 'Ac';
            app.Rf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rf_Button.Enable = 'off';
            app.Rf_Button.Text = 'Rf';
            app.Db_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Db_Button.Enable = 'off';
            app.Db_Button.Text = 'Db';
            app.Sg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sg_Button.Enable = 'off';
            app.Sg_Button.Text = 'Sg';
            app.Bh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bh_Button.Enable = 'off';
            app.Bh_Button.Text = 'Bh';
            app.Hs_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Hs_Button.Enable = 'off';
            app.Hs_Button.Text = 'Hs';
            app.Mt_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mt_Button.Enable = 'off';
            app.Mt_Button.Text = 'Mt';
            app.Ds_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ds_Button.Enable = 'off';
            app.Ds_Button.Text = 'Ds';
            app.Rg_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Rg_Button.Enable = 'off';
            app.Rg_Button.Text = 'Rg';
            app.Cn_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cn_Button.Enable = 'off';
            app.Cn_Button.Text = 'Cn';
            app.Nh_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nh_Button.Enable = 'off';
            app.Nh_Button.Text = 'Nh';
            app.Fl_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fl_Button.Enable = 'off';
            app.Fl_Button.Text = 'Fl';
            app.Mc_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Mc_Button.Enable = 'off';
            app.Mc_Button.Text = 'Mc';
            app.Lv_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lv_Button.Enable = 'off';
            app.Lv_Button.Text = 'Lv';
            app.Ts_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ts_Button.Enable = 'off';
            app.Ts_Button.Text = 'Ts';
            app.Og_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Og_Button.Enable = 'off';
            app.Og_Button.Text = 'Og';
            app.Ce_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ce_Button.Text = 'Ce';
            app.Pr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pr_Button.Text = 'Pr';
            app.Nd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Nd_Button.Text = 'Nd';
            app.Pm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pm_Button.Text = 'Pm';
            app.Sm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Sm_Button.Text = 'Sm';
            app.Eu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Eu_Button.Text = 'Eu';
            app.Gd_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Gd_Button.Text = 'Gd';
            app.Tb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tb_Button.Text = 'Tb';
            app.Dy_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Dy_Button.Text = 'Dy';
            app.Ho_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Ho_Button.Text = 'Ho';
            app.Er_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Er_Button.Text = 'Er';
            app.Tm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Tm_Button.Text = 'Tm';
            app.Yb_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Yb_Button.Text = 'Yb';
            app.Lu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lu_Button.Text = 'Lu';
            app.Th_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Th_Button.Text = 'Th';
            app.Pa_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pa_Button.Text = 'Pa';
            app.U_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.U_Button.Text = 'U';
            app.Np_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Np_Button.Text = 'Np';
            app.Pu_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Pu_Button.Text = 'Pu';
            app.Am_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Am_Button.Text = 'Am';
            app.Cm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cm_Button.Text = 'Cm';
            app.Bk_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Bk_Button.Text = 'Bk';
            app.Cf_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Cf_Button.Text = 'Cf';
            app.Es_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Es_Button.Text = 'Es';
            app.Fm_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Fm_Button.Text = 'Fm';
            app.Md_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Md_Button.Text = 'Md';
            app.No_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.No_Button.Text = 'No';
            app.Lr_Button = uitogglebutton(app.PeriodicTableSingle_ButtonGroup);
            app.Lr_Button.Text = 'Lr';
            app.UICONTROL_GridLayout = uigridlayout(app.GridLayout);
            app.RESIZEUI_Button = uibutton(app.UICONTROL_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.RESTART_Button = uibutton(app.UICONTROL_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.CLOSEFIGS_Button = uibutton(app.UICONTROL_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.ExportData_Button = uibutton(app.UICONTROL_GridLayout, 'push');
            app.ExportData_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_ButtonPushed, true);
            app.ExportData_Button.Text = 'Export Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PES_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end