classdef BZNavi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AppTitle                      matlab.ui.control.Label
        CLOSEFIGS_Button              matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        RESIZEGUI_Button              matlab.ui.control.Button
        ARPESGUI_Button               matlab.ui.control.Button
        BrilluoinZoneExtractionPanel  matlab.ui.container.Panel
        Extract2DBZ_Button            matlab.ui.control.Button
        Extract3DBZ_Button            matlab.ui.control.Button
        CrystalDropDownLabel          matlab.ui.control.Label
        Crystal_DropDown              matlab.ui.control.DropDown
        PlaneSlicehklDropDownLabel    matlab.ui.control.Label
        PlaneSlice_DropDown           matlab.ui.control.DropDown
        aAngLabel                     matlab.ui.control.Label
        a_EditField                   matlab.ui.control.NumericEditField
        bAngLabel                     matlab.ui.control.Label
        b_EditField                   matlab.ui.control.NumericEditField
        cAngLabel                     matlab.ui.control.Label
        c_EditField                   matlab.ui.control.NumericEditField
        alphadegLabel                 matlab.ui.control.Label
        alpha_EditField               matlab.ui.control.NumericEditField
        betadegLabel                  matlab.ui.control.Label
        beta_EditField                matlab.ui.control.NumericEditField
        gammadegLabel                 matlab.ui.control.Label
        gamma_EditField               matlab.ui.control.NumericEditField
        BrilluoinZoneNavigationPanel  matlab.ui.container.Panel
        V000eVLabel                   matlab.ui.control.Label
        V000_EditField                matlab.ui.control.NumericEditField
        ARPESgeometryPanel            matlab.ui.container.Panel
        ePhieVEditFieldLabel          matlab.ui.control.Label
        ePhi_EditField                matlab.ui.control.NumericEditField
        alphadegEditField_2Label      matlab.ui.control.Label
        alpha2_EditField              matlab.ui.control.NumericEditField
        thtMdegEditFieldLabel         matlab.ui.control.Label
        thtM_EditField                matlab.ui.control.NumericEditField
        tltMdegEditFieldLabel         matlab.ui.control.Label
        tltM_EditField                matlab.ui.control.NumericEditField
        eBrefeVLabel                  matlab.ui.control.Label
        eBref_EditField               matlab.ui.control.NumericEditField
        thtArefdegEditFieldLabel      matlab.ui.control.Label
        thtAref_EditField             matlab.ui.control.NumericEditField
        kxrefAng1EditFieldLabel       matlab.ui.control.Label
        kxref_EditField               matlab.ui.control.NumericEditField
        ExtractARPES_Button           matlab.ui.control.Button
        hveVEditFieldLabel            matlab.ui.control.Label
        hv_EditField                  matlab.ui.control.EditField
        IMFPAngLabel                  matlab.ui.control.Label
        IMFP_EditField                matlab.ui.control.NumericEditField
        dKzAng1Label                  matlab.ui.control.Label
        dKz_EditField                 matlab.ui.control.NumericEditField
        Image                         matlab.ui.control.Image
        SAVE_Button                   matlab.ui.control.Button
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
    end

    % Properties that correspond to public variables within the app
    properties (Access = public)
        % Defining the argument structures
        crystal_args = cell(1,4);
        navi_args = struct();
        % Defining the main data structure
        myData = struct();
    end
    
    % Functions to be used within the app
    methods (Access = public)
        % ---  Function to extract all real- and reciprocal-space vectors based on crystallographic input
        function [realStr, reciStr] = extract_lattice(app, crystal_args, plotFig)
            % crystStr = extract_lattice(crystal_args)
            %   This is a function that extracts the crystallographic vectors
            %   of a general crystal structure in both real- and reciprocal-
            %   space based on the inputs. All 14 Bravais lattices can be 
            %   defined, including primitive-, base-, face- and body-centered
            %   lattices. The outputs are two MATLAB structures that contain
            %   all of the real- and reciprocal-space data.
            %
            %   IN:
            %   (1)   crystal_args:   1x4 cell of {crystal, (hkl), (a, b, c), (alpha, beta, gamma)} described below;
            %    -crystalType;          "CUB-Oh", "BCC-Oh", "FCC-Oh", "HEX-D6h", "RHL-D3d", "TET-D4h", "BCT-D4h", "ORC-D2h", 
            %                           "ORCC-D2h", "ORCI-D2h", "ORCF-D2h", "MCL-C2h", "MCLC-C2h",  "TRI-Ci" .
            %    -(a, b, c);            1x3 row-vector of the side lengths of the crystal systems unit cell (Angstroms).
            %    -(alpha, beta, gamma); 1x3 row vector the opposite angles of the crystal systems unit cell (degrees).
            %   (2) plotFig:            if 1, will plot figure summary, otherwise it wont.
            %
            %   OUT:
            %   realStr: MATLAB data structure containing all real-space information below.
            %   -   .(crystal):     string of the crystal type.
            %   -   .(T1):          a1 primitive translation vector.
            %	-   .(T2):          a2 primitive translation vector.
            %   -   .(T3):          a3 primitive translation vector.
            %   -   .(Tb0):         atomic basis vectors.
            %	-   .(Tvol):        volume of the real-space unit cell.
            %	-   .(T):           atomic positions over multiple primitive-translations to show crystal structure.
            %	-   .(Tlims):       axes limits in 3D for the lattice structure T.
            %	-   .(Tnn):         linked lines between nearest neighbour atomsdisplayed in .(T).
            %	-   .(Tws):         Wigner-Seitz cell in real-space.
            %   reciStr: MATLAB data structure containing all reciprocal-space information below.
            %   -   .(crystal):     string of the crystal type.
            %   -   .(G1):          b1 primitive reciprocal vector.
            %	-   .(G2):          b2 primitive reciprocal vector.
            %   -   .(G3):          b3 primitive reciprocal vector.
            %   -   .(Gb0):         atomic basis vectors in reciprocal space.
            %	-   .(Gvol):        volume of the reciprocal-space unit cell.
            %	-   .(G):           reciprocal lattice over multiple translations.
            %	-   .(Glims):       axes limits in 3D for the lattice structure G.
            %	-   .(Gnn):         linked lines between nearest neighbour reciprocal-points displayed in .(G).
            %	-   .(Gbz):         First Brilluoin zone in reciprocal-space.
            
            %% Default parameters
            if nargin < 3; plotFig = 0; end
            if isempty(plotFig); plotFig = 0;  end
            disp('-> Extracting 3D BZ...')
            wbar = waitbar(0, 'Extracting crystallographic variables...', 'Name', 'extract_lattice');
            
            %% Initialising the input variables
            crystal     = crystal_args{1};
            a           = crystal_args{3}(1);
            b           = crystal_args{3}(2);
            c           = crystal_args{3}(3);
            alpha       = crystal_args{4}(1) * pi/180;
            beta        = crystal_args{4}(2) * pi/180;
            gamma       = crystal_args{4}(3) * pi/180;
            
            %% 1 - Defining the real-space unit cell from a general triclinic geometry
            % -- Extracting the triclinic coefficients for real-space unit vectors
            w1 = cos(alpha) - cos(beta) * cos(gamma)/(sin(beta) * sin(gamma));
            w2 = sin(gamma)^2 - cos(beta)^2 - cos(alpha)^2 + 2*cos(alpha) *cos(beta) * cos(gamma);
            w2 = sqrt(w2) / (sin(beta) * sin(gamma));
            % -- Defining the real-space unit vectors
            T1 = [a, 0, 0];
            T2 = [b * cos(gamma), b	* sin(gamma), 0];
            T3 = [c * cos(beta), c * w1*sin(beta), c*w2*sin(beta)];
            % -- Extracting the volume of the real-space unit cell
            Tvol = cross(T1, T2)*T3';
            %% 2 - Defining the basis of the Bravais Lattice out of the 14 possible choices
            % - 2.1 - The 7 Primitive (Simple) Bravais lattices
            if crystal == "CUB-Oh" || crystal == "HEX-D6h" || crystal == "RHL-D3d" ||...
                    crystal == "TET-D4h" || crystal == "ORC-D2h" || crystal == "MCL-C2h" ||...
                    crystal == "TRI-Ci"
                Tb0 = [0, 0, 0];
            % - 2.2 - The 2 Base-Centered Bravais Lattices
            elseif crystal == "ORCC-D2h" || crystal == "MCLC-C2h"
                Tb0 = [0, 0, 0;...
                    0.5*(T1+T2)];
            % - 2.3 - The 3 Body-Centered Bravais Lattices
            elseif crystal == "BCC-Oh" || crystal == "BCT-D4h" || crystal == "ORCI-D2h"
                Tb0 = [0, 0, 0;...
                    0.5*(T1+T2+T3)];
            % - 2.4 - The 2 Face-Centered Bravais Lattices
            elseif crystal == "FCC-Oh" || crystal == "ORCF-D2h"
                Tb0 = [0, 0, 0;...
                    0.5*(T1+T2);...
                    0.5*(T1+T3);...
                    0.5*(T2+T3)];
            end
            %% 3 - Extracting the real-space lattice over multiple translations of T
            l=1;
            for i=-1:1
                for j=0:2
                    for k=-1:1
                        for n = 1:size(Tb0,1)
                            T(l,:)=i*T1+j*T2+k*T3+Tb0(n,:);
                            l=l+1;
                        end
                    end
                end
            end
            % - 3.1 - Finding the axes limited for a 2x2 super structure as in T
            if crystal == "CUB-Oh" || crystal == "HEX-D6h" || crystal == "RHL-D3d" ||...
                    crystal == "TET-D4h" || crystal == "ORC-D2h" || crystal == "MCL-C2h" ||...
                    crystal == "TRI-Ci"
                Tlims = 1.04*[min(T(:,1)), max(T(:,1)), min(T(:,2)), max(T(:,2)), min(T(:,3)), max(T(:,3))];
            elseif crystal == "ORCC-D2h" || crystal == "MCLC-C2h" || crystal == "BCC-Oh" ||...
                    crystal == "BCT-D4h" || crystal == "ORCI-D2h" || crystal == "FCC-Oh" ||...
                    crystal == "ORCF-D2h" 
                Tlims = 1.04*[-1*norm(T1), norm(T1), 0, 2*norm(T2),-1*norm(T3), norm(T3)];
            end
            %% 4 - Finding all vector paths to link all nearest neighbour points
            Tnn = [];
            % - Filing through all possible points given in the T-matrix
            for i = 1:length(T)-1
                waitbar(i/length(T)-1, wbar, 'Extracting real-space lattice...', 'Name', 'extract_lattice');
                for j = i+1:length(T)
                    % -- Finding the Euclidean distance between the two lattice points
                    dist  = sqrt((T(i,1)-T(j,1)).^2+(T(i,2)-T(j,2)).^2+(T(i,3)-T(j,3)).^2);
                    %% 4.1 - Applying geometrical constraints for nearest neighbour points
                    
                    % --- For the 7 Primitive (Simple) Bravais lattices
                    % Points are linked if their distance is identical to the
                    % length of T1, T2 or T3.
                    if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                            crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                            crystal_args{1} == "TRI-Ci"
                        if (abs(dist-norm(T1))) <= 0.0001 || (abs(dist-norm(T2))) <= 0.0001 || (abs(dist-norm(T3))) <= 0.0001                
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    % --- For the 2 Base-Centered Bravais Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % T1, T2 or T3, with the condition that it is not divisible by
                    % the BCC basis vector (as the base-centered points are only
                    % joined within the horizontal plane of the vertices).
                    elseif crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h" 
                        if abs(dist-norm(Tb0(2,:))) <= 0.0001
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif (mod(T(i,2), 2*Tb0(2,2)) == 0 ||  abs(T(i,2)) <= 0.0001 ) && ((abs(dist-norm(T1))) <= 0.0001 || (abs(dist-norm(T2))) <= 0.0001 || (abs(dist-norm(T3))) <= 0.0001)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    % --- For the 3 Body-Centered Bravais Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % T1, T2 or T3, with the condition that it is not divisible by
                    % the BCC basis vector (as the body-centered points are only
                    % joined radially outwards to vertices of unit cell).
                    elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h"
                        if abs(dist-norm(Tb0(2,:))) <= 0.0001
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif mod(T(i,3), 2*Tb0(2,3)) == 0 && ((abs(dist-norm(T1))) <= 0.0001 || (abs(dist-norm(T2))) <= 0.0001 || (abs(dist-norm(T3))) <= 0.0001)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    % --- For the 2 Face-Centered Bravais Lattices
                    % Points are linked if their distance is equal to the length
                    % of the FCC basis vector, with the proviso that the two points
                    % are coplanar and not an FCC point. This is applied along each
                    % dimension. Furthermore, if their length is equal to T1, T2 or T3, 
                    % with the condition that it is not divisible by the FCC basis vector
                    % (as the face-centered points are only joined radially outwards to
                    % vertices of unit cell, not to other FCC points).
                    elseif crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                        if abs(dist-norm(Tb0(2,:))) <= 0.001 && abs(T(i,1)-T(j,1)) <= 0.001 && (abs(T(i,1)) <= 0.001 || mod(T(i,1), 2*Tb0(2,1)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif abs(dist-norm(Tb0(2,:))) <= 0.001 && abs(T(i,2)-T(j,2)) <= 0.001 && (abs(T(i,2)) <= 0.001 || mod(T(i,2), 2*Tb0(2,2)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif abs(dist-norm(Tb0(3,:))) <= 0.001 && abs(T(i,3)-T(j,3)) <= 0.001 && (abs(T(i,3)) <= 0.001 || mod(T(i,3), 2*Tb0(3,3)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif (abs(dist-norm(T1))) <= 0.0001 && abs(T(i,1)-T(j,1)) <= 0.001 && (abs(T(i,1)) <= 0.001 || mod(T(i,1), 2*Tb0(2,1)) == 0) && (abs(T(i,3)) <= 0.001 || mod(T(i,3), 2*Tb0(3,3)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif (abs(dist-norm(T2))) <= 0.0001 && abs(T(i,2)-T(j,2)) <= 0.001 && (abs(T(i,2)) <= 0.001 || mod(T(i,2), 2*Tb0(2,2)) == 0) && (abs(T(i,3)) <= 0.001 || mod(T(i,3), 2*Tb0(3,3)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    end
                end
            end
            %% 5 - Computing the Voronoi diagram, which is identical to the Wigner-Seitz cell
            % - Calculating Voronoi diagram
            [T_c, T_v] = voronoin(T); 
            T_nx = T_c(T_v{floor(l/2)},:);
            T_cell = convhulln(T_nx);
            % - Defining the T_ws variable and shifting Wigner-Seitz cell to +T2
            for i = 1:size(T_cell,1)
                Tws{i,1} = T_nx(T_cell(i,:),1)-T(floor(l/2),1)+T2(1);
                Tws{i,2} = T_nx(T_cell(i,:),2)-T(floor(l/2),2)+T2(2);
                Tws{i,3} = T_nx(T_cell(i,:),3)-T(floor(l/2),3)+T2(3);
            end
            
            
            %% A - Defining the reciprocal-space unit cell
            % - A.1 - For simple and base-centered lattices, use T-vectors only
            if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                    crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                    crystal_args{1} == "TRI-Ci" || crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h"
                G1 = 2*pi*cross(T2,T3)/Tvol;
                G2 = 2*pi*cross(T3,T1)/Tvol;
                G3 = 2*pi*cross(T1,T2)/Tvol;
            % - A.2 - For body- and face-centered lattices, scale by a factor of 2
            elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h" ||...
                    crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                G1 = 4*pi*cross(T2,T3)/Tvol;
                G2 = 4*pi*cross(T3,T1)/Tvol;
                G3 = 4*pi*cross(T1,T2)/Tvol;
            end
            % -- Extracting the volume of reciprocal unit cell
            Gvol = cross(G1,G2)*G3';
            %% B - Extracting the corresponding Brilluoin Zone in reciprocal-space
            % - B.1 - The 7 Primitive (Simple) Bravais lattices yield no basis
            if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                    crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                    crystal_args{1} == "TRI-Ci"
                Gb0 = [0, 0, 0];
            % - B.2 - The 2 Base-Centered Bravais Lattices turn into base-centered in reciprocal space
            elseif crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h"
                Gb0 = [0, 0, 0;...
                    0.5*(G1+G2)];
            % - B.3 - The 3 Body-Centered Bravais Lattice turns to FCC in reciprocal space
            elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h"
                Gb0 = [0, 0, 0;...
                    0.5*(G1+G2);...
                    0.5*(G1+G3);...
                    0.5*(G2+G3)];
            % - B.4 - The 2 Face-Centered Bravais Lattices turns to BCC in reciprocal space
            elseif crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                Gb0 = [0, 0, 0;...
                    0.5*(G1+G2+G3)];
            end
            %% C - Extracting the reciprocal-space lattice over multiple translations of G
            l=1;
            for i=-1:1
                for j=0:2
                    for k=-1:1
                        for n = 1:size(Gb0,1)
                            G(l,:)=i*G1+j*G2+k*G3+Gb0(n,:);
                            l=l+1;
                        end
                    end
                end
            end
            % - C.1 - Finding the axes limited for a 2x2 super structure as in G
            if crystal == "CUB-Oh" || crystal == "HEX-D6h" || crystal == "RHL-D3d" ||...
                    crystal == "TET-D4h" || crystal == "ORC-D2h" || crystal == "MCL-C2h" ||...
                    crystal == "TRI-Ci"
                Glims = 1.04*[min(G(:,1)), max(G(:,1)), min(G(:,2)), max(G(:,2)), min(G(:,3)), max(G(:,3))];
            elseif crystal == "ORCC-D2h" || crystal == "MCLC-C2h" || crystal == "BCC-Oh" ||...
                    crystal == "BCT-D4h" || crystal == "ORCI-D2h" || crystal == "FCC-Oh" ||...
                    crystal == "ORCF-D2h"
                Glims = 1.04*[-1*norm(G1), norm(G1), 0, 2*norm(G2),-1*norm(G3), norm(G3)];
            end
            
            %% D - Finding all vector paths to link all nearest neighbour points
            Gnn = [];
            % - Filing through all possible points given in the G-matrix
            for i = 1:length(G)-1
                waitbar(i/length(G)-1, wbar, 'Extracting reciprocal-space lattice...', 'Name', 'extract_lattice');
                for j = i+1:length(G)
                    % -- Finding the Euclidean distance between the two lattice points
                    dist  = sqrt((G(i,1)-G(j,1)).^2+(G(i,2)-G(j,2)).^2+(G(i,3)-G(j,3)).^2);
                    %% 4.1 - Applying geometrical constraints for nearest neighbour points
                    
                    % --- For the 7 Primitive (Simple) Reciprocal lattices
                    % Points are linked if their distance is identical to the
                    % length of G1, G2 or G3.
                    if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                            crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                            crystal_args{1} == "TRI-Ci"
                        if (abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001                
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                        
                    % --- For the 2 Base-Centered Reciprocal Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % G1, G2 or G3, with the condition that it is not divisible by
                    % the BCC basis vector (as the base-centered points are only
                    % joined within the horizontal plane of the vertices).
                    elseif crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h" 
                        if abs(dist-norm(Gb0(2,:))) <= 0.0001
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (mod(G(i,2), 2*Gb0(2,2)) == 0 ||  abs(G(i,2)) <= 0.0001 ) && ((abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                        
                    % --- For the 2 Body-Centered Reciprocal Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % G1, G2 or G3, with the condition that it is not divisible by
                    % the BCC basis vector (as the body-centered points are only
                    % joined radially outwards to vertices of unit cell).
                    elseif crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                        if abs(dist-norm(Gb0(2,:))) <= 0.0001
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (mod(G(i,3), 2*Gb0(2,3)) == 0 || abs(G(i,3)) <= 0.001) && ((abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (mod(G(i,2), 2*Gb0(2,2)) == 0 || abs(G(i,2)) <= 0.001) && ((abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                        
                    % --- For the 3 Face-Centered Reciprocal Lattices
                    % Points are linked if their distance is equal to the length
                    % of the FCC basis vector, with the proviso that the two points
                    % are coplanar and not an FCC point. This is applied along each
                    % dimension. Furthermore, if their length is equal to G1, G2 or G3, 
                    % with the condition that it is not divisible by the FCC basis vector
                    % (as the face-centered points are only joined radially outwards to
                    % vertices of unit cell, not to other FCC points).
                    elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h"
                        if abs(dist-norm(Gb0(2,:))) <= 0.001 && abs(G(i,1)-G(j,1)) <= 0.001 && (abs(G(i,1)) <= 0.001 || mod(G(i,1), 2*Gb0(2,1)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif abs(dist-norm(Gb0(2,:))) <= 0.001 && abs(G(i,2)-G(j,2)) <= 0.001 && (abs(G(i,2)) <= 0.001 || mod(G(i,2), 2*Gb0(2,2)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif abs(dist-norm(Gb0(3,:))) <= 0.001 && abs(G(i,3)-G(j,3)) <= 0.001 && (abs(G(i,3)) <= 0.001 || mod(G(i,3), 2*Gb0(3,3)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (abs(dist-norm(G1))) <= 0.0001 && abs(G(i,1)-G(j,1)) <= 0.001 && (abs(G(i,1)) <= 0.001 || mod(G(i,1), 2*Gb0(2,1)) == 0) && (abs(G(i,3)) <= 0.001 || mod(G(i,3), 2*Gb0(3,3)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (abs(dist-norm(G2))) <= 0.0001 && abs(G(i,2)-G(j,2)) <= 0.001 && (abs(G(i,2)) <= 0.001 || mod(G(i,2), 2*Gb0(2,2)) == 0) && (abs(G(i,3)) <= 0.001 || mod(G(i,3), 2*Gb0(3,3)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                    end
                end
            end
            %% E - Computing the Voronoi diagram, which is identical to the First Brilluoin Zone
            % - Calculating Voronoi diagram
            [G_c, G_v] = voronoin(G); 
            G_nx = G_c(G_v{floor(l/2)},:);
            G_cell = convhulln(G_nx);
            % - Defining the G_bz variable and shifting Brilluoin zone to +G2
            for i = 1:size(G_cell,1)
                Gbz{i,1} = G_nx(G_cell(i,:),1)-G(floor(l/2),1)+G2(1);
                Gbz{i,2} = G_nx(G_cell(i,:),2)-G(floor(l/2),2)+G2(2);
                Gbz{i,3} = G_nx(G_cell(i,:),3)-G(floor(l/2),3)+G2(3);
            end
            
            %% Assigning the real- and reciprocal-information to MATLAB data structure
            waitbar(1, wbar, 'Assigning real- and reciprocal-variables...', 'Name', 'extract_lattice');
            % - Real-space information
            realStr.crystal = crystal;
            realStr.T1 = T1;
            realStr.T2 = T2;
            realStr.T3 = T3;
            realStr.Tb0 = Tb0;
            realStr.Tvol = Tvol;
            realStr.T = T;
            realStr.Tlims = Tlims;
            realStr.Tnn = Tnn;
            realStr.Tws = Tws;
            % - Reciprocal-space information
            reciStr.crystal = crystal;
            reciStr.G1 = G1;
            reciStr.G2 = G2;
            reciStr.G3 = G3;
            reciStr.Gb0 = Gb0;
            reciStr.Gvol = Gvol;
            reciStr.G = G;
            reciStr.Glims = Glims;
            reciStr.Gnn = Gnn;
            reciStr.Gbz = Gbz;
            %% Close wait-bar
            close(wbar);
            
            %% Figure summary of the real- and reciprocal-space structures
            if plotFig == 1
                fig = figure(); set(fig, 'position', [1,1,950,650]);
            
                % - REAL-SPACE FIGURE
                subplot(1,2,1); hold on;
                % -- Plotting the axes lines
                line([min(T(:,1)), max(T(:,1))], [0 0], [0,0],  'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [min(T(:,2)), max(T(:,2))], [0, 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [0, 0], [min(T(:,3)), max(T(:,3))], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Plotting the translational real-space lattice vectors
                plot3([0,T1(1)],[0,T1(2)],[0,T1(3)], '-', 'linewidth', 3, 'color', [0.8, 0.3, 0.3]);
                plot3([0,T2(1)],[0,T2(2)],[0,T2(3)], '-', 'linewidth', 3, 'color', [0.8, 0.3, 0.3]);
                plot3([0,T3(1)],[0,T3(2)],[0,T3(3)], '-', 'linewidth', 3, 'color', [0.8, 0.3, 0.3]);
                % -- Plotting the multiple translations of the real-space vectors
                plot3(T(:,1),T(:,2),T(:,3),'b.','markersize',20);
                % -- Plotting all the nearest neighbour points
                for i = 1:2:size(Tnn,1)
                    plot3([Tnn(i,1), Tnn(i+1,1)], [Tnn(i,2), Tnn(i+1,2)], [Tnn(i,3), Tnn(i+1,3)], 'k-', 'linewidth', 1);
                end
                % -- Plotting the Wigner-Seitz cell
                for i = 1:size(Tws,1)
                    patch(Tws{i,1}, Tws{i,2}, Tws{i,3},[0.8 0.3 0.3], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                xlabel('$$ \bf  x (\AA) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  y (\AA) $$', 'Interpreter', 'latex');
                zlabel('$$ \bf  z (\AA) $$', 'Interpreter', 'latex');
                xticks(round(-10*norm(T1):norm(T1):10*norm(T1),2));
                yticks(round(-10*norm(T2):norm(T2):10*norm(T2),2));
                zticks(round(-10*norm(T3):norm(T3):10*norm(T3),2));
                % -- Modify the view
                view(3); camlight(-30,24);
                title_txt1 = sprintf("%s; Real; Wigner-Seitz", crystal);
                title(title_txt1);
                axis tight equal vis3d; rotate3d on;
                pbaspect([1,1,1]);
                axis(Tlims);
            
                % - RECIPROCAL-SPACE FIGURE
                subplot(1,2,2); hold on;
                % -- Plotting the axes lines
                line([min(G(:,1)), max(G(:,1))], [0 0], [0,0],  'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [min(G(:,2)), max(G(:,2))], [0, 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [0, 0], [min(G(:,3)), max(G(:,3))], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Plotting the translational real-space lattice vectors
                plot3([0,G1(1)],[0,G1(2)],[0,G1(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,G2(1)],[0,G2(2)],[0,G2(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,G3(1)],[0,G3(2)],[0,G3(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                % -- Plotting the multiple translations of the real-space vectors
                plot3(G(:,1),G(:,2),G(:,3),'b.','markersize',20);
                % -- Plotting all the nearest neighbour points
                for i = 1:2:size(Gnn,1)
                    plot3([Gnn(i,1), Gnn(i+1,1)], [Gnn(i,2), Gnn(i+1,2)], [Gnn(i,3), Gnn(i+1,3)], 'k-', 'linewidth', 1);
                end
                % -- Plotting the first Brilluoin Zone
                for i = 1:size(Gbz,1)
                    patch(Gbz{i,1}, Gbz{i,2}, Gbz{i,3},[0.3 0.8 0.3], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                zlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                xticks(round(-10*norm(G1):norm(G1):10*norm(G1),2));
                yticks(round(-10*norm(G2):norm(G2):10*norm(G2),2));
                zticks(round(-10*norm(G3):norm(G3):10*norm(G3),2));
                % -- Modify the view
                view(3); camlight(-30,24);
                title_txt2 = sprintf("%s; Reciprocal; Brilluoin Zone", crystal);
                title(title_txt2);
                axis tight  equal vis3d; rotate3d on;
                pbaspect([1,1,1]);
                axis(Glims);
            end
        end
        
        % ---  Function to extract the Brilluoin zone overlay of the given crystal plane slice
        function planeStr = extract_plane(app, reciStr, crystal_plane, plotFig)
            % planeStr = extract_plane(reciStr, crystal_plane, plotFig)
            %   This is a function that extracts the planar Brilluoin zone 
            %   along the defined crystal plane (h,k,l) in miller indices notation.
            %   This Brilluoin zone plane then represents the planar slice that
            %   that is probed through via ARPES by changing the scan parameter.
            %
            %   IN:
            %   -  reciStr:         MATLAB data structure containing all reciprocal-space information below;
            %   -  crystal_plane:   string of the crystal plane to extract in "(hkl)" format.
            %   -  plotFig:         if 1, will plot figure summary, otherwise it wont.
            %
            %   OUT:
            %   planeStr          MATLAB data structure containing all BZ plane information below;
            %   - .(crystal):           string of the crystal type.
            %   - .(crystal_plane):     string of the (h,k,l) plane extracted.
            %	- .(area):              area of the planar BZ unit cell.
            %   - .(X):                 cell array of the x vertices of the planar BZ cell slice.
            %   - .(Y):                 cell array of the y vertices of the planar BZ cell slice.
            %	- .(gX):                double of the reciprocal-tesselation vector in x.
            %	- .(gY):                double of the reciprocal-tesselation vector in y.
            
            %% Default parameters
            if nargin < 4; plotFig = 0; end
            if isempty(plotFig); plotFig = 0;  end
            disp('-> Extracting plane slice of BZ...')
            wbar = waitbar(0, 'Extracting BZ overlay...', 'Name', 'extract_plane');
            
            %% Initialising the input variables
            crystal = reciStr.crystal;
            G1 =  reciStr.G1;
            G2 =  reciStr.G2;
            G3 =  reciStr.G3;
            Gbz = reciStr.Gbz;
            %% 1 - Extracting the First Brilluoin Zone boundary
            % - Extracting the point cloud for the First Brilluoin Zone
            x_pts = cell2mat(Gbz(:,1));
            y_pts = cell2mat(Gbz(:,2));
            z_pts = cell2mat(Gbz(:,3));
            cloud_pts = [x_pts, y_pts, z_pts];
            % - Projecting the point cloud onto the desired planar slice
            % --- For the (100) plane
            if crystal_plane == "(100)"
                proj_vec = [0,1,1];
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,2);
                ypts1 = proj_pts(:,3);
            % --- For the (010) plane
            elseif crystal_plane == "(010)"
                proj_vec = [1,0,1];
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,1);
                ypts1 = proj_pts(:,3);
            % --- For the (001) plane
            elseif crystal_plane == "(001)"
                proj_vec = [1,1,0];
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,1);
                ypts1 = proj_pts(:,2);
            % --- For the (110) plane
            elseif crystal_plane == "(110)"
                proj_vec = [1,0,1];
                R = [cosd(45) -sind(45) 0; sind(45) cosd(45) 0; 0 0 1];
                cloud_pts = cloud_pts * R;
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,1);
                ypts1 = proj_pts(:,3);
            end
            % - Shifting the boundary points to the origin
            xpts1 = xpts1 - (max(xpts1(:))+min(xpts1(:)))/2;
            ypts1 = ypts1 - (max(ypts1(:))+min(ypts1(:)))/2;
            
            %% 2 - Creating the boundary Brilluoin Zone polygon and finding the area of a single zone
            [b_indx, area] = boundary(xpts1, ypts1, 0.1);
            xpts1 = xpts1(b_indx);
            ypts1 = ypts1(b_indx);
            
            %% 3 - Tessalating the polygons to get the overlay
            l=1;
            % Iterating over all (or most of!) reciprocal space
            for i = -20:20
                for j = -20:20
                    % -- For the (100) plane
                    if crystal_plane == "(100)"
                        % -- Applying the tessalated shifts
                        X{l} = xpts1 + i*G2(2) + j*G3(2);
                        Y{l} = ypts1 + i*G2(3) + j*G3(3);
                    % -- For the (010) plane
                    elseif crystal_plane == "(010)"
                        % -- Applying the tessalated shifts
                        X{l} = xpts1 + i*G1(1) + j*G3(1);
                        Y{l} = ypts1 + i*G1(3) + j*G3(3);
                    % -- For the (001) plane
                    elseif crystal_plane == "(001)"
                        % -- Applying the tessalated shifts
                        X{l} = xpts1 + i*G1(1) + j*G2(1);
                        Y{l} = ypts1 + i*G1(2) + j*G2(2);
                    % -- For the (110) plane
                    elseif crystal_plane == "(110)"
                        % --- Extracting the shift vectors
                        xshift = norm(0.5*G1 + 0.5*G2);
                        yshift = norm(0.5*G3);
                        % -- Applying the tessalated shifts
                        if mod(i,2) == 1
                            X{l} = xpts1 + i*xshift;
                            Y{l} = ypts1 + yshift +  j*2*max(yshift);
                        else
                            X{l} = xpts1 + i*xshift;
                            Y{l} = ypts1 + j*2*max(yshift);
                        end
                    end
                    l = l + 1;  
                end
            end
            %% Assigning the overlay structure to a MATLAB structure
            waitbar(1, wbar, 'Assigning the BZ overlay variables...', 'Name', 'extract_lattice');
            % - Real-space information
            planeStr.crystal = crystal;
            planeStr.crystal_plane = crystal_plane;
            planeStr.area = area;
            planeStr.X = X;
            planeStr.Y = Y;
            if crystal_plane == "(100)"
                planeStr.gX = norm(G2);
                planeStr.gY = norm(G3);
            elseif crystal_plane == "(010)"
                planeStr.gX = norm(G1);
                planeStr.gY = norm(G3);
            elseif crystal_plane == "(001)"
                planeStr.gX = norm(G1);
                planeStr.gY = norm(G2);
            elseif crystal_plane == "(110)"
                planeStr.gX = norm(G1 + G2);
                planeStr.gY = norm(G3);
            end
            %% Close wait-bar
            close(wbar);
            %% Figure summary of the real- and reciprocal-space structures
            if plotFig == 1
                
                fig = figure(); set(fig, 'position', [1,1,950,650]);
                
                %% - RECIPROCAL-SPACE FIGURE
                subplot(1,2,1); hold on;
                % -- Plotting the axes lines
                line([min(reciStr.G(:,1)), max(reciStr.G(:,1))], [0 0], [0,0],  'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [min(reciStr.G(:,2)), max(reciStr.G(:,2))], [0, 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [0, 0], [min(reciStr.G(:,3)), max(reciStr.G(:,3))], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Plotting the translational real-space lattice vectors
                plot3([0,reciStr.G1(1)],[0,reciStr.G1(2)],[0,reciStr.G1(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,reciStr.G2(1)],[0,reciStr.G2(2)],[0,reciStr.G2(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,reciStr.G3(1)],[0,reciStr.G3(2)],[0,reciStr.G3(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                % -- Plotting the multiple translations of the real-space vectors
                plot3(reciStr.G(:,1),reciStr.G(:,2),reciStr.G(:,3),'b.','markersize',20);
                % -- Plotting all the nearest neighbour points
                for i = 1:2:size(reciStr.Gnn,1)
                    plot3([reciStr.Gnn(i,1), reciStr.Gnn(i+1,1)], [reciStr.Gnn(i,2), reciStr.Gnn(i+1,2)], [reciStr.Gnn(i,3), reciStr.Gnn(i+1,3)], 'k-', 'linewidth', 1);
                end
                % -- Plotting the first Brilluoin Zone
                for i = 1:size(reciStr.Gbz,1)
                    patch(reciStr.Gbz{i,1}, reciStr.Gbz{i,2}, reciStr.Gbz{i,3},[0.3 0.8 0.3], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % -- Plotting a path of the brilluoin zone slice
                xP = [-1e3, 1e3, 1e3, -1e3, -1e3];
                yP = [1e3, 1e3, -1e3, -1e3, 1e3];
                zP = [0, 0, 0, 0, 0];
                if crystal_plane == "(100)"
                    patch(zP, xP, yP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                elseif crystal_plane == "(010)"
                    patch(xP, zP+reciStr.G2(2), yP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                elseif crystal_plane == "(001)"
                    patch(xP, yP, zP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                elseif crystal_plane == "(110)"
                    xP = [1e3, 1e3, -1e3, -1e3, 1e3];
                    yP = [1e3, 1e3, -1e3, -1e3, 1e3]+reciStr.G2(2);
                    zP = [-1e3, 1e3, 1e3, -1e3, -1e3];
                    patch(xP, yP, zP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % -- Defining the axes properties
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                zlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                xticks(round(-10*norm(G1):norm(G1):10*norm(G1),2));
                yticks(round(-10*norm(G2):norm(G2):10*norm(G2),2));
                zticks(round(-10*norm(G3):norm(G3):10*norm(G3),2));
                % -- Modify the view
                view(3); camlight(-30,24);
                title_txt2 = sprintf("%s; Reciprocal; Brilluoin Zone", crystal);
                title(title_txt2);
                axis tight equal; rotate3d on;
                pbaspect([1,1,1]);
                axis(reciStr.Glims);
                
                %% - BRILLUOIN ZONE PLANAR SLICE
                subplot(1,2,2); hold on;
                for i = 1:size(X, 2)
                    plot(X{i}, Y{i}, 'k-','linewidth', 2);
                end
                % -- Defining the axes properties
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                title_txt = sprintf("%s; BZNavi through %s", planeStr.crystal, planeStr.crystal_plane);
                title(title_txt);
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                xticks(round(-100*norm(planeStr.gX):0.5*norm(planeStr.gX):100*norm(planeStr.gX),2));
                yticks(round(-100*norm(planeStr.gY):0.5*norm(planeStr.gY):100*norm(planeStr.gY),2));
                axis(1.5*[-norm(planeStr.gX), norm(planeStr.gX), -norm(planeStr.gY),norm(planeStr.gY)]);
                axis equal;
                % Axis labels, limits and ticks
                title_txt = sprintf("BZ plane; %s", crystal_plane);
                title(title_txt);
                axis equal;
                if crystal_plane == "(100)"
                    xlabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                elseif crystal_plane == "(010)"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                elseif crystal_plane == "(001)"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                elseif crystal_plane == "(110)"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                end
                xticks(round(-10*planeStr.gX:0.5*planeStr.gX:10*planeStr.gX,2));
                yticks(round(-10*planeStr.gY:0.5*planeStr.gY:10*planeStr.gY,2));
                axis([-planeStr.gX, planeStr.gX, -planeStr.gY, planeStr.gY]*1.05);
            end

        end
        
        % ---  Function to extract the Brilluoin zone overlay of the given crystal plane slice
        function arpesStr = extract_kcut(app, planeStr, navi_args)
            % extract_kcut(planeStr, navi_args)
            %   This is a function that extracts the crystallographic vectors
            %   of a general crystal structure in both real- and reciprocal-
            %   space based on the inputs. All 14 Bravais lattices can be 
            %   defined, including primitive-, base-, face- and body-centered
            %   lattices. The outputs are two MATLAB structures that contain
            %   all of the real- and reciprocal-space data.
            %
            %   IN:
            %   - planeStr          MATLAB data structure containing all BZ plane information below;
            %   .(crystal):                        string of the crystal type.
            %   .(crystal_plane):            string of the (h,k,l) plane extracted.
            %	.(area):                           area of the planar BZ unit cell.
            %   .(X):                     cell array of the x vertices of the planar BZ cell slice.
            %   .(Y):                     cell array of the y vertices of the planar BZ cell slice.
            %	.(gX):                  double of the reciprocal-tesselation vector in x.
            %	.(gY):                  double of the reciprocal-tesselation vector in y.
            %   -   navi_args:     MATLAB data structure containing all of the navigation arguments below;
            %   .(ePhi):                        Work function of analyser
            %   .(alpha):                      Angle between analyser and sample normal
            %	.(eB):                           Binding energy reference
            %   .(thtM):                       Primary manipulator rotation
            %   .(thtA):                       Analyser angle to sample normal in geometry is at origin
            %	.(kx):                          kx reference is at origin
            %	.(tltM):                       Primary tilt rotation is set to origin
            %	.(V000):                     user defined inner potential.
            %	.(hv):                          user defined photon energy.
            
            disp('-> Extracting k-space ARPES scan line...')
            
            %% 1.1 - Extracting the wave-vector path in k-space for the ARPES scan
            tht = linspace(-8, 8, 5e2);
            if length(navi_args.hv) == 1
                surfNormX = SurfNormX(navi_args.hv, navi_args.eBref, navi_args.kxref, navi_args.thtM, navi_args.thtAref);
                kx = Kxx(navi_args.hv, navi_args.eBref, navi_args.thtM, tht, surfNormX);
                kz = Kzz(navi_args.hv, navi_args.eBref, navi_args.thtM, tht, navi_args.tltM, navi_args.V000, surfNormX);
            elseif length(navi_args.hv) == 2
                surfNormX(1,:) = SurfNormX(navi_args.hv(1), navi_args.eBref, navi_args.kxref, navi_args.thtM, navi_args.thtAref);
                kx(1,:) = Kxx(navi_args.hv(1), navi_args.eBref, navi_args.thtM, tht, surfNormX(1,:));
                kz(1,:) = Kzz(navi_args.hv(1), navi_args.eBref, navi_args.thtM, tht, navi_args.tltM, navi_args.V000, surfNormX(1,:));
                surfNormX(2,:) = SurfNormX(navi_args.hv(2), navi_args.eBref, navi_args.kxref, navi_args.thtM, navi_args.thtAref);
                kx(2,:) = Kxx(navi_args.hv(2), navi_args.eBref, navi_args.thtM, tht, surfNormX(2,:));
                kz(2,:) = Kzz(navi_args.hv(2), navi_args.eBref, navi_args.thtM, tht, navi_args.tltM, navi_args.V000, surfNormX(2,:));
            end
            %% 1.2 - Extracting the kz-broadening
            eKE = navi_args.hv - navi_args.ePhi + navi_args.eBref;
            imfp = 10 .* (143 ./ eKE .^2 + 0.054.*sqrt(eKE));
            dkz = 1 ./ imfp;
            
            %% 2 - Updating the figure with the photon energy path
            fig = figure(3633);
            set(fig, 'position', [1,1,500,500], 'Name', 'BZ Navigation');
            hold on;
            %% 2.1 - Plotting the BZ planar slice
            for i = 1:size(planeStr.X, 2)
                plot(planeStr.X{i}, planeStr.Y{i}, 'k-','linewidth', 2);
            end
            % -- Defining the axes properties
            % - Figure formatting
            ax = gca;
            % Font properties
            ax.FontName = 'Helvetica'; ax.FontSize = 14;
            % Tick properties
            ax.TickLabelInterpreter = 'latex';
            ax.TickDir = 'both';
            % Box Styling properties
            ax.LineWidth = 1.2;
            % Axis labels, limits and ticks
            title_txt = sprintf("%s; BZNavi through %s", planeStr.crystal, planeStr.crystal_plane);
            title(title_txt);
            xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
            ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
            xticks(round(-100*norm(planeStr.gX):0.5*norm(planeStr.gX):100*norm(planeStr.gX),2));
            yticks(round(-100*norm(planeStr.gY):0.5*norm(planeStr.gY):100*norm(planeStr.gY),2));
            axis(1.5*[-norm(planeStr.gX), norm(planeStr.gX), -norm(planeStr.gY),norm(planeStr.gY)]);
            axis equal;
            %% 2.2 - Plotting the k-space patches probed with ARPES
            col = rand(1,3);
            % - For a single photon energy
            if length(navi_args.hv) == 1
                % -- Extract the planar limits of the ARPES scan
                kx_lower = (kx); kz_lower = (kz - dkz)';
                kx_upper = (kx); kz_upper = (kz + dkz)';
                % -- Plotting the ARPES scan plane which includes the Kz broadening
                patch([kx_upper, fliplr(kx_lower)], [kz_upper, fliplr(kz_lower)], col, 'edgecolor', 'none', 'facealpha', 0.5);
                % -- Plotting the ARPES scan line
                plot(kx, kz, '-', 'linewidth', 2, 'color', col);
                % -- Adding text
                text(mean(kx(:)), max(kz_lower(:))-0.05*norm(planeStr.gY), sprintf("%.2f eV", navi_args.hv),...
                    'color', col, 'Fontsize', 12, 'horizontalalignment', 'center');
            % - For a range of photon energies
            elseif length(navi_args.hv) == 2
                % -- Extract the planar limits of the ARPES scan
                kx_lower = (kx(1,:)); kz_lower = (kz(1,:) - dkz(1));
                kx_upper = (kx(2,:)); kz_upper = (kz(2,:) + dkz(2));
                % -- Plotting the ARPES scan plane which includes the Kz broadening
                patch([kx_upper, fliplr(kx_lower)], [kz_upper, fliplr(kz_lower)], col, 'edgecolor', 'none', 'facealpha', 0.5);
                % -- Plotting the ARPES scan line
                plot(kx(1,:), kz(1,:), '-', 'linewidth', 2, 'color', col);
                plot(kx(2,:), kz(2,:), '-', 'linewidth', 2, 'color', col);
                % -- Adding text
                text(mean(kx(1,:)), min(kz_lower(:))-0.05*norm(planeStr.gY), sprintf("%.2f eV", navi_args.hv(1)),...
                    'color', col, 'Fontsize', 12, 'horizontalalignment', 'center');
                text(mean(kx(2,:)), max(kz_upper(:))+0.05*norm(planeStr.gY), sprintf("%.2f eV", navi_args.hv(2)),...
                    'color', col, 'Fontsize', 12, 'horizontalalignment', 'center');
            end
            % - Adding the axes limits
            xlim([mean(kx(:))-1.5*norm(planeStr.gX), mean(kx(:))+1.5*norm(planeStr.gX)]);
            ylim([mean(kz(:))-1.5*norm(planeStr.gY), mean(kz(:))+1.5*norm(planeStr.gY)]);
            %% 4.0 - Assigning the data structure
            arpesStr = struct();
            arpesStr.navi_args = navi_args;
            arpesStr.planeStr = planeStr;
            arpesStr.imfp = imfp;
            arpesStr.dkz = dkz;
            arpesStr.kx = kx;
            arpesStr.kz = kz;
            arpesStr.kx_lower = kx_lower;
            arpesStr.kx_upper = kx_upper;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 1 - Initialisation of variables
            % - Crystal arguments
            app.crystal_args{1}         = app.Crystal_DropDown.Value;
            app.crystal_args{2}         = app.PlaneSlice_DropDown.Value;
            app.crystal_args{3}(1)      = app.a_EditField.Value;
            app.crystal_args{3}(2)      = app.b_EditField.Value;
            app.crystal_args{3}(3)      = app.c_EditField.Value;
            app.crystal_args{4}(1)      = app.alpha_EditField.Value;
            app.crystal_args{4}(2)      = app.beta_EditField.Value;
            app.crystal_args{4}(3)      = app.gamma_EditField.Value;
            % - Navigation arguments
            app.navi_args.ePhi          = app.ePhi_EditField.Value;
            app.navi_args.alpha         = app.alpha2_EditField.Value;
            app.navi_args.thtM          = app.thtM_EditField.Value;
            app.navi_args.tltM          = app.tltM_EditField.Value;
            app.navi_args.eBref         = app.eBref_EditField.Value;
            app.navi_args.thtAref       = app.thtAref_EditField.Value;
            app.navi_args.kxref         = app.kxref_EditField.Value;
            app.navi_args.V000          = app.V000_EditField.Value;
            app.navi_args.hv            = round(sort(str2double(split(app.hv_EditField.Value,':'))), 4);
            % - Updating IMFP and dKz
            % - Determination of IMFP and Kz broadening
            eKE = app.navi_args.hv - app.navi_args.ePhi + app.navi_args.eBref;
            imfp = 10 .* (143 ./ eKE .^2 + 0.054.*sqrt(eKE));
            dkz = 1 ./ imfp;
            app.IMFP_EditField.Value = round(mean(imfp), 5);
            app.dKz_EditField.Value = round(mean(dkz), 5);
%             %% 2 - Plotting the ARPES geometry figure
%             IS = imread('arpes_geometry.png');
%             image(app.ARPESgeometry_Axes, IS);
%             axis(app.ARPESgeometry_Axes, 'off', 'image');
        end

        % Button pushed function: ARPESGUI_Button
        function ARPESGUI_ButtonPushed(app, event)
            run ARPESGUI;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            %% 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            %% 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            %% Remove the GUI handle and restart
            close(gcbf); run BZNavi;
        end

        % Button pushed function: RESIZEGUI_Button
        function RESIZEGUI_ButtonPushed(app, event)
            %% Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 510;
            screen_size(4) = 550;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'arpes_data', app.myData);
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('APP : '); disp(app);
            disp('DATA (app.myData) : '); disp(app.myData);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = app.myData; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.myData);
            close(wbar);
        end

        % Value changed function: Crystal_DropDown
        function Crystal_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Crystal_DropDown.Value;
            
            %% 2 - Changing the GUI elements based on the selection
            % For 'CUB', 'BCC' and 'FCC' crystal
            if value == "CUB-Oh" || value == "BCC-Oh" || value == "FCC-Oh"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'HEX' crystal
            elseif value == "HEX-D6h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'RHL' crystal
            elseif value == "RHL-D3d"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'TET' and 'BCT' crystal
            elseif value == "TET-D4h" || value == "BCT-D4h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif value == "ORC-D2h" || value == "ORCC-D2h" || value == "ORCI-D2h" || value == "ORCF-D2h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'MCL' and 'MCLC' crystal
            elseif value == "MCL-C2h" || value == "MCLC-C2h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'off';
            % For 'TRI' crystal
            elseif value == "TRI-Ci" 
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'on';
            end
            % - Setting the value of the argument
            app.crystal_args{1} = value;
            
            %% 3 - Run through all crystallographic inputs to update their values
            a_EditFieldValueChanged(app);
            b_EditFieldValueChanged(app);
            c_EditFieldValueChanged(app);
            alpha_EditFieldValueChanged(app);
            beta_EditFieldValueChanged(app);
            gamma_EditFieldValueChanged(app);
            
            %% 4 - Printing the change that has occurred
            fprintf("--> Crystal: " + app.crystal_args{1} + " \n");
        end

        % Value changed function: PlaneSlice_DropDown
        function PlaneSlice_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.PlaneSlice_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.crystal_args{2} = value;
            fprintf("--> Plane Slice: " + app.crystal_args{2} + " \n");
        end

        % Value changed function: a_EditField
        function a_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.a_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - a == b & c
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - a == b ONLY
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - a == b & c
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - a == b AND a =/= c
                if value == app.crystal_args{3}(3); value = value - 0.001; end
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - a =/= b AND a =/= c
                if value == app.crystal_args{3}(2); value = value - 0.001; end
                if value == app.crystal_args{3}(3); value = value - 0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - a =/= c ONLY
                if value == app.crystal_args{3}(3); value = value - 0.001; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for a
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(1) = value;
            fprintf("--> a (Ang): " + app.crystal_args{3}(1) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: b_EditField
        function b_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.b_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - b == a & c
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - b == a ONLY
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - b == a & c
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - b == a AND b =/= c
                if value == app.crystal_args{3}(3); value = value-0.001; end
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - b =/= a AND b =/= c
                if value == app.crystal_args{3}(1); value = value-0.001; end
                if value == app.crystal_args{3}(3); value = value-0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - No conditions for the 'MCL' or 'MCLC' crystal for b
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                 % - No conditions for the 'TRI' crystal for b
            end
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(2) = value;
            fprintf("--> b (Ang): " + app.crystal_args{3}(2) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: c_EditField
        function c_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.c_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - c == a & b
                if value ~= app.crystal_args{2}(1); app.crystal_args{2}(1) = value; end
                if value ~= app.crystal_args{2}(2); app.crystal_args{2}(2) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - No conditions for the 'HEX' crystal for c(nm)
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - c == a & b
                if value ~= app.crystal_args{2}(1); app.crystal_args{2}(1) = value; end
                if value ~= app.crystal_args{2}(2); app.crystal_args{2}(2) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - c =/= a
                if value == app.crystal_args{2}(1); value = value-0.001; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - c =/= a
                if value == app.crystal_args{3}(1); value = value-0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - c =/= a
                if value == app.crystal_args{3}(1); value = value-0.001; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for c(nm)
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(3) = value;
            fprintf("--> c (Ang): " + app.crystal_args{3}(3) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
            
        end

        % Value changed function: alpha_EditField
        function alpha_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.alpha_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - Applying condition that alpha must be 90degrees and equal to beta
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - Applying condition that alpha must be 90degrees and equal to gamma
                value = 90;
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for alpha
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(1) = value;
            fprintf("--> alpha (deg): " + app.crystal_args{4}(1) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Value changed function: beta_EditField
        function beta_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.beta_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - Applying condition that alpha must be 90degrees and equal to beta
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - Applying condition that beta must not be 90 degrees
                if value == 90; value = value - 1; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for beta
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(2) = value;
            fprintf("--> beta (deg): " + app.crystal_args{4}(2) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Value changed function: gamma_EditField
        function gamma_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.gamma_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - Applying condition that gamma must be equal to 120 degrees
                value = 120;
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - Applying condition that alpha must be equal to gamma and 90 degrees
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for beta
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(3) = value;
            fprintf("--> gamma (deg): " + app.crystal_args{4}(3) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Button pushed function: Extract2DBZ_Button
        function Extract2DBZ_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            app.myData.crystal_args = app.crystal_args;
            [app.myData.realStr, app.myData.reciStr] = extract_lattice(app, app.myData.crystal_args);
            %% 2 - Extracting the 2D Brilluoin Zone planar slice
            app.myData.planeStr = extract_plane(app, app.myData.reciStr, app.myData.crystal_args{2}, 1);
        end

        % Button pushed function: Extract3DBZ_Button
        function Extract3DBZ_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            app.myData.crystal_args = app.crystal_args;
            [app.myData.realStr, app.myData.reciStr] = extract_lattice(app, app.myData.crystal_args, 1);
        end

        % Value changed function: ePhi_EditField
        function ePhi_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ePhi_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.ePhi = value;
            fprintf("--> ePhi (eV): " + app.navi_args.ePhi + " \n");
        end

        % Value changed function: alpha2_EditField
        function alpha2_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.alpha2_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.alpha = value;
            fprintf("--> alpha (deg.): " + app.navi_args.alpha + " \n");
        end

        % Value changed function: thtM_EditField
        function thtM_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.thtM_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.thtM = value;
            fprintf("--> thtM (deg.): " + app.navi_args.thtM + " \n");
        end

        % Value changed function: tltM_EditField
        function tltM_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.tltM_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.tltM = value;
            fprintf("--> tltM (deg.): " + app.navi_args.tltM + " \n");
        end

        % Value changed function: eBref_EditField
        function eBref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.eBref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.eBref = value;
            fprintf("--> eBref (eV): " + app.navi_args.eBref + " \n");
        end

        % Value changed function: thtAref_EditField
        function thtAref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.thtAref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.thtAref = value;
            fprintf("--> thtAref (deg.): " + app.navi_args.thtAref + " \n");
        end

        % Value changed function: kxref_EditField
        function kxref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kxref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.kxref = value;
            fprintf("--> kxref (Ang^-1): " + app.navi_args.kxref + " \n");
        end

        % Value changed function: V000_EditField
        function V000_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.V000_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.navi_args.V000 = value;
            fprintf("--> V000 (eV): " + app.navi_args.V000 + " \n");
        end

        % Value changed function: hv_EditField
        function hv_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.hv_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Validity check on input
            minVal = 8;
            maxVal = 1e5;
            % - If no entry is made, default to a single hv value
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = 400;
            % - If a single entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 1
                if isnan(data_entry(1)); data_entry(1) = 400; end
                if data_entry(1) < minVal; data_entry(1) = minVal; end
                if data_entry(1) > maxVal; data_entry(1) = maxVal; end
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                if data_entry(1) < minVal ||  data_entry(1) > maxVal || isnan(data_entry(1)); data_entry(1) = minVal; end
                if data_entry(2) < minVal ||  data_entry(2) > maxVal || isnan(data_entry(1)); data_entry(2) = maxVal; end
            end
            %% 3 - Assigning and printing the output
            app.navi_args.hv = data_entry;
            if length(app.navi_args.hv) == 1
                app.hv_EditField.Value = string(app.navi_args.hv); 
                fprintf("--> hv (eV): " + string(app.navi_args.hv) + " \n");
            elseif length(app.navi_args.hv) == 2
                app.hv_EditField.Value = string(app.navi_args.hv(1) + ":" + app.navi_args.hv(2)); 
                fprintf("--> hv (eV): " + string(app.navi_args.hv(1) + ":" + app.navi_args.hv(2)) + " \n");
            end
            %% 4 - Setting the IMFP and Kz broadening
            % - Determination of IMFP and Kz broadening
            eKE = app.navi_args.hv - app.navi_args.ePhi + app.navi_args.eBref;
            imfp = 10 .* (143 ./ eKE .^2 + 0.054.*sqrt(eKE));
            dkz = 1 ./ imfp;
            app.IMFP_EditField.Value = round(mean(imfp), 5);
            app.dKz_EditField.Value = round(mean(dkz), 5);
        end

        % Button pushed function: ExtractARPES_Button
        function ExtractARPES_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            app.myData.crystal_args = app.crystal_args;
            [app.myData.realStr, app.myData.reciStr] = extract_lattice(app, app.myData.crystal_args, 0);
            %% 2 - Extracting the 2D Brilluoin Zone planar slice
            app.myData.planeStr = extract_plane(app, app.myData.reciStr, app.myData.crystal_args{2}, 0);
            %% 3 - Extracting and viewing the ARPES cut that is taken
            app.myData.navi_args = app.navi_args;
            app.myData.arpesStr = extract_kcut(app, app.myData.planeStr, app.myData.navi_args);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'Brilluoin Zone Navigation';
            app.CLOSEFIGS_Button = uibutton(app.UIFigure, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Closes all open figures, but leaves the current one open.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.UIFigure, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Restarts the app to its default state.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEGUI_Button = uibutton(app.UIFigure, 'push');
            app.RESIZEGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEGUI_ButtonPushed, true);
            app.RESIZEGUI_Button.Tooltip = {'Resizes the app to its default size.'};
            app.RESIZEGUI_Button.Text = 'RESIZE GUI';
            app.ARPESGUI_Button = uibutton(app.UIFigure, 'push');
            app.ARPESGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESGUI_ButtonPushed, true);
            app.ARPESGUI_Button.Tooltip = {'Back to the main ARPESGUI menu.'};
            app.ARPESGUI_Button.Text = 'ARPESGUI (main)';
            app.BrilluoinZoneExtractionPanel = uipanel(app.UIFigure);
            app.BrilluoinZoneExtractionPanel.Title = 'Brilluoin Zone Extraction';
            app.Extract2DBZ_Button = uibutton(app.BrilluoinZoneExtractionPanel, 'push');
            app.Extract2DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @Extract2DBZ_ButtonPushed, true);
            app.Extract2DBZ_Button.Tooltip = {'Extract the 2D slice through the Brilluoin Zone of the crystal structure defined through the plane slice (hkl).'};
            app.Extract2DBZ_Button.Text = 'Extract / View 2D BZ';
            app.Extract3DBZ_Button = uibutton(app.BrilluoinZoneExtractionPanel, 'push');
            app.Extract3DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @Extract3DBZ_ButtonPushed, true);
            app.Extract3DBZ_Button.Tooltip = {'Extract the 3D Brilluoin Zone of the crystal structure defined.'};
            app.Extract3DBZ_Button.Text = 'Extract / View 3D BZ';
            app.CrystalDropDownLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.CrystalDropDownLabel.Text = 'Crystal:';
            app.Crystal_DropDown = uidropdown(app.BrilluoinZoneExtractionPanel);
            app.Crystal_DropDown.Items = {'CUB-Oh', 'BCC-Oh', 'FCC-Oh', 'HEX-D6h', 'RHL-D3d', 'TET-D4h', 'BCT-D4h', 'ORC-D2h', 'ORCC-D2h', 'ORCI-D2h', 'ORCF-D2h', 'MCL-C2h', 'MCLC-C2h', 'TRI-Ci'};
            app.Crystal_DropDown.ValueChangedFcn = createCallbackFcn(app, @Crystal_DropDownValueChanged, true);
            app.Crystal_DropDown.Tooltip = {'Real-space crystal structure.'};
            app.Crystal_DropDown.Value = 'FCC-Oh';
            app.PlaneSlicehklDropDownLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.PlaneSlicehklDropDownLabel.Text = 'Plane Slice (hkl):';
            app.PlaneSlice_DropDown = uidropdown(app.BrilluoinZoneExtractionPanel);
            app.PlaneSlice_DropDown.Items = {'(001)', '(010)', '(100)', '(110)'};
            app.PlaneSlice_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneSlice_DropDownValueChanged, true);
            app.PlaneSlice_DropDown.Tooltip = {'Miller place slice through the real-space crystal.'};
            app.PlaneSlice_DropDown.Value = '(001)';
            app.aAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.aAngLabel.Text = 'a (Ang.):';
            app.a_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.a_EditField.Limits = [0 Inf];
            app.a_EditField.ValueDisplayFormat = '%.4f';
            app.a_EditField.ValueChangedFcn = createCallbackFcn(app, @a_EditFieldValueChanged, true);
            app.a_EditField.Tooltip = {'Lattice constant in Angstroms along the x-axis (or crystal vector a).'};
            app.a_EditField.Value = 5.431;
            app.bAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.bAngLabel.Text = 'b (Ang.):';
            app.b_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.b_EditField.Limits = [0 Inf];
            app.b_EditField.ValueDisplayFormat = '%.4f';
            app.b_EditField.ValueChangedFcn = createCallbackFcn(app, @b_EditFieldValueChanged, true);
            app.b_EditField.Enable = 'off';
            app.b_EditField.Tooltip = {'Lattice constant in Angstroms along the y-axis (or crystal vector b).'};
            app.b_EditField.Value = 5.431;
            app.cAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.cAngLabel.Text = 'c(Ang.):';
            app.c_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.c_EditField.Limits = [0 Inf];
            app.c_EditField.ValueDisplayFormat = '%.4f';
            app.c_EditField.ValueChangedFcn = createCallbackFcn(app, @c_EditFieldValueChanged, true);
            app.c_EditField.Enable = 'off';
            app.c_EditField.Tooltip = {'Lattice constant in Angstroms along the z-axis (or crystal vector c).'};
            app.c_EditField.Value = 5.431;
            app.alphadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.alphadegLabel.Text = 'alpha (deg.):';
            app.alpha_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.alpha_EditField.Limits = [-180 180];
            app.alpha_EditField.ValueDisplayFormat = '%.4f';
            app.alpha_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_EditFieldValueChanged, true);
            app.alpha_EditField.Enable = 'off';
            app.alpha_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector a, relative to the x-axis.'};
            app.alpha_EditField.Value = 90;
            app.betadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.betadegLabel.Text = 'beta (deg.):';
            app.beta_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.beta_EditField.Limits = [-180 180];
            app.beta_EditField.ValueDisplayFormat = '%.4f';
            app.beta_EditField.ValueChangedFcn = createCallbackFcn(app, @beta_EditFieldValueChanged, true);
            app.beta_EditField.Enable = 'off';
            app.beta_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector b, relative to the y-axis.'};
            app.beta_EditField.Value = 90;
            app.gammadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.gammadegLabel.Text = 'gamma (deg.):';
            app.gamma_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.gamma_EditField.Limits = [-180 180];
            app.gamma_EditField.ValueDisplayFormat = '%.4f';
            app.gamma_EditField.ValueChangedFcn = createCallbackFcn(app, @gamma_EditFieldValueChanged, true);
            app.gamma_EditField.Enable = 'off';
            app.gamma_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector c, relative to the z-axis.'};
            app.gamma_EditField.Value = 90;
            app.BrilluoinZoneNavigationPanel = uipanel(app.UIFigure);
            app.BrilluoinZoneNavigationPanel.Title = 'Brilluoin Zone Navigation';
            app.V000eVLabel = uilabel(app.BrilluoinZoneNavigationPanel);
            app.V000eVLabel.Text = 'V000 (eV):';
            app.V000_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'numeric');
            app.V000_EditField.Limits = [0 Inf];
            app.V000_EditField.ValueDisplayFormat = '%.4f';
            app.V000_EditField.ValueChangedFcn = createCallbackFcn(app, @V000_EditFieldValueChanged, true);
            app.V000_EditField.Tooltip = {'Inner potential which is usually tuned for each sample so that the ARPES states match the Brilluoin zone.'};
            app.V000_EditField.Value = 12.57;
            app.ARPESgeometryPanel = uipanel(app.BrilluoinZoneNavigationPanel);
            app.ARPESgeometryPanel.Title = 'ARPES geometry';
            app.ePhieVEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.ePhieVEditFieldLabel.Text = 'ePhi (eV):';
            app.ePhi_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.ePhi_EditField.Limits = [0 Inf];
            app.ePhi_EditField.ValueDisplayFormat = '%.4f';
            app.ePhi_EditField.ValueChangedFcn = createCallbackFcn(app, @ePhi_EditFieldValueChanged, true);
            app.ePhi_EditField.Tooltip = {'Sample work function.'};
            app.ePhi_EditField.Value = 4.5;
            app.alphadegEditField_2Label = uilabel(app.ARPESgeometryPanel);
            app.alphadegEditField_2Label.Text = 'alpha (deg.):';
            app.alpha2_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.alpha2_EditField.Limits = [-180 180];
            app.alpha2_EditField.ValueDisplayFormat = '%.4f';
            app.alpha2_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha2_EditFieldValueChanged, true);
            app.alpha2_EditField.Tooltip = {'Incline of the analyser axis'};
            app.alpha2_EditField.Value = 20;
            app.thtMdegEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.thtMdegEditFieldLabel.Text = 'thtM (deg.):';
            app.thtM_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.thtM_EditField.Limits = [-180 180];
            app.thtM_EditField.ValueDisplayFormat = '%.4f';
            app.thtM_EditField.ValueChangedFcn = createCallbackFcn(app, @thtM_EditFieldValueChanged, true);
            app.thtM_EditField.Tooltip = {'Primary manipulator rotation (generally 0).'};
            app.tltMdegEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.tltMdegEditFieldLabel.Text = 'tltM (deg.):';
            app.tltM_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.tltM_EditField.Limits = [-180 180];
            app.tltM_EditField.ValueDisplayFormat = '%.4f';
            app.tltM_EditField.ValueChangedFcn = createCallbackFcn(app, @tltM_EditFieldValueChanged, true);
            app.tltM_EditField.Tooltip = {'Primary manipulator tilt (generally 0).'};
            app.eBrefeVLabel = uilabel(app.ARPESgeometryPanel);
            app.eBrefeVLabel.Text = 'eB ref (eV):';
            app.eBref_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.eBref_EditField.Limits = [0 Inf];
            app.eBref_EditField.ValueDisplayFormat = '%.4f';
            app.eBref_EditField.ValueChangedFcn = createCallbackFcn(app, @eBref_EditFieldValueChanged, true);
            app.eBref_EditField.Tooltip = {'Binding energy reference of a known ARPES state.'};
            app.thtArefdegEditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.thtArefdegEditFieldLabel.Text = 'thtA ref(deg.):';
            app.thtAref_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.thtAref_EditField.Limits = [-180 180];
            app.thtAref_EditField.ValueDisplayFormat = '%.4f';
            app.thtAref_EditField.ValueChangedFcn = createCallbackFcn(app, @thtAref_EditFieldValueChanged, true);
            app.thtAref_EditField.Tooltip = {'Angle along the analyser slit to a reference ARPES feature (equivalent to the angle in an ARPES scan).'};
            app.kxrefAng1EditFieldLabel = uilabel(app.ARPESgeometryPanel);
            app.kxrefAng1EditFieldLabel.Text = 'kx ref (Ang.^-1):';
            app.kxref_EditField = uieditfield(app.ARPESgeometryPanel, 'numeric');
            app.kxref_EditField.Limits = [-180 180];
            app.kxref_EditField.ValueDisplayFormat = '%.4f';
            app.kxref_EditField.ValueChangedFcn = createCallbackFcn(app, @kxref_EditFieldValueChanged, true);
            app.kxref_EditField.Tooltip = {'Kx value of the angle reference in the ARPES feature of thtA.'};
            app.ExtractARPES_Button = uibutton(app.BrilluoinZoneNavigationPanel, 'push');
            app.ExtractARPES_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractARPES_ButtonPushed, true);
            app.ExtractARPES_Button.Tooltip = {'Extract the ARPES Brilluoin Zone slice over a given photon energy range.'};
            app.ExtractARPES_Button.Text = 'Extract ARPES BZ Slice';
            app.hveVEditFieldLabel = uilabel(app.BrilluoinZoneNavigationPanel);
            app.hveVEditFieldLabel.Text = 'hv (eV):';
            app.hv_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'text');
            app.hv_EditField.ValueChangedFcn = createCallbackFcn(app, @hv_EditFieldValueChanged, true);
            app.hv_EditField.Tooltip = {'Photon energy. Either a single value x or range from xmin:xmax.'};
            app.hv_EditField.Value = '400';
            app.IMFPAngLabel = uilabel(app.BrilluoinZoneNavigationPanel);
            app.IMFPAngLabel.Text = 'IMFP (Ang.):';
            app.IMFP_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'numeric');
            app.IMFP_EditField.Limits = [0 Inf];
            app.IMFP_EditField.ValueDisplayFormat = '%.4f';
            app.IMFP_EditField.Editable = 'off';
            app.IMFP_EditField.Tooltip = {'Estimate of the IMFP of electrons at the given photon energy.'};
            app.dKzAng1Label = uilabel(app.BrilluoinZoneNavigationPanel);
            app.dKzAng1Label.Text = 'dKz (Ang.^-1):';
            app.dKz_EditField = uieditfield(app.BrilluoinZoneNavigationPanel, 'numeric');
            app.dKz_EditField.Limits = [0 Inf];
            app.dKz_EditField.ValueDisplayFormat = '%.4f';
            app.dKz_EditField.Editable = 'off';
            app.dKz_EditField.Tooltip = {'Estimation of the kz broadening associated with the given photon energy.'};
            app.Image = uiimage(app.BrilluoinZoneNavigationPanel);
            app.Image.ImageSource = 'arpes_geometry.png';
            app.SAVE_Button = uibutton(app.UIFigure, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB structure file.'};
            app.SAVE_Button.Text = 'SAVE';
            app.TRANSFER_Button = uibutton(app.UIFigure, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.APPINFO_Button = uibutton(app.UIFigure, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BZNavi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end