classdef APP_BZNAVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Global_GridLayout           matlab.ui.container.GridLayout
        Image_GridLayout            matlab.ui.container.GridLayout
        Image_Caption_ARPES         matlab.ui.control.Label
        Image_ARPES                 matlab.ui.control.Image
        Image_Caption               matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        GridLayout                  matlab.ui.container.GridLayout
        Geometry_Panel              matlab.ui.container.Panel
        GeometryParams_GridLayout   matlab.ui.container.GridLayout
        hv_EditField                matlab.ui.control.EditField
        hveVEditFieldLabel          matlab.ui.control.Label
        ExtractCut_Button           matlab.ui.control.Button
        V000_EditField              matlab.ui.control.NumericEditField
        AtomicweightamuLabel_3      matlab.ui.control.Label
        tltM_EditField              matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_7      matlab.ui.control.Label
        kxRef_EditField             matlab.ui.control.NumericEditField
        AtomicweightamuLabel_2      matlab.ui.control.Label
        thtM_EditField              matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_6      matlab.ui.control.Label
        thtARef_EditField           matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_8      matlab.ui.control.Label
        alphaM_EditField            matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_10     matlab.ui.control.Label
        ebRef_EditField             matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_5      matlab.ui.control.Label
        ePhi_EditField              matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_9      matlab.ui.control.Label
        BZSlice_Panel               matlab.ui.container.Panel
        BZSlice_GridLayout          matlab.ui.container.GridLayout
        View2DBZ_Button             matlab.ui.control.Button
        hkl_DropDown                matlab.ui.control.DropDown
        PlaneSlicehklDropDownLabel  matlab.ui.control.Label
        CrysStructure_Panel         matlab.ui.container.Panel
        MatParams_GridLayout        matlab.ui.container.GridLayout
        Orient_DropDown             matlab.ui.control.DropDown
        View3DBZ_Button             matlab.ui.control.Button
        gamma_EditField             matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_3      matlab.ui.control.Label
        c_EditField                 matlab.ui.control.NumericEditField
        AtomicweightamuLabel        matlab.ui.control.Label
        beta_EditField              matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_4      matlab.ui.control.Label
        b_EditField                 matlab.ui.control.NumericEditField
        bAngLabel                   matlab.ui.control.Label
        alpha_EditField             matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_2      matlab.ui.control.Label
        a_EditField                 matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label        matlab.ui.control.Label
        Crystal_DropDown            matlab.ui.control.DropDown
        CrystalDropDownLabel        matlab.ui.control.Label
        Material_GridLayout         matlab.ui.container.GridLayout
        Material_Lamp               matlab.ui.control.Lamp
        Material_EditField          matlab.ui.control.EditField
        MaterialEditFieldLabel      matlab.ui.control.Label
        BUTTONS_GridLayout          matlab.ui.container.GridLayout
        AppButtons_GridLayout       matlab.ui.container.GridLayout
        SAVE_Button                 matlab.ui.control.Button
        TRANSFER_Button             matlab.ui.control.Button
        APPINFO_Button              matlab.ui.control.Button
        RESIZEUI_Button             matlab.ui.control.Button
        RESTART_Button              matlab.ui.control.Button
        CLOSEFIGS_Button            matlab.ui.control.Button
        AppTitle                    matlab.ui.control.Label
        MAINMENU_GridLayout         matlab.ui.container.GridLayout
        MAINMENU_Button             matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        bzNaviData
        % - Variables for determining the crystal structure
        material    % material being considered
        crystal     % crystal unit cell being considered
        a           % a lattice constant of crystal unit cell (Angstroms)
        b           % b lattice constant of crystal unit cell (Angstroms)
        c           % c lattice constant of crystal unit cell (Angstroms)
        alpha       % alpha opposite angle of crystal unit cell (degrees)
        beta        % beta opposite angle of crystal unit cell (degrees)
        gamma       % gamma opposite angle of crystal unit cell (degrees)
        orient      % orientation of the crystal
        hkl         % miller indice plane cut (hkl)
        % - Variables for the ARPES geometry
        alphaM      % angle between analyser and sample normal (July 2022: 20deg, from August 2022: 9deg)
        ePhi        % work function of analyser
        thtM        % primary manipulator rotation
        tltM        % primary tilt rotation is set to origin
        eBref       % binding energy reference
        thtAref     % analyser angle to sample normal in geometry is at origin
        kxref       % kx reference at the origin
        V000        % user defined inner potential
        hv          % single or 1x2 vector of [minHv, maxHv] for the user defined photon energy
    end
    
    % Private functions available to the app only
    methods (Access = private)
        % - Extract the data structure
        function bzNaviData = extract_myData(app)
            bzNaviData = struct();
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [bzNaviData.real_3D, bzNaviData.reciprocal_3D] = get_crystal_props(crystal_args, 0);
            % 3 - Extracting the BZ slice
            bzNaviData.bz_slice_2D = get_bz_slice(bzNaviData.reciprocal_3D, app.hkl, 0);
            % 4 - Extracting the photon energy cuts
            navi_args.ePhi      = app.ePhi; 
            navi_args.alpha     = app.alphaM;
            navi_args.thtM      = app.thtM; 
            navi_args.eBref     = app.eBref; 
            navi_args.thtAref	= app.thtAref; 
            navi_args.kxref     = app.kxref; 
            navi_args.tltM      = app.tltM; 
            navi_args.V000      = app.V000; 
            bzNaviData.hv_cut = get_hv_cut(app.hv, navi_args, 0);
        end

        % - Check the material properties to the user-input and see if the match
        function update_lamp4material(app)
            mat_props = get_mpd_props(app.material);
            % -- If there is an entry for the material
            idx = 0;
            if ~isempty(mat_props)
                % --- Extract the material parameters
                mat_crystal = string(mat_props.CRY_STRUCT{1});
                mat_a       = round(mat_props.CRY_A/100,4);
                mat_b       = round(mat_props.CRY_B/100,4);
                mat_c       = round(mat_props.CRY_C/100,4);
                mat_alpha   = round(mat_props.CRY_ALPHA,4);
                mat_beta    = round(mat_props.CRY_BETA,4);
                mat_gamma   = round(mat_props.CRY_GAMMA,4);
                % --- Extract the user input values
                user_crystal = app.crystal;
                user_a       = round(app.a,4);
                user_b       = round(app.b,4);
                user_c       = round(app.c,4);
                user_alpha   = round(app.alpha,4);
                user_beta    = round(app.beta,4);
                user_gamma   = round(app.gamma,4);
                % --- Compare the material parameters with user inputs
                % --- Comparing crystal structure
                if ~contains(user_crystal, mat_crystal); idx = idx+1; end
                % --- Comparing the crystal parameters
                if user_a ~= mat_a; idx = idx+1; end
                if user_b ~= mat_b; idx = idx+1; end
                if user_c ~= mat_c; idx = idx+1; end
                if user_alpha ~= mat_alpha; idx = idx+1; end
                if user_beta ~= mat_beta; idx = idx+1; end
                if user_gamma ~= mat_gamma; idx = idx+1; end
            % -- If no entry for the material exists
            else; idx = idx+1;
            end
            % -- Assigning lamp color
            if idx == 0; app.Material_Lamp.Color = [0 1 0];
            else; app.Material_Lamp.Color = [1 0 0];
            end
        end

        % - Check the crystal structure to the user-input and see if the match
        function str_crystal_dropdown = update_crystaltype_value(app, str_crystal)
            if contains(str_crystal,"CUB-cP-Oh");        str_crystal_dropdown = app.Crystal_DropDown.Items{1};
            elseif contains(str_crystal,"CUB-cI-Oh");    str_crystal_dropdown = app.Crystal_DropDown.Items{2};
            elseif contains(str_crystal,"CUB-cF-Oh");    str_crystal_dropdown = app.Crystal_DropDown.Items{3};
            elseif contains(str_crystal,"HEX-hP-D6h");   str_crystal_dropdown = app.Crystal_DropDown.Items{4};
            elseif contains(str_crystal,"RHL-hR-D3d");   str_crystal_dropdown = app.Crystal_DropDown.Items{5};
            elseif contains(str_crystal,"TET-tP-D4h");   str_crystal_dropdown = app.Crystal_DropDown.Items{6};
            elseif contains(str_crystal,"TET-tI-D4h");   str_crystal_dropdown = app.Crystal_DropDown.Items{7};
            elseif contains(str_crystal,"ORC-oP-D2h ");  str_crystal_dropdown = app.Crystal_DropDown.Items{8};
            elseif contains(str_crystal,"ORC-oS-D2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{9};
            elseif contains(str_crystal,"ORC-oI-D2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{10};
            elseif contains(str_crystal,"ORC-oF-D2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{11};
            elseif contains(str_crystal,"MCL-mP-C2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{12};
            elseif contains(str_crystal,"MCL-mS-C2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{13};
            elseif contains(str_crystal,"TRI-aP-Ci");    str_crystal_dropdown = app.Crystal_DropDown.Items{14};
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1 - Default parameters upon startup
            app.bzNaviData      = struct();
            app.material    = app.Material_EditField.Value;
            app.crystal     = app.Crystal_DropDown.Value;
            app.a           = app.a_EditField.Value;        
            app.b           = app.b_EditField.Value;
            app.c           = app.c_EditField.Value;   
            app.alpha       = app.alpha_EditField.Value; 
            app.beta        = app.beta_EditField.Value;
            app.gamma       = app.gamma_EditField.Value;
            app.orient      = app.Orient_DropDown.Value;
            app.hkl         = [0,0,1];
            app.alphaM      = app.alphaM_EditField.Value;
            app.ePhi        = app.ePhi_EditField.Value;
            app.thtM        = app.thtM_EditField.Value;      
            app.tltM        = app.tltM_EditField.Value;
            app.eBref       = app.ebRef_EditField.Value;  
            app.thtAref     = app.thtARef_EditField.Value; 
            app.kxref       = app.kxRef_EditField.Value;
            app.V000        = app.V000_EditField.Value;
            app.hv          = app.hv_EditField.Value;
            % 2 - Running UI elements
            Material_EditFieldValueChanged(app);
            hkl_DropDownValueChanged(app);
            hv_EditFieldValueChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run APP_BZNAVI; 
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 610;
            screen_size(4) = 475;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            app.bzNaviData = extract_myData(app);
            disp('bzNaviData : '); disp(app.bzNaviData);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            app.bzNaviData = extract_myData(app);
            assignin('base', 'bzNaviData', app.bzNaviData);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.bzNaviData = extract_myData(app);
            dataStruc = app.bzNaviData; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.bzNaviData);
            close(wbar);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Value changed function: Material_EditField
        function Material_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.Material_EditField.Value;
            app.material    = string(value);
            % 2 - Extracting the material parameters from the materials database
            mat_props = get_mpd_props(app.material);
            % -- If there is an entry for the material
            if ~isempty(mat_props)
                % --- Extract the material parameters
                mat_crystal = string(mat_props.CRY_STRUCT{1}); if mat_crystal == ""; mat_crystal = app.crystal; end
                mat_a       = mat_props.CRY_A ./ 100;   if isnan(mat_a); mat_a = app.a; end
                mat_b       = mat_props.CRY_B ./ 100;   if isnan(mat_b); mat_b = app.b; end
                mat_c       = mat_props.CRY_C ./ 100;   if isnan(mat_c); mat_c = app.c; end
                mat_alpha   = mat_props.CRY_ALPHA;      if isnan(mat_alpha);  mat_alpha = app.alpha; end
                mat_beta    = mat_props.CRY_BETA;       if isnan(mat_beta);   mat_beta = app.beta; end
                mat_gamma   = mat_props.CRY_GAMMA;      if isnan(mat_gamma);  mat_gamma = app.gamma; end
                % --- Defining the new lattice constants and angles from materials database
                app.crystal = update_crystaltype_value(app, mat_crystal);
                app.a       = round(mat_a,4);
                app.b       = round(mat_b,4);
                app.c       = round(mat_c,4);
                app.alpha   = round(mat_alpha,4);
                app.beta    = round(mat_beta,4);
                app.gamma   = round(mat_gamma,4);
                % --- Updating all UI elements
                app.Material_EditField.Value    = app.material;
                app.Crystal_DropDown.Value      = app.crystal; 
                app.a_EditField.Value           = app.a;
                app.b_EditField.Value           = app.b;
                app.c_EditField.Value           = app.c;
                app.alpha_EditField.Value       = app.alpha;
                app.beta_EditField.Value        = app.beta;
                app.gamma_EditField.Value       = app.gamma;
                % --- Assigning output and printing change
                fprintf("-> material: " + string(app.material) + "\n");
                fprintf("-- crystal: " + string(app.crystal) + "\n");
                % --- Updating UI elements
                Crystal_DropDownValueChanged(app);
                a_EditFieldValueChanged(app);
                b_EditFieldValueChanged(app);
                c_EditFieldValueChanged(app);
                alpha_EditFieldValueChanged(app);
                beta_EditFieldValueChanged(app);
                gamma_EditFieldValueChanged(app);
            % -- If no entry for the material exists
            else
                fprintf("-> material: " + string(app.material) + " - material parameters not in database. \n");
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
        end

        % Value changed function: Orient_DropDown
        function Orient_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Orient_DropDown.Value;
            app.orient  = string(value);
            % 2 - Assigning output and printing change
            fprintf("-- Orientation = " + app.orient + "\n");
        end

        % Value changed function: Crystal_DropDown
        function Crystal_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value          = app.Crystal_DropDown.Value;
            app.crystal    = string(value);
            % 2 - Set the image of the crystal being used
            if contains(app.crystal,"CUB-cP-Oh");        app.Image.ImageSource = 'CUB-cP-Oh_Simple Cubic.png';
            elseif contains(app.crystal,"CUB-cI-Oh");    app.Image.ImageSource = 'CUB-cI-Oh_Body-Centered Cubic.png';
            elseif contains(app.crystal,"CUB-cF-Oh");    app.Image.ImageSource = 'CUB-cF-Oh_Face-Centered Cubic.png';
            elseif contains(app.crystal,"HEX-hP-D6h");   app.Image.ImageSource = 'HEX-hP-D6h_Simple Hexagonal.png';
            elseif contains(app.crystal,"RHL-hR-D3d");   app.Image.ImageSource = 'RHL-hR-D3d_Simple Rhombahedral.png';
            elseif contains(app.crystal,"TET-tP-D4h");   app.Image.ImageSource = 'TET-tP-D4h_Simple Tetragonal.png';
            elseif contains(app.crystal,"TET-tI-D4h");   app.Image.ImageSource = 'TET-tI-D4h_Body-Centered Tetragonal.png';
            elseif contains(app.crystal,"ORC-oP-D2h ");  app.Image.ImageSource = 'ORC-oP-D2h_Simple Orthorhombic.png';
            elseif contains(app.crystal,"ORC-oS-D2h");   app.Image.ImageSource = 'ORC-oS-D2h_Base-Centered Orthorhombic.png';
            elseif contains(app.crystal,"ORC-oI-D2h");   app.Image.ImageSource = 'ORC-oI-D2h_Body-Centered Orthorhombic.png';
            elseif contains(app.crystal,"ORC-oF-D2h");   app.Image.ImageSource = 'ORC-oF-D2h_Face-Centered Orthorhombic.png';
            elseif contains(app.crystal,"MCL-mP-C2h");   app.Image.ImageSource = 'MCL-mP-C2h_Simple Monoclinic.png';
            elseif contains(app.crystal,"MCL-mS-C2h");   app.Image.ImageSource = 'MCL-mS-C2h_Base-Centered Monoclinic.png';
            elseif contains(app.crystal,"TRI-aP-Ci");    app.Image.ImageSource = 'TRI-aP-Ci_Simple Triclinic.png';
            end
            % 3 - Applying symmetry arguments to the inputs
            offset = 0;
            % For 'CUB', 'BCC' and 'FCC' crystal
            if contains(app.crystal,"CUB")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b & c
                if app.a ~= app.b; app.b = app.a; end
                if app.a ~= app.c; app.c = app.a; end
                % - alpha = beta = gamma = 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b ONLY
                if app.a ~= app.b; app.b = app.a; end
                % - alpha = beta = 90, gamma = 120
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 120; app.gamma = 120; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b & c
                if app.a ~= app.b; app.b = app.a; end
                if app.a ~= app.c; app.c = app.a; end
                % - alpha = beta = gamma = arbitrary
                if app.beta ~= app.alpha;  app.beta = app.alpha; end
                if app.gamma ~= app.alpha; app.gamma = app.alpha; end
            % For 'TET' crystals
            elseif contains(app.crystal,"TET")
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b AND a =/= c
                if app.a == app.c; app.c = app.a - offset; end
                if app.a ~= app.b; app.b = app.a; end
                % - alpha = beta = gamma = 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'ORC' crystals
            elseif contains(app.crystal,"ORC")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a =/= b AND a =/= c
                if app.a == app.b; app.b = app.a - offset; end
                if app.a == app.c; app.c = app.a - offset; end
                % - alpha = beta = gamma = 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'off';
                % - a =/= c ONLY
                if app.a == app.c; app.c = app.a - offset; end
                % - alpha = gamma = 90, beta = arbitrary
                if app.alpha ~= 90; app.alpha = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'on';
            end
            % 4 - Applying symmetry arguments to the inputs
            app.a_EditField.Value       = app.a;
            app.b_EditField.Value       = app.b;
            app.c_EditField.Value       = app.c;
            app.alpha_EditField.Value   = app.alpha;
            app.beta_EditField.Value    = app.beta;
            app.gamma_EditField.Value   = app.gamma;
            % 5 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
        end

        % Value changed function: a_EditField
        function a_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value   = app.a_EditField.Value;
            app.a   = value;
            % 2 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 3 - Assigning output and printing change
            fprintf("-- a = " + string(app.a) + " Angstrom\n");
        end

        % Value changed function: b_EditField
        function b_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.b_EditField.Value;
            app.b   = value;
            % 2 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 3 - Assigning output and printing change
            fprintf("-- b = " + string(app.b) + " Angstrom\n");
        end

        % Value changed function: c_EditField
        function c_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.c_EditField.Value;
            app.c   = value;
            % 2 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 3 - Assigning output and printing change
            fprintf("-- c = " + string(app.c) + " Angstrom \n");
        end

        % Value changed function: alpha_EditField
        function alpha_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.alpha_EditField.Value;
            app.alpha   = value;
            % 2 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 3 - Assigning output and printing change
            fprintf("-- alpha = " + string(app.alpha) + "deg \n");
        end

        % Value changed function: beta_EditField
        function beta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.beta_EditField.Value;
            app.beta    = value;
            % 2 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 3 - Assigning output and printing change
            fprintf("-- beta = " + string(app.beta) + "deg\n");
        end

        % Value changed function: gamma_EditField
        function gamma_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.gamma_EditField.Value;
            app.gamma   = value;
            % 2 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 3 - Assigning output and printing change
            fprintf("-- gamma = " + string(app.gamma) + "deg\n");
        end

        % Button pushed function: View3DBZ_Button
        function View3DBZ_ButtonPushed(app, event)
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [~, ~] = get_crystal_props(crystal_args, 1);
        end

        % Value changed function: hkl_DropDown
        function hkl_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.hkl_DropDown.Value;
            str_hkl     = string(value);
            % 2 - Extracting the input defined by the user
            if strcmpi(str_hkl, "(100)");       app.hkl = [1,0,0];
            elseif strcmpi(str_hkl, "(010)");   app.hkl = [0,1,0];
            elseif strcmpi(str_hkl, "(001)");   app.hkl = [0,0,1];
            elseif strcmpi(str_hkl, "(110)");   app.hkl = [1,1,0];
            end
            % 3 - Assigning output and printing change
            fprintf("-- (hkl) = " + str_hkl + "\n");
        end

        % Button pushed function: View2DBZ_Button
        function View2DBZ_ButtonPushed(app, event)
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [~, reciStr] = get_crystal_props(crystal_args, 0);
            % 3 - Extracting the BZ slice
            get_bz_slice(reciStr, app.hkl, 1);
        end

        % Value changed function: alphaM_EditField
        function alphaM_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.alphaM_EditField.Value;
            app.alphaM    = value;
            % 2 - Assigning output and printing change
            fprintf("-- alphaM = " + string(app.alphaM) + "deg. \n");
        end

        % Value changed function: ePhi_EditField
        function ePhi_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.ePhi_EditField.Value;
            app.ePhi    = value;
            % 2 - Assigning output and printing change
            fprintf("-- ePhi = " + string(app.ePhi) + " eV\n");
        end

        % Value changed function: thtM_EditField
        function thtM_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.thtM_EditField.Value;
            app.thtM    = value;
            % 2 - Assigning output and printing change
            fprintf("-- thtM = " + string(app.thtM) + "deg. \n");
        end

        % Value changed function: tltM_EditField
        function tltM_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.tltM_EditField.Value;
            app.tltM    = value;
            % 2 - Assigning output and printing change
            fprintf("-- tltM = " + string(app.tltM) + "deg. \n");
        end

        % Value changed function: ebRef_EditField
        function ebRef_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.ebRef_EditField.Value;
            app.eBref    = value;
            % 2 - Assigning output and printing change
            fprintf("-- eBref = " + string(app.eBref) + " eV\n");
        end

        % Value changed function: thtARef_EditField
        function thtARef_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.thtARef_EditField.Value;
            app.thtAref    = value;
            % 2 - Assigning output and printing change
            fprintf("-- thtAref = " + string(app.thtAref) + "deg. \n");
        end

        % Value changed function: kxRef_EditField
        function kxRef_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.kxRef_EditField.Value;
            app.kxref    = value;
            % 2 - Assigning output and printing change
            fprintf("-- kxref = " + string(app.kxref) + " Ang^-1 \n");
        end

        % Value changed function: V000_EditField
        function V000_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.V000_EditField.Value;
            app.V000    = value;
            % 2 - Assigning output and printing change
            fprintf("-- V000 = " + string(app.V000) + " eV\n");
        end

        % Value changed function: hv_EditField
        function hv_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.hv_EditField.Value;
            if contains(string(value),","); data_entry = round(sort(str2double(split(value,','))), 4);
            elseif contains(string(value),";"); data_entry = round(sort(str2double(split(value,';'))), 4);
            else; data_entry = round(sort(str2double(split(value,':'))), 4);
            end
            % 2 - Validity check on input
            minVal = 8;
            maxVal = 1e5;
            % - If no entry is made, default to a single hv value
            if isempty(data_entry)
                data_entry = 400;
            % - If a single entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 1
                if isnan(data_entry(1)); data_entry(1) = 400; end
                if data_entry(1) < minVal; data_entry(1) = minVal; end
                if data_entry(1) > maxVal; data_entry(1) = maxVal; end
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                if data_entry(1) < minVal ||  data_entry(1) > maxVal || isnan(data_entry(1)); data_entry(1) = minVal; end
                if data_entry(2) < minVal ||  data_entry(2) > maxVal || isnan(data_entry(1)); data_entry(2) = maxVal; end
            % - If there are more than two entries, only store the smallest and largest
            elseif length(data_entry) > 2
                data_entry(2:end-1) = [];
                if data_entry(1) < minVal ||  data_entry(1) > maxVal || isnan(data_entry(1)); data_entry(1) = minVal; end
                if data_entry(end) < minVal ||  data_entry(end) > maxVal || isnan(data_entry(1)); data_entry(end) = maxVal; end
            end
            % 3 - Assigning and printing the output
            app.hv = data_entry;
            if length(app.hv) == 1
                app.hv_EditField.Value = string(app.hv); 
                fprintf("--> hv (eV): " + string(app.hv) + " \n");
            elseif length(app.hv) == 2
                app.hv_EditField.Value = string(app.hv(1) + ":" + app.hv(2)); 
                fprintf("--> hv (eV): " + string(app.hv(1) + ":" + app.hv(2)) + " \n");
            end
        end

        % Button pushed function: ExtractCut_Button
        function ExtractCut_ButtonPushed(app, event)
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [~, reciStr] = get_crystal_props(crystal_args, 0);
            % 3 - Extracting the BZ slice
            bz_slice = get_bz_slice(reciStr, app.hkl, 0);
            % 4 - Extracting the photon energy cuts
            navi_args.ePhi      = app.ePhi; 
            navi_args.alpha     = app.alphaM;
            navi_args.thtM      = app.thtM; 
            navi_args.eBref     = app.eBref; 
            navi_args.thtAref	= app.thtAref; 
            navi_args.kxref     = app.kxref; 
            navi_args.tltM      = app.tltM; 
            navi_args.V000      = app.V000; 
            hv_cut = get_hv_cut(app.hv, navi_args, 0);
            % 5 - Plotting the final Brilluoin Zone overlay
            fig = figure(); set(fig, 'position', [50,50,600,600]);
            hold on;
            plot_bz_slice(bz_slice);
            plot_hv_cut(hv_cut);
            ax = gca; ax.TickLabelInterpreter = 'latex'; ax.TickDir = 'both';
            xlabel('$$ \bf  k_x\ (\AA^{-1}) $$', 'Interpreter', 'latex');
            ylabel('$$ \bf  k_{\perp}\ (\AA^{-1}) $$', 'Interpreter', 'latex');
            line([0, 0], [-1e5, 1e5], 'color', 'k', 'linestyle', '-');
            axis equal; grid on;
            xlim([min(hv_cut.kx(:)), max(hv_cut.kx(:))]*1.05);
            ylim([0.95*min(hv_cut.kz(:)), 1.05*max(hv_cut.kz(:))]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Brilluoin Zone Navigation';
            app.BUTTONS_GridLayout = uigridlayout(app.Global_GridLayout);
            app.AppButtons_GridLayout = uigridlayout(app.BUTTONS_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.GridLayout = uigridlayout(app.Global_GridLayout);
            app.Material_GridLayout = uigridlayout(app.GridLayout);
            app.MaterialEditFieldLabel = uilabel(app.Material_GridLayout);
            app.MaterialEditFieldLabel.Text = 'Material:';
            app.Material_EditField = uieditfield(app.Material_GridLayout, 'text');
            app.Material_EditField.ValueChangedFcn = createCallbackFcn(app, @Material_EditFieldValueChanged, true);
            app.Material_EditField.Tooltip = {'Enter an element / compound to search for the crystal structure parameters in the Materials Properties Database.'};
            app.Material_EditField.Value = 'Si';
            app.Material_Lamp = uilamp(app.Material_GridLayout);
            app.Material_Lamp.Tooltip = {'If green, input parameters match the Materials Properties Database.'};
            app.CrysStructure_Panel = uipanel(app.GridLayout);
            app.CrysStructure_Panel.Title = 'Crystal Structure';
            app.MatParams_GridLayout = uigridlayout(app.CrysStructure_Panel);
            app.CrystalDropDownLabel = uilabel(app.MatParams_GridLayout);
            app.CrystalDropDownLabel.Text = 'Crystal:';
            app.Crystal_DropDown = uidropdown(app.MatParams_GridLayout);
            app.Crystal_DropDown.Items = {'CUB-cP-Oh : Simple Cubic', 'CUB-cI-Oh : Body-Centered Cubic', 'CUB-cF-Oh : Face-Centered Cubic', 'HEX-hP-D6h : Simple Hexagonal', 'RHL-hR-D3d : Simple Rhombahedral', 'TET-tP-D4h : Simple Tetragonal', 'TET-tI-D4h : Body-Centered Tetragonal', 'ORC-oP-D2h : Simple Orthorhombic', 'ORC-oS-D2h : Base-Centered Orthorhombic', 'ORC-oI-D2h : Body-Centered Orthorhombic', 'ORC-oF-D2h : Face-Centered Orthorhombic', 'MCL-mP-C2h : Simple Monoclinic', 'MCL-mS-C2h : Base-Centered Monoclinic', 'TRI-aP-Ci : Simple Triclinic'};
            app.Crystal_DropDown.ValueChangedFcn = createCallbackFcn(app, @Crystal_DropDownValueChanged, true);
            app.Crystal_DropDown.Tooltip = {'Select the crystal type.'};
            app.Crystal_DropDown.Value = 'CUB-cF-Oh : Face-Centered Cubic';
            app.Bulkdensitygcm3Label = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label.Text = 'a (Ang.):';
            app.a_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.a_EditField.Limits = [0 Inf];
            app.a_EditField.ValueDisplayFormat = '%.4f';
            app.a_EditField.ValueChangedFcn = createCallbackFcn(app, @a_EditFieldValueChanged, true);
            app.a_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_2 = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label_2.Text = 'alpha (deg.):';
            app.alpha_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.alpha_EditField.Limits = [-180 180];
            app.alpha_EditField.ValueDisplayFormat = '%.4f';
            app.alpha_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_EditFieldValueChanged, true);
            app.alpha_EditField.Value = 90;
            app.bAngLabel = uilabel(app.MatParams_GridLayout);
            app.bAngLabel.Text = 'b (Ang.):';
            app.b_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.b_EditField.Limits = [0 Inf];
            app.b_EditField.ValueDisplayFormat = '%.4f';
            app.b_EditField.ValueChangedFcn = createCallbackFcn(app, @b_EditFieldValueChanged, true);
            app.b_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_4 = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label_4.Text = 'beta (deg.):';
            app.beta_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.beta_EditField.Limits = [-180 180];
            app.beta_EditField.ValueDisplayFormat = '%.4f';
            app.beta_EditField.ValueChangedFcn = createCallbackFcn(app, @beta_EditFieldValueChanged, true);
            app.beta_EditField.Value = 90;
            app.AtomicweightamuLabel = uilabel(app.MatParams_GridLayout);
            app.AtomicweightamuLabel.Text = 'c (Ang.):';
            app.c_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.c_EditField.Limits = [0 Inf];
            app.c_EditField.ValueDisplayFormat = '%.4f';
            app.c_EditField.ValueChangedFcn = createCallbackFcn(app, @c_EditFieldValueChanged, true);
            app.c_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_3 = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label_3.Text = 'gamma (deg.):';
            app.gamma_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.gamma_EditField.Limits = [-180 180];
            app.gamma_EditField.ValueDisplayFormat = '%.4f';
            app.gamma_EditField.ValueChangedFcn = createCallbackFcn(app, @gamma_EditFieldValueChanged, true);
            app.gamma_EditField.Value = 90;
            app.View3DBZ_Button = uibutton(app.MatParams_GridLayout, 'push');
            app.View3DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @View3DBZ_ButtonPushed, true);
            app.View3DBZ_Button.Tooltip = {'Extract and view the 3D real-space and reciprocal-space structures.'};
            app.View3DBZ_Button.Text = 'Extract / View 3D BZ';
            app.Orient_DropDown = uidropdown(app.MatParams_GridLayout);
            app.Orient_DropDown.Items = {'<100>', '<010>', '<001>'};
            app.Orient_DropDown.ValueChangedFcn = createCallbackFcn(app, @Orient_DropDownValueChanged, true);
            app.Orient_DropDown.Tooltip = {'Orientation of the crystal.'};
            app.Orient_DropDown.Value = '<100>';
            app.BZSlice_Panel = uipanel(app.GridLayout);
            app.BZSlice_Panel.Title = 'Brilluoin-Zone Slice';
            app.BZSlice_GridLayout = uigridlayout(app.BZSlice_Panel);
            app.PlaneSlicehklDropDownLabel = uilabel(app.BZSlice_GridLayout);
            app.PlaneSlicehklDropDownLabel.Text = 'Plane Slice (hkl):';
            app.hkl_DropDown = uidropdown(app.BZSlice_GridLayout);
            app.hkl_DropDown.Items = {'(100)', '(010)', '(001)', '(110)'};
            app.hkl_DropDown.ValueChangedFcn = createCallbackFcn(app, @hkl_DropDownValueChanged, true);
            app.hkl_DropDown.Tooltip = {'Plane slice through the 3D Brilluoin zone to be mapped.'};
            app.hkl_DropDown.Value = '(100)';
            app.View2DBZ_Button = uibutton(app.BZSlice_GridLayout, 'push');
            app.View2DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @View2DBZ_ButtonPushed, true);
            app.View2DBZ_Button.Tooltip = {'Extract and view the 2D Brilluoin Zone slice.'};
            app.View2DBZ_Button.Text = 'Extract / View 2D BZ Slice';
            app.Geometry_Panel = uipanel(app.GridLayout);
            app.Geometry_Panel.Title = 'Measurement Geometry';
            app.GeometryParams_GridLayout = uigridlayout(app.Geometry_Panel);
            app.Bulkdensitygcm3Label_9 = uilabel(app.GeometryParams_GridLayout);
            app.Bulkdensitygcm3Label_9.Text = 'ePhi (eV):';
            app.ePhi_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.ePhi_EditField.Limits = [0 Inf];
            app.ePhi_EditField.ValueDisplayFormat = '%.4f';
            app.ePhi_EditField.ValueChangedFcn = createCallbackFcn(app, @ePhi_EditFieldValueChanged, true);
            app.ePhi_EditField.Value = 4.5;
            app.Bulkdensitygcm3Label_5 = uilabel(app.GeometryParams_GridLayout);
            app.Bulkdensitygcm3Label_5.Text = 'eB ref (eV):';
            app.ebRef_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.ebRef_EditField.Limits = [0 Inf];
            app.ebRef_EditField.ValueDisplayFormat = '%.4f';
            app.ebRef_EditField.ValueChangedFcn = createCallbackFcn(app, @ebRef_EditFieldValueChanged, true);
            app.ebRef_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_10 = uilabel(app.GeometryParams_GridLayout);
            app.Bulkdensitygcm3Label_10.Text = 'alpha (deg.):';
            app.alphaM_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.alphaM_EditField.Limits = [0 Inf];
            app.alphaM_EditField.ValueDisplayFormat = '%.4f';
            app.alphaM_EditField.ValueChangedFcn = createCallbackFcn(app, @alphaM_EditFieldValueChanged, true);
            app.alphaM_EditField.Value = 9;
            app.Bulkdensitygcm3Label_8 = uilabel(app.GeometryParams_GridLayout);
            app.Bulkdensitygcm3Label_8.Text = 'thtA ref (deg.):';
            app.thtARef_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.thtARef_EditField.Limits = [0 Inf];
            app.thtARef_EditField.ValueDisplayFormat = '%.4f';
            app.thtARef_EditField.ValueChangedFcn = createCallbackFcn(app, @thtARef_EditFieldValueChanged, true);
            app.Bulkdensitygcm3Label_6 = uilabel(app.GeometryParams_GridLayout);
            app.Bulkdensitygcm3Label_6.Text = 'thtM (deg.):';
            app.thtM_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.thtM_EditField.Limits = [0 Inf];
            app.thtM_EditField.ValueDisplayFormat = '%.4f';
            app.thtM_EditField.ValueChangedFcn = createCallbackFcn(app, @thtM_EditFieldValueChanged, true);
            app.AtomicweightamuLabel_2 = uilabel(app.GeometryParams_GridLayout);
            app.AtomicweightamuLabel_2.Text = 'kx ref (Ang.^-1):';
            app.kxRef_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.kxRef_EditField.Limits = [0 Inf];
            app.kxRef_EditField.ValueDisplayFormat = '%.4f';
            app.kxRef_EditField.ValueChangedFcn = createCallbackFcn(app, @kxRef_EditFieldValueChanged, true);
            app.Bulkdensitygcm3Label_7 = uilabel(app.GeometryParams_GridLayout);
            app.Bulkdensitygcm3Label_7.Text = 'tltM (deg.):';
            app.tltM_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.tltM_EditField.Limits = [0 Inf];
            app.tltM_EditField.ValueDisplayFormat = '%.4f';
            app.tltM_EditField.ValueChangedFcn = createCallbackFcn(app, @tltM_EditFieldValueChanged, true);
            app.AtomicweightamuLabel_3 = uilabel(app.GeometryParams_GridLayout);
            app.AtomicweightamuLabel_3.Text = 'V000 (eV):';
            app.V000_EditField = uieditfield(app.GeometryParams_GridLayout, 'numeric');
            app.V000_EditField.Limits = [0 Inf];
            app.V000_EditField.ValueDisplayFormat = '%.4f';
            app.V000_EditField.ValueChangedFcn = createCallbackFcn(app, @V000_EditFieldValueChanged, true);
            app.V000_EditField.Value = 12.57;
            app.ExtractCut_Button = uibutton(app.GeometryParams_GridLayout, 'push');
            app.ExtractCut_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractCut_ButtonPushed, true);
            app.ExtractCut_Button.Tooltip = {'Extract and view the 2D Brilluoin Zone slice.'};
            app.ExtractCut_Button.Text = 'Extract ARPES Cut';
            app.hveVEditFieldLabel = uilabel(app.GeometryParams_GridLayout);
            app.hveVEditFieldLabel.Text = 'hv (eV):';
            app.hv_EditField = uieditfield(app.GeometryParams_GridLayout, 'text');
            app.hv_EditField.ValueChangedFcn = createCallbackFcn(app, @hv_EditFieldValueChanged, true);
            app.hv_EditField.Value = '400:2000';
            app.Image_GridLayout = uigridlayout(app.Global_GridLayout);
            app.Image = uiimage(app.Image_GridLayout);
            app.Image.Tooltip = {'Image of the real-space unit cell selected.'};
            app.Image.ImageSource = 'CUB-cF-Oh_Face-Centered Cubic.png';
            app.Image_Caption = uilabel(app.Image_GridLayout);
            app.Image_Caption.Text = 'Real-space unit-cell';
            app.Image_ARPES = uiimage(app.Image_GridLayout);
            app.Image_ARPES.Tooltip = {'Image of the real-space unit cell selected.'};
            app.Image_ARPES.ImageSource = 'arpes_geometry.png';
            app.Image_Caption_ARPES = uilabel(app.Image_GridLayout);
            app.Image_Caption_ARPES.Text = 'Measurement Geometry';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_BZNAVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end