classdef App_ARPEScape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        PESToolsPCCPSIPanel    matlab.ui.container.Panel
        PESTools_GridLayout    matlab.ui.container.GridLayout
        PESModelling_Button    matlab.ui.control.Button
        BZNavi_Button          matlab.ui.control.Button
        MAToolsVNSPSIPanel     matlab.ui.container.Panel
        MATools_GridLayout     matlab.ui.container.GridLayout
        ResBeamline_Button     matlab.ui.control.Button
        KNavi_Button           matlab.ui.control.Button
        ARPESView_Button       matlab.ui.control.Button
        AppTitle               matlab.ui.control.Label
        AppButtons_GridLayout  matlab.ui.container.GridLayout
        APPINFO_Button         matlab.ui.control.Button
        RESIZEUI_Button        matlab.ui.control.Button
        RESTART_Button         matlab.ui.control.Button
        CLOSEFIGS_Button       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'ARPEScape-v8.1.0 (procopios.constantinou@psi.ch)';
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_ARPEScape;
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 440;
            screen_size(4) = 440;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
        end

        % Button pushed function: ARPESView_Button
        function ARPESView_ButtonPushed(app, event)
            run ARPESView;
        end

        % Button pushed function: KNavi_Button
        function KNavi_ButtonPushed(app, event)
            run KNavi;
        end

        % Button pushed function: ResBeamline_Button
        function ResBeamline_ButtonPushed(app, event)
            run ResBeamline;
        end

        % Button pushed function: BZNavi_Button
        function BZNavi_ButtonPushed(app, event)
            run App_ARPEScape_BZNAVI;
        end

        % Button pushed function: PESModelling_Button
        function PESModelling_ButtonPushed(app, event)
            run App_ARPEScape_XPSFitting;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AppButtons_GridLayout = uigridlayout(app.GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.AppTitle = uilabel(app.GridLayout);
            app.AppTitle.Text = 'ARPEScape Main Menu';
            app.MAToolsVNSPSIPanel = uipanel(app.GridLayout);
            app.MAToolsVNSPSIPanel.Title = 'MATools (VNS @ PSI)';
            app.MATools_GridLayout = uigridlayout(app.MAToolsVNSPSIPanel);
            app.ARPESView_Button = uibutton(app.MATools_GridLayout, 'push');
            app.ARPESView_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESView_ButtonPushed, true);
            app.ARPESView_Button.Text = 'ARPESView';
            app.KNavi_Button = uibutton(app.MATools_GridLayout, 'push');
            app.KNavi_Button.ButtonPushedFcn = createCallbackFcn(app, @KNavi_ButtonPushed, true);
            app.KNavi_Button.Text = 'KNavi';
            app.ResBeamline_Button = uibutton(app.MATools_GridLayout, 'push');
            app.ResBeamline_Button.ButtonPushedFcn = createCallbackFcn(app, @ResBeamline_ButtonPushed, true);
            app.ResBeamline_Button.Text = 'ResBeamline';
            app.PESToolsPCCPSIPanel = uipanel(app.GridLayout);
            app.PESToolsPCCPSIPanel.Title = 'PESTools (PCC @ PSI)';
            app.PESTools_GridLayout = uigridlayout(app.PESToolsPCCPSIPanel);
            app.BZNavi_Button = uibutton(app.PESTools_GridLayout, 'push');
            app.BZNavi_Button.ButtonPushedFcn = createCallbackFcn(app, @BZNavi_ButtonPushed, true);
            app.BZNavi_Button.Text = 'BZNavi';
            app.PESModelling_Button = uibutton(app.PESTools_GridLayout, 'push');
            app.PESModelling_Button.ButtonPushedFcn = createCallbackFcn(app, @PESModelling_ButtonPushed, true);
            app.PESModelling_Button.Text = 'XPS Fitting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ARPEScape

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end