classdef App_ARPEScape_BZNAVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Global_GridLayout         matlab.ui.container.GridLayout
        Image_GridLayout          matlab.ui.container.GridLayout
        ARPES_Geometry_Caption    matlab.ui.control.Label
        ARPES_Geometry_Image      matlab.ui.control.Image
        Crystal_Image_Caption     matlab.ui.control.Label
        Crystal_Image             matlab.ui.control.Image
        GridLayout                matlab.ui.container.GridLayout
        ExtractCut_Panel          matlab.ui.container.Panel
        ExtractCut_GridLayout     matlab.ui.container.GridLayout
        hv_EditField              matlab.ui.control.EditField
        hveVEditFieldLabel        matlab.ui.control.Label
        ViewARPESCut_Button       matlab.ui.control.Button
        ARPESGeometry_Panel       matlab.ui.container.Panel
        ARPESGeometry_GridLayout  matlab.ui.container.GridLayout
        RefKx_EditField           matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_8    matlab.ui.control.Label
        TltM_EditField            matlab.ui.control.NumericEditField
        AtomicweightamuLabel_3    matlab.ui.control.Label
        RefThtA_EditField         matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_7    matlab.ui.control.Label
        ThtM_EditField            matlab.ui.control.NumericEditField
        AtomicweightamuLabel_2    matlab.ui.control.Label
        RefEB_EditField           matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_6    matlab.ui.control.Label
        V000_EditField            matlab.ui.control.NumericEditField
        AtomicweightamuLabel_4    matlab.ui.control.Label
        InciAlpha_EditField       matlab.ui.control.NumericEditField
        bAngLabel_2               matlab.ui.control.Label
        EPhi_EditField            matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_5    matlab.ui.control.Label
        BZSlice_Panel             matlab.ui.container.Panel
        BZSlice_GridLayout        matlab.ui.container.GridLayout
        View2DBZ_Button           matlab.ui.control.Button
        hkl_DropDown              matlab.ui.control.DropDown
        BZSlicehklLabel           matlab.ui.control.Label
        CrysStructure_Panel       matlab.ui.container.Panel
        MatParams_GridLayout      matlab.ui.container.GridLayout
        Orient_DropDown           matlab.ui.control.DropDown
        View3DBZ_Button           matlab.ui.control.Button
        gamma_EditField           matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_3    matlab.ui.control.Label
        c_EditField               matlab.ui.control.NumericEditField
        AtomicweightamuLabel      matlab.ui.control.Label
        beta_EditField            matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_4    matlab.ui.control.Label
        b_EditField               matlab.ui.control.NumericEditField
        bAngLabel                 matlab.ui.control.Label
        alpha_EditField           matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label_2    matlab.ui.control.Label
        a_EditField               matlab.ui.control.NumericEditField
        Bulkdensitygcm3Label      matlab.ui.control.Label
        Crystal_DropDown          matlab.ui.control.DropDown
        CrystalDropDownLabel      matlab.ui.control.Label
        Material_GridLayout       matlab.ui.container.GridLayout
        Material_Lamp             matlab.ui.control.Lamp
        Material_EditField        matlab.ui.control.EditField
        MaterialEditFieldLabel    matlab.ui.control.Label
        BUTTONS_GridLayout        matlab.ui.container.GridLayout
        AppButtons_GridLayout     matlab.ui.container.GridLayout
        SAVE_Button               matlab.ui.control.Button
        TRANSFER_Button           matlab.ui.control.Button
        APPINFO_Button            matlab.ui.control.Button
        RESIZEUI_Button           matlab.ui.control.Button
        RESTART_Button            matlab.ui.control.Button
        CLOSEFIGS_Button          matlab.ui.control.Button
        AppTitle                  matlab.ui.control.Label
        MAINMENU_GridLayout       matlab.ui.container.GridLayout
        MAINMENU_Button           matlab.ui.control.Button
    end

    % Private properties available to the app only
    properties (Access = private)
        % - MATLAB data structure to summarise all data for analysis
        Data_BZNavi
        % - Variables for determining the crystal structure
        material    % material being considered
        crystal     % crystal unit cell being considered
        a           % a lattice constant of crystal unit cell (Angstroms)
        b           % b lattice constant of crystal unit cell (Angstroms)
        c           % c lattice constant of crystal unit cell (Angstroms)
        alpha       % alpha opposite angle of crystal unit cell (degrees)
        beta        % beta opposite angle of crystal unit cell (degrees)
        gamma       % gamma opposite angle of crystal unit cell (degrees)
        orient      % orientation of the crystal
        hkl         % miller indice plane cut (hkl)
        % - Variables for determining the ARPES cuts
        EPhi            % Work function of analyser (eV) (@ADRESS: 4.50 eV)
        V000            % User defined inner potential (eV)
        ThtM            % Primary manipulator rotation (degree) (at normal emission = 0)
        TltM            % Primary tilt rotation (degree) (at normal emission = 0)
        InciAlpha       % Angle between analyser and sample normal (@ADRESS: < July 2022 = 20deg, present = 9deg).
        RefEB           % Reference Binding Energy (eV)
        RefThtA         % Reference Analyser Angle relative to sample normal (degree) (at normal emission = 0)
        RefKx           % Reference Kx value at the angle Ref_ThtA (Angstrom^-1)
        hv              % List of photon energies (eV)
    end
    
    % Private functions available to the app only
    methods (Access = private)
        % - Extract the data structure
        function dataStruc = extract_Data_BZNavi(app)
            dataStruc = struct();
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [dataStruc.real_3D, dataStruc.reciprocal_3D] = get_crystal_props(crystal_args, 0);
            % 3 - Extracting the BZ slice
            dataStruc.bz_slice_2D = get_bz_slice(dataStruc.reciprocal_3D, app.hkl, 0);
            % 4 - Extracting the photon energy cuts
            hvCut = {}; for i = 1:length(app.hv); hvCut{i} = get_hv_cut(app.hv(i)); end
            if length(hvCut) == 1; dataStruc.hv_cut = hvCut{1}; else; dataStruc.hv_cut = hvCut; end
        end

        % - Check the material properties to the user-input and see if the match
        function update_lamp4material(app)
            mat_props = get_mpd_props(app.material);
            % -- If there is an entry for the material
            idx = 0;
            if ~isempty(mat_props)
                % --- Extract the material parameters
                mat_crystal = string(mat_props.CRY_STRUCT{1});
                mat_a       = round(mat_props.CRY_A/100,4);
                mat_b       = round(mat_props.CRY_B/100,4);
                mat_c       = round(mat_props.CRY_C/100,4);
                mat_alpha   = round(mat_props.CRY_ALPHA,4);
                mat_beta    = round(mat_props.CRY_BETA,4);
                mat_gamma   = round(mat_props.CRY_GAMMA,4);
                % --- Extract the user input values
                user_crystal = app.crystal;
                user_a       = round(app.a,4);
                user_b       = round(app.b,4);
                user_c       = round(app.c,4);
                user_alpha   = round(app.alpha,4);
                user_beta    = round(app.beta,4);
                user_gamma   = round(app.gamma,4);
                % --- Compare the material parameters with user inputs
                % --- Comparing crystal structure
                if ~contains(user_crystal, mat_crystal); idx = idx+1; end
                % --- Comparing the crystal parameters
                if user_a ~= mat_a; idx = idx+1; end
                if user_b ~= mat_b; idx = idx+1; end
                if user_c ~= mat_c; idx = idx+1; end
                if user_alpha ~= mat_alpha; idx = idx+1; end
                if user_beta ~= mat_beta; idx = idx+1; end
                if user_gamma ~= mat_gamma; idx = idx+1; end
            % -- If no entry for the material exists
            else; idx = idx+1;
            end
            % -- Assigning lamp color
            if idx == 0; app.Material_Lamp.Color = [0 1 0];
            else; app.Material_Lamp.Color = [1 0 0];
            end
        end

        % - Check the crystal structure to the user-input and see if the match
        function str_crystal_dropdown = update_crystaltype_value(app, str_crystal)
            if contains(str_crystal,"CUB-cP-Oh");        str_crystal_dropdown = app.Crystal_DropDown.Items{1};
            elseif contains(str_crystal,"CUB-cI-Oh");    str_crystal_dropdown = app.Crystal_DropDown.Items{2};
            elseif contains(str_crystal,"CUB-cF-Oh");    str_crystal_dropdown = app.Crystal_DropDown.Items{3};
            elseif contains(str_crystal,"HEX-hP-D6h");   str_crystal_dropdown = app.Crystal_DropDown.Items{4};
            elseif contains(str_crystal,"RHL-hR-D3d");   str_crystal_dropdown = app.Crystal_DropDown.Items{5};
            elseif contains(str_crystal,"TET-tP-D4h");   str_crystal_dropdown = app.Crystal_DropDown.Items{6};
            elseif contains(str_crystal,"TET-tI-D4h");   str_crystal_dropdown = app.Crystal_DropDown.Items{7};
            elseif contains(str_crystal,"ORC-oP-D2h ");  str_crystal_dropdown = app.Crystal_DropDown.Items{8};
            elseif contains(str_crystal,"ORC-oS-D2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{9};
            elseif contains(str_crystal,"ORC-oI-D2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{10};
            elseif contains(str_crystal,"ORC-oF-D2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{11};
            elseif contains(str_crystal,"MCL-mP-C2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{12};
            elseif contains(str_crystal,"MCL-mS-C2h");   str_crystal_dropdown = app.Crystal_DropDown.Items{13};
            elseif contains(str_crystal,"TRI-aP-Ci");    str_crystal_dropdown = app.Crystal_DropDown.Items{14};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'ARPEScape - Brilluoin Zone Navigation App (procopios.constantinou@psi.ch)';
            % 1 - Default parameters upon startup
            app.Data_BZNavi = struct();
            % - Crystal data
            app.material    = app.Material_EditField.Value;
            app.crystal     = app.Crystal_DropDown.Value;
            app.a           = app.a_EditField.Value;        
            app.b           = app.b_EditField.Value;
            app.c           = app.c_EditField.Value;   
            app.alpha       = app.alpha_EditField.Value; 
            app.beta        = app.beta_EditField.Value;
            app.gamma       = app.gamma_EditField.Value;
            app.orient      = app.Orient_DropDown.Value;
            app.hkl         = [0,0,1];
            % - Hv cut data
            app.EPhi        = app.EPhi_EditField.Value;   
            app.V000        = app.V000_EditField.Value;   
            app.ThtM        = app.ThtM_EditField.Value;        
            app.TltM        = app.TltM_EditField.Value;
            app.InciAlpha   = app.InciAlpha_EditField.Value;   
            app.RefEB       = app.RefEB_EditField.Value; 
            app.RefThtA     = app.RefThtA_EditField.Value;
            app.RefKx       = app.RefKx_EditField.Value;
            app.hv          = app.hv_EditField.Value;
            % 2 - Running UI elements
            Material_EditFieldValueChanged(app);
            hkl_DropDownValueChanged(app);
            hv_EditFieldValueChanged(app);
        end

        % Button pushed function: MAINMENU_Button
        function MAINMENU_ButtonPushed(app, event)
            run App_ARPEScape;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            % 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            % 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            % Remove the UI handle and restart
            close(gcbf); run App_ARPEScape_BZNAVI; 
        end

        % Button pushed function: RESIZEUI_Button
        function RESIZEUI_ButtonPushed(app, event)
            % Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 645;
            screen_size(4) = 510;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('App : '); disp(app);
            app.Data_BZNavi = extract_Data_BZNavi(app);
            disp('Data_BZNavi : '); disp(app.Data_BZNavi);
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            app.Data_BZNavi = extract_Data_BZNavi(app);
            assignin('base', 'Data_BZNavi', app.Data_BZNavi);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            app.Data_BZNavi = extract_Data_BZNavi(app);
            dataStruc = app.Data_BZNavi; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.Data_BZNavi);
            close(wbar);
            %% 3 - Print confirmation that data is saved
            waitbar(1,'Save complete!'); 
        end

        % Value changed function: Material_EditField
        function Material_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value           = app.Material_EditField.Value;
            app.material    = string(value);
            % 2 - Extracting the material parameters from the materials database
            mat_props = get_mpd_props(app.material);
            % -- If there is an entry for the material
            if ~isempty(mat_props)
                % --- Extract the material parameters
                mat_crystal = string(mat_props.CRY_STRUCT{1}); if mat_crystal == ""; mat_crystal = app.crystal; end
                mat_a       = mat_props.CRY_A ./ 100;   if isnan(mat_a); mat_a = app.a; end
                mat_b       = mat_props.CRY_B ./ 100;   if isnan(mat_b); mat_b = app.b; end
                mat_c       = mat_props.CRY_C ./ 100;   if isnan(mat_c); mat_c = app.c; end
                mat_alpha   = mat_props.CRY_ALPHA;      if isnan(mat_alpha);  mat_alpha = app.alpha; end
                mat_beta    = mat_props.CRY_BETA;       if isnan(mat_beta);   mat_beta = app.beta; end
                mat_gamma   = mat_props.CRY_GAMMA;      if isnan(mat_gamma);  mat_gamma = app.gamma; end
                % --- Defining the new lattice constants and angles from materials database
                app.crystal = update_crystaltype_value(app, mat_crystal);
                app.a       = round(mat_a,4);
                app.b       = round(mat_b,4);
                app.c       = round(mat_c,4);
                app.alpha   = round(mat_alpha,4);
                app.beta    = round(mat_beta,4);
                app.gamma   = round(mat_gamma,4);
                % --- Updating all UI elements
                app.Material_EditField.Value    = app.material;
                app.Crystal_DropDown.Value      = app.crystal; 
                app.a_EditField.Value           = app.a;
                app.b_EditField.Value           = app.b;
                app.c_EditField.Value           = app.c;
                app.alpha_EditField.Value       = app.alpha;
                app.beta_EditField.Value        = app.beta;
                app.gamma_EditField.Value       = app.gamma;
                % --- Assigning output and printing change
                fprintf("-> material: " + string(app.material) + "\n");
                fprintf("-- crystal: " + string(app.crystal) + "\n");
                % --- Updating UI elements
                Crystal_DropDownValueChanged(app);
                a_EditFieldValueChanged(app);
                b_EditFieldValueChanged(app);
                c_EditFieldValueChanged(app);
                alpha_EditFieldValueChanged(app);
                beta_EditFieldValueChanged(app);
                gamma_EditFieldValueChanged(app);
            % -- If no entry for the material exists
            else
                fprintf("-> material: " + string(app.material) + " - material parameters not in database. \n");
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
        end

        % Value changed function: Orient_DropDown
        function Orient_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.Orient_DropDown.Value;
            app.orient  = string(value);
            % 2 - Assigning output and printing change
            fprintf("-- Orientation = " + app.orient + "\n");
        end

        % Value changed function: Crystal_DropDown
        function Crystal_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value          = app.Crystal_DropDown.Value;
            app.crystal    = string(value);
            % 2 - Set the image of the crystal being used
            if contains(app.crystal,"CUB-cP-Oh");        app.Crystal_Image.ImageSource = 'CUB-cP-Oh_Simple Cubic.png';
            elseif contains(app.crystal,"CUB-cI-Oh");    app.Crystal_Image.ImageSource = 'CUB-cI-Oh_Body-Centered Cubic.png';
            elseif contains(app.crystal,"CUB-cF-Oh");    app.Crystal_Image.ImageSource = 'CUB-cF-Oh_Face-Centered Cubic.png';
            elseif contains(app.crystal,"HEX-hP-D6h");   app.Crystal_Image.ImageSource = 'HEX-hP-D6h_Simple Hexagonal.png';
            elseif contains(app.crystal,"RHL-hR-D3d");   app.Crystal_Image.ImageSource = 'RHL-hR-D3d_Simple Rhombahedral.png';
            elseif contains(app.crystal,"TET-tP-D4h");   app.Crystal_Image.ImageSource = 'TET-tP-D4h_Simple Tetragonal.png';
            elseif contains(app.crystal,"TET-tI-D4h");   app.Crystal_Image.ImageSource = 'TET-tI-D4h_Body-Centered Tetragonal.png';
            elseif contains(app.crystal,"ORC-oP-D2h ");  app.Crystal_Image.ImageSource = 'ORC-oP-D2h_Simple Orthorhombic.png';
            elseif contains(app.crystal,"ORC-oS-D2h");   app.Crystal_Image.ImageSource = 'ORC-oS-D2h_Base-Centered Orthorhombic.png';
            elseif contains(app.crystal,"ORC-oI-D2h");   app.Crystal_Image.ImageSource = 'ORC-oI-D2h_Body-Centered Orthorhombic.png';
            elseif contains(app.crystal,"ORC-oF-D2h");   app.Crystal_Image.ImageSource = 'ORC-oF-D2h_Face-Centered Orthorhombic.png';
            elseif contains(app.crystal,"MCL-mP-C2h");   app.Crystal_Image.ImageSource = 'MCL-mP-C2h_Simple Monoclinic.png';
            elseif contains(app.crystal,"MCL-mS-C2h");   app.Crystal_Image.ImageSource = 'MCL-mS-C2h_Base-Centered Monoclinic.png';
            elseif contains(app.crystal,"TRI-aP-Ci");    app.Crystal_Image.ImageSource = 'TRI-aP-Ci_Simple Triclinic.png';
            end
            % 3 - Applying symmetry arguments to the inputs
            offset = 0;
            % For 'CUB', 'BCC' and 'FCC' crystal
            if contains(app.crystal,"CUB")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b & c
                if app.a ~= app.b; app.b = app.a; end
                if app.a ~= app.c; app.c = app.a; end
                % - alpha = beta = gamma = 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b ONLY
                if app.a ~= app.b; app.b = app.a; end
                % - alpha = beta = 90, gamma = 120
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 120; app.gamma = 120; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b & c
                if app.a ~= app.b; app.b = app.a; end
                if app.a ~= app.c; app.c = app.a; end
                % - alpha = beta = gamma = arbitrary
                if app.beta ~= app.alpha;  app.beta = app.alpha; end
                if app.gamma ~= app.alpha; app.gamma = app.alpha; end
            % For 'TET' crystals
            elseif contains(app.crystal,"TET")
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a == b AND a =/= c
                if app.a == app.c; app.c = app.a - offset; end
                if app.a ~= app.b; app.b = app.a; end
                % - alpha = beta = gamma = 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'ORC' crystals
            elseif contains(app.crystal,"ORC")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
                % - a =/= b AND a =/= c
                if app.a == app.b; app.b = app.a - offset; end
                if app.a == app.c; app.c = app.a - offset; end
                % - alpha = beta = gamma = 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90;  app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'off';
                % - a =/= c ONLY
                if app.a == app.c; app.c = app.a - offset; end
                % - alpha = gamma = 90, beta = arbitrary
                if app.alpha ~= 90; app.alpha = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % -- Symmetry rules
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'on';
            end
            % 4 - Applying symmetry arguments to the inputs
            app.a_EditField.Value       = app.a;
            app.b_EditField.Value       = app.b;
            app.c_EditField.Value       = app.c;
            app.alpha_EditField.Value   = app.alpha;
            app.beta_EditField.Value    = app.beta;
            app.gamma_EditField.Value   = app.gamma;
            % 5 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
        end

        % Value changed function: a_EditField
        function a_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value   = app.a_EditField.Value;
            app.a   = value;
            % 2 - Symmetry rules for the crystals
            offset = 0.000;
            % For 'CUB', 'BCC' and 'FCC' crystal
            if contains(app.crystal,"CUB")
                % - a == b & c
                if app.a ~= app.b; app.b = app.a; end
                if app.a ~= app.c; app.c = app.a; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % - a == b ONLY
                if app.a ~= app.b; app.b = app.a; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % - a == b & c
                if app.a ~= app.b; app.b = app.a; end
                if app.a ~= app.c; app.c = app.a; end
            % For 'TET' crystals
            elseif contains(app.crystal,"TET")
                % - a == b AND a =/= c
                if app.a ~= app.b; app.b = app.a; end
                if app.a == app.c; app.c = app.a - offset; end
            % For 'ORC' crystals
            elseif contains(app.crystal,"ORC")
                % - a =/= b AND a =/= c
                if app.a == app.b; app.b  = app.a - offset; end
                if app.a == app.c; app.c = app.a - offset; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % - a =/= c ONLY
                if app.a == app.c; app.c = app.c - offset; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % - No conditions for the 'TRI' crystal for a
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 4 - Assigning output and printing change
            fprintf("-- a = " + string(app.a) + " Angstrom\n");
            % 5 - Updating all other relevant fields
            app.a_EditField.Value = app.a;
            app.b_EditField.Value = app.b;
            app.c_EditField.Value = app.c;
        end

        % Value changed function: b_EditField
        function b_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.b_EditField.Value;
            app.b   = value;
            % 2 - Symmetry rules for the crystals
            offset = 0.000;
            % For 'CUB', 'BCC' and 'FCC' crystal
            if contains(app.crystal,"CUB")
                % - b == a & c
                if app.b ~= app.a; app.a = app.b; end
                if app.b ~= app.c; app.c = app.b; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % - b == a ONLY
                if app.b ~= app.a; app.a = app.b; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % - b == a & c
                if app.b ~= app.a; app.a = app.b; end
                if app.b ~= app.c; app.c = app.b; end
            % For 'TET' crystals
            elseif contains(app.crystal,"TET")
                % - b == a AND b =/= c
                if app.b ~= app.a; app.a = app.b; end
                if app.b == app.c; app.c = app.b - offset; end
            % For 'ORC' crystals
            elseif contains(app.crystal,"ORC")
                % - b =/= a AND b =/= c
                if app.b == app.a; app.a = app.b - offset; end
                if app.b == app.c; app.c = app.b - offset; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % - No conditions for the 'MCL' crystal for b
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % - No conditions for the 'TRI' crystal for a
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 4 - Assigning output and printing change
            fprintf("-- b = " + string(app.b) + " Angstrom\n");
            % 5 - Updating all other relevant fields
            app.a_EditField.Value = app.a;
            app.b_EditField.Value = app.b;
            app.c_EditField.Value = app.c;
        end

        % Value changed function: c_EditField
        function c_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.c_EditField.Value;
            app.c   = value;
            % 2 - Symmetry rules for the crystals
            offset = 0.000;
            % For 'CUB', 'BCC' and 'FCC' crystal
            if contains(app.crystal,"CUB")
                % - c == a & b
                if app.c ~= app.a; app.a = app.c; end
                if app.c ~= app.b; app.b = app.c; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % - No conditions for the 'HEX' crystal for c
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % - c == a & b
                if app.c ~= app.a; app.a = app.c; end
                if app.c ~= app.b; app.b = app.c; end
            % For 'TET' crystals
            elseif contains(app.crystal,"TET")
                % - c =/= a
                if app.c == app.a; app.a = app.c - offset; end
            % For 'ORC' crystals
            elseif contains(app.crystal,"ORC")
                % - c =/= a
                if app.c == app.a; app.a = app.c - offset; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % - c =/= a
                if app.c == app.a; app.a = app.c - offset; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % - No conditions for the 'TRI' crystal for c
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 4 - Assigning output and printing change
            fprintf("-- c = " + string(app.c) + " Angstrom \n");
            % 5 - Updating all other relevant fields
            app.a_EditField.Value = app.a;
            app.b_EditField.Value = app.b;
            app.c_EditField.Value = app.c;
        end

        % Value changed function: alpha_EditField
        function alpha_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.alpha_EditField.Value;
            app.alpha   = value;
            % 2 - Symmetry rules for the crystals
            offset = 0.000;
            % For 'CUB', 'BCC' and 'FCC' crystals, or 'TET' or 'ORC' crystals
            if contains(app.crystal,"CUB") || contains(app.crystal,"TET") || contains(app.crystal,"ORC")
                % - alpha == beta == gamma == 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90; app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % - alpha == beta == 90, gamma == 120
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90; app.beta = 90; end
                if app.gamma ~= 120; app.gamma = 120; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % - alpha == beta == gamma =/= 90
                if app.beta ~= app.alpha; app.beta = app.alpha; end
                if app.gamma ~= app.alpha; app.gamma = app.alpha; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % - alpha == gamma == 90, beta =/= 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % - No conditions for the 'TRI' crystal for alpha
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 4 - Assigning output and printing change
            fprintf("-- alpha = " + string(app.alpha) + "deg \n");
            % 5 - Updating all other relevant fields
            app.alpha_EditField.Value = app.alpha;
            app.beta_EditField.Value = app.beta;
            app.gamma_EditField.Value = app.gamma;
        end

        % Value changed function: beta_EditField
        function beta_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.beta_EditField.Value;
            app.beta    = value;
            % 2 - Symmetry rules for the crystals
            offset = 0.000;
            % For 'CUB', 'BCC' and 'FCC' crystals, or 'TET' or 'ORC' crystals
            if contains(app.crystal,"CUB") || contains(app.crystal,"TET") || contains(app.crystal,"ORC")
                % - alpha == beta == gamma == 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90; app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % - alpha == beta == 90, gamma == 120
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90; app.beta = 90; end
                if app.gamma ~= 120; app.gamma = 120; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % - alpha == beta == gamma =/= 90
                if app.alpha ~= app.beta; app.alpha = app.beta; end
                if app.gamma ~= app.beta; app.gamma = app.beta; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % - alpha == gamma == 90, beta =/= 90
                if app.beta == 90; app.beta = 90 - offset; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % - No conditions for the 'TRI' crystal for alpha
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 4 - Assigning output and printing change
            fprintf("-- beta = " + string(app.beta) + "deg\n");
            % 5 - Updating all other relevant fields
            app.alpha_EditField.Value = app.alpha;
            app.beta_EditField.Value = app.beta;
            app.gamma_EditField.Value = app.gamma;
        end

        % Value changed function: gamma_EditField
        function gamma_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.gamma_EditField.Value;
            app.gamma   = value;
            % 2 - Symmetry rules for the crystals
            offset = 0.000;
            % For 'CUB', 'BCC' and 'FCC' crystals, or 'TET' or 'ORC' crystals
            if contains(app.crystal,"CUB") || contains(app.crystal,"TET") || contains(app.crystal,"ORC")
                % - alpha == beta == gamma == 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90; app.beta = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'HEX' crystal
            elseif contains(app.crystal,"HEX")
                % - alpha == beta == 90, gamma == 120
                if app.alpha ~= 90; app.alpha = 90; end
                if app.beta ~= 90; app.beta = 90; end
                if app.gamma ~= 120; app.gamma = 120; end
            % For 'RHL' crystal
            elseif contains(app.crystal,"RHL")
                % - alpha == beta == gamma =/= 90
                if app.alpha ~= app.gamma; app.alpha = app.gamma; end
                if app.beta ~= app.gamma; app.beta = app.gamma; end
            % For 'MCL' crystals
            elseif contains(app.crystal,"MCL")
                % - alpha == gamma == 90, beta =/= 90
                if app.alpha ~= 90; app.alpha = 90; end
                if app.gamma ~= 90; app.gamma = 90; end
            % For 'TRI' crystal
            elseif contains(app.crystal,"TRI")
                % - No conditions for the 'TRI' crystal for alpha
            end
            % 3 - Updating whether material matches cystallographic inputs
            update_lamp4material(app);
            % 4 - Assigning output and printing change
            fprintf("-- gamma = " + string(app.gamma) + "deg\n");
            % 5 - Updating all other relevant fields
            app.alpha_EditField.Value = app.alpha;
            app.beta_EditField.Value = app.beta;
            app.gamma_EditField.Value = app.gamma;
        end

        % Button pushed function: View3DBZ_Button
        function View3DBZ_ButtonPushed(app, event)
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [~, ~] = get_crystal_props(crystal_args, 1);
        end

        % Value changed function: hkl_DropDown
        function hkl_DropDownValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value       = app.hkl_DropDown.Value;
            str_hkl     = string(value);
            % 2 - Extracting the input defined by the user
            if strcmpi(str_hkl, "(100)");       app.hkl = [1,0,0];
            elseif strcmpi(str_hkl, "(010)");   app.hkl = [0,1,0];
            elseif strcmpi(str_hkl, "(001)");   app.hkl = [0,0,1];
            elseif strcmpi(str_hkl, "(110)");   app.hkl = [1,1,0];
            end
            % 3 - Assigning output and printing change
            fprintf("-- (hkl) = " + str_hkl + "\n");
        end

        % Button pushed function: View2DBZ_Button
        function View2DBZ_ButtonPushed(app, event)
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [~, reciStr] = get_crystal_props(crystal_args, 0);
            % 3 - Extracting the BZ slice
            get_bz_slice(reciStr, app.hkl, 1);
        end

        % Value changed function: EPhi_EditField
        function EPhi_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.EPhi_EditField.Value;
            app.EPhi   = value;
            % 2 - Assigning output and printing change
            fprintf("-- EPhi = " + string(app.EPhi) + " eV \n");
        end

        % Value changed function: InciAlpha_EditField
        function InciAlpha_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.InciAlpha_EditField.Value;
            app.InciAlpha   = value;
            % 2 - Assigning output and printing change
            fprintf("-- InciAlpha = " + string(app.InciAlpha) + "deg. \n");
        end

        % Value changed function: V000_EditField
        function V000_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.V000_EditField.Value;
            app.V000   = value;
            % 2 - Assigning output and printing change
            fprintf("-- V000 = " + string(app.V000) + " eV \n");
        end

        % Value changed function: RefEB_EditField
        function RefEB_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.RefEB_EditField.Value;
            app.RefEB   = value;
            % 2 - Assigning output and printing change
            fprintf("-- RefEB = " + string(app.RefEB) + " eV \n");
        end

        % Value changed function: ThtM_EditField
        function ThtM_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.ThtM_EditField.Value;
            app.ThtM   = value;
            % 2 - Assigning output and printing change
            fprintf("-- ThtM = " + string(app.ThtM) + "deg. \n");
        end

        % Value changed function: RefThtA_EditField
        function RefThtA_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.RefThtA_EditField.Value;
            app.RefThtA   = value;
            % 2 - Assigning output and printing change
            fprintf("-- RefThtA = " + string(app.RefThtA) + "deg. \n");
        end

        % Value changed function: TltM_EditField
        function TltM_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.TltM_EditField.Value;
            app.TltM   = value;
            % 2 - Assigning output and printing change
            fprintf("-- TltM = " + string(app.TltM) + "deg. \n");
        end

        % Value changed function: RefKx_EditField
        function RefKx_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.RefKx_EditField.Value;
            app.RefKx   = value;
            % 2 - Assigning output and printing change
            fprintf("-- RefKx = " + string(app.RefKx) + " Angstrom^-1 \n");
        end

        % Value changed function: hv_EditField
        function hv_EditFieldValueChanged(app, event)
            % 1 - Extracting the input defined by the user
            value = app.hv_EditField.Value;
            data_entry = round(sort(str2double(split(value,','))), 4);
            if isnan(data_entry); data_entry = round(sort(str2double(split(value,';'))), 4); end
            if isnan(data_entry); data_entry = round(sort(str2double(split(value,':'))), 4); end
            % 2 - Validity check on input
            minVal = 8; maxVal = 1e6;
            % - If no entry is made, default to a single hv value
            phoEne = [];
            if isempty(data_entry); phoEne = 500;
            % - File through all entered values and extract relevant ones only
            else
                data_entry = data_entry(~isnan(data_entry));
                if isempty(data_entry); phoEne = 500;
                else
                    for i = 1:length(data_entry)
                        if data_entry(i) < minVal; data_entry(i) = minVal;
                        elseif data_entry(i) > maxVal; data_entry(i) = maxVal; 
                        end
                        phoEne(end+1) = data_entry(i);
                    end
                end
            end
            % 3 - Assigning and printing the output
            app.hv = unique(sort(phoEne(:)));
            if length(app.hv) == 1
                app.hv_EditField.Value = string(app.hv); 
                fprintf("-- hv = " + string(app.hv) + " eV \n");
            elseif length(app.hv) > 1
                hvStr = sprintf('%.0f,' , app.hv); hvStr = hvStr(1:end-1);
                app.hv_EditField.Value = hvStr;
                fprintf("--> hv (eV): " + hvStr + " eV \n");
            end
        end

        % Button pushed function: ViewARPESCut_Button
        function ViewARPESCut_ButtonPushed(app, event)
            % 1 - Extracting the crystal orientation
            if app.orient == "<100>"
                abc         = [app.a,app.b,app.c];
                angles      = [app.alpha,app.beta,app.gamma];
            elseif app.orient == "<010>"
                abc         = [app.c,app.a,app.b];
                angles      = [app.gamma,app.alpha,app.beta];
            elseif app.orient == "<001>"
                abc         = [app.b,app.c,app.a];
                angles      = [app.beta,app.gamma,app.alpha];
            end
            % 2 - Extracting the crystal properties
            crystal_args = {app.crystal, abc, angles};
            [~, reciStr] = get_crystal_props(crystal_args, 0);
            % 3 - Extracting the BZ slice
            bzSlice = get_bz_slice(reciStr, app.hkl, 0);
            % 4 - Extracting the photon energy cuts
            hvCut = {}; for i = 1:length(app.hv); hvCut{i} = get_hv_cut(app.hv(i)); end
            % 5 - Plotting the final ARPES cuts
            figX = figure(); set(figX, 'Position', [100, 100, 400, 600]);
            hold on;
            plot_bz_slice(bzSlice);
            plot_hv_cut(hvCut);
            gca_props(); 
            box on; axis equal;
            axis([-5,5,5,25]);
            xlabel('$$ \bf  k_{\parallel} (\AA^{-1}) $$', 'Interpreter', 'latex');
            ylabel('$$ \bf  k_{\perp} (\AA^{-1}) $$', 'Interpreter', 'latex');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Global_GridLayout = uigridlayout(app.UIFigure);
            app.MAINMENU_GridLayout = uigridlayout(app.Global_GridLayout);
            app.MAINMENU_Button = uibutton(app.MAINMENU_GridLayout, 'push');
            app.MAINMENU_Button.ButtonPushedFcn = createCallbackFcn(app, @MAINMENU_ButtonPushed, true);
            app.MAINMENU_Button.Tooltip = {'Go back to the Main Menu.'};
            app.MAINMENU_Button.Text = 'MAIN MENU';
            app.AppTitle = uilabel(app.Global_GridLayout);
            app.AppTitle.Text = 'Brilluoin Zone Navigation';
            app.BUTTONS_GridLayout = uigridlayout(app.Global_GridLayout);
            app.AppButtons_GridLayout = uigridlayout(app.BUTTONS_GridLayout);
            app.CLOSEFIGS_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Close all open figures.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Close and restart the UI.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEUI_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.RESIZEUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEUI_ButtonPushed, true);
            app.RESIZEUI_Button.Tooltip = {'Resize the UI to its default size.'};
            app.RESIZEUI_Button.Text = 'RESIZE UI';
            app.APPINFO_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.TRANSFER_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.SAVE_Button = uibutton(app.AppButtons_GridLayout, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB data structure file.'};
            app.SAVE_Button.Text = 'Save As... (.mat)';
            app.GridLayout = uigridlayout(app.Global_GridLayout);
            app.Material_GridLayout = uigridlayout(app.GridLayout);
            app.MaterialEditFieldLabel = uilabel(app.Material_GridLayout);
            app.MaterialEditFieldLabel.Text = 'Material:';
            app.Material_EditField = uieditfield(app.Material_GridLayout, 'text');
            app.Material_EditField.ValueChangedFcn = createCallbackFcn(app, @Material_EditFieldValueChanged, true);
            app.Material_EditField.Tooltip = {'Enter an element / compound to search for the crystal structure parameters in the Materials Properties Database.'};
            app.Material_EditField.Value = 'Si';
            app.Material_Lamp = uilamp(app.Material_GridLayout);
            app.Material_Lamp.Tooltip = {'If green, input parameters match the Materials Properties Database.'};
            app.CrysStructure_Panel = uipanel(app.GridLayout);
            app.CrysStructure_Panel.Title = 'Crystal Structure';
            app.MatParams_GridLayout = uigridlayout(app.CrysStructure_Panel);
            app.CrystalDropDownLabel = uilabel(app.MatParams_GridLayout);
            app.CrystalDropDownLabel.Text = 'Crystal:';
            app.Crystal_DropDown = uidropdown(app.MatParams_GridLayout);
            app.Crystal_DropDown.Items = {'CUB-cP-Oh : Simple Cubic', 'CUB-cI-Oh : Body-Centered Cubic', 'CUB-cF-Oh : Face-Centered Cubic', 'HEX-hP-D6h : Simple Hexagonal', 'RHL-hR-D3d : Simple Rhombahedral', 'TET-tP-D4h : Simple Tetragonal', 'TET-tI-D4h : Body-Centered Tetragonal', 'ORC-oP-D2h : Simple Orthorhombic', 'ORC-oS-D2h : Base-Centered Orthorhombic', 'ORC-oI-D2h : Body-Centered Orthorhombic', 'ORC-oF-D2h : Face-Centered Orthorhombic', 'MCL-mP-C2h : Simple Monoclinic', 'MCL-mS-C2h : Base-Centered Monoclinic', 'TRI-aP-Ci : Simple Triclinic'};
            app.Crystal_DropDown.ValueChangedFcn = createCallbackFcn(app, @Crystal_DropDownValueChanged, true);
            app.Crystal_DropDown.Tooltip = {'Select the crystal type.'};
            app.Crystal_DropDown.Value = 'CUB-cF-Oh : Face-Centered Cubic';
            app.Bulkdensitygcm3Label = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label.Text = 'a (Ang.):';
            app.a_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.a_EditField.Limits = [0 Inf];
            app.a_EditField.ValueDisplayFormat = '%.4f';
            app.a_EditField.ValueChangedFcn = createCallbackFcn(app, @a_EditFieldValueChanged, true);
            app.a_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_2 = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label_2.Text = 'alpha (deg.):';
            app.alpha_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.alpha_EditField.Limits = [-180 180];
            app.alpha_EditField.ValueDisplayFormat = '%.4f';
            app.alpha_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_EditFieldValueChanged, true);
            app.alpha_EditField.Value = 90;
            app.bAngLabel = uilabel(app.MatParams_GridLayout);
            app.bAngLabel.Text = 'b (Ang.):';
            app.b_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.b_EditField.Limits = [0 Inf];
            app.b_EditField.ValueDisplayFormat = '%.4f';
            app.b_EditField.ValueChangedFcn = createCallbackFcn(app, @b_EditFieldValueChanged, true);
            app.b_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_4 = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label_4.Text = 'beta (deg.):';
            app.beta_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.beta_EditField.Limits = [-180 180];
            app.beta_EditField.ValueDisplayFormat = '%.4f';
            app.beta_EditField.ValueChangedFcn = createCallbackFcn(app, @beta_EditFieldValueChanged, true);
            app.beta_EditField.Value = 90;
            app.AtomicweightamuLabel = uilabel(app.MatParams_GridLayout);
            app.AtomicweightamuLabel.Text = 'c (Ang.):';
            app.c_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.c_EditField.Limits = [0 Inf];
            app.c_EditField.ValueDisplayFormat = '%.4f';
            app.c_EditField.ValueChangedFcn = createCallbackFcn(app, @c_EditFieldValueChanged, true);
            app.c_EditField.Value = 5.431;
            app.Bulkdensitygcm3Label_3 = uilabel(app.MatParams_GridLayout);
            app.Bulkdensitygcm3Label_3.Text = 'gamma (deg.):';
            app.gamma_EditField = uieditfield(app.MatParams_GridLayout, 'numeric');
            app.gamma_EditField.Limits = [-180 180];
            app.gamma_EditField.ValueDisplayFormat = '%.4f';
            app.gamma_EditField.ValueChangedFcn = createCallbackFcn(app, @gamma_EditFieldValueChanged, true);
            app.gamma_EditField.Value = 90;
            app.View3DBZ_Button = uibutton(app.MatParams_GridLayout, 'push');
            app.View3DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @View3DBZ_ButtonPushed, true);
            app.View3DBZ_Button.Tooltip = {'Extract and view the 3D real-space and reciprocal-space structures.'};
            app.View3DBZ_Button.Text = 'View 3D Crystal and BZ';
            app.Orient_DropDown = uidropdown(app.MatParams_GridLayout);
            app.Orient_DropDown.Items = {'<100>', '<010>', '<001>'};
            app.Orient_DropDown.ValueChangedFcn = createCallbackFcn(app, @Orient_DropDownValueChanged, true);
            app.Orient_DropDown.Tooltip = {'Orientation of the crystal.'};
            app.Orient_DropDown.Value = '<100>';
            app.BZSlice_Panel = uipanel(app.GridLayout);
            app.BZSlice_Panel.Title = 'Brilluoin-Zone Slice';
            app.BZSlice_GridLayout = uigridlayout(app.BZSlice_Panel);
            app.BZSlicehklLabel = uilabel(app.BZSlice_GridLayout);
            app.BZSlicehklLabel.Text = 'Planar Slice (hkl):';
            app.hkl_DropDown = uidropdown(app.BZSlice_GridLayout);
            app.hkl_DropDown.Items = {'(100)', '(010)', '(001)', '(110)'};
            app.hkl_DropDown.ValueChangedFcn = createCallbackFcn(app, @hkl_DropDownValueChanged, true);
            app.hkl_DropDown.Tooltip = {'Plane slice through the 3D Brilluoin zone to be mapped.'};
            app.hkl_DropDown.Value = '(100)';
            app.View2DBZ_Button = uibutton(app.BZSlice_GridLayout, 'push');
            app.View2DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @View2DBZ_ButtonPushed, true);
            app.View2DBZ_Button.Tooltip = {'Extract and view the 2D Brilluoin Zone slice.'};
            app.View2DBZ_Button.Text = 'View 2D BZ Slice';
            app.ARPESGeometry_Panel = uipanel(app.GridLayout);
            app.ARPESGeometry_Panel.Title = 'ARPES Geometry';
            app.ARPESGeometry_GridLayout = uigridlayout(app.ARPESGeometry_Panel);
            app.Bulkdensitygcm3Label_5 = uilabel(app.ARPESGeometry_GridLayout);
            app.Bulkdensitygcm3Label_5.Text = 'EPhi (eV):';
            app.EPhi_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.EPhi_EditField.Limits = [0 Inf];
            app.EPhi_EditField.ValueDisplayFormat = '%.4f';
            app.EPhi_EditField.ValueChangedFcn = createCallbackFcn(app, @EPhi_EditFieldValueChanged, true);
            app.EPhi_EditField.Value = 4.5;
            app.bAngLabel_2 = uilabel(app.ARPESGeometry_GridLayout);
            app.bAngLabel_2.Text = 'InciAlpha (deg.):';
            app.InciAlpha_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.InciAlpha_EditField.Limits = [-180 180];
            app.InciAlpha_EditField.ValueDisplayFormat = '%.4f';
            app.InciAlpha_EditField.ValueChangedFcn = createCallbackFcn(app, @InciAlpha_EditFieldValueChanged, true);
            app.InciAlpha_EditField.Value = 9;
            app.AtomicweightamuLabel_4 = uilabel(app.ARPESGeometry_GridLayout);
            app.AtomicweightamuLabel_4.Text = 'V000 (eV):';
            app.V000_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.V000_EditField.Limits = [0 Inf];
            app.V000_EditField.ValueDisplayFormat = '%.4f';
            app.V000_EditField.ValueChangedFcn = createCallbackFcn(app, @V000_EditFieldValueChanged, true);
            app.V000_EditField.Value = 12.57;
            app.Bulkdensitygcm3Label_6 = uilabel(app.ARPESGeometry_GridLayout);
            app.Bulkdensitygcm3Label_6.Text = 'Ref_EB (eV):';
            app.RefEB_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.RefEB_EditField.ValueDisplayFormat = '%.4f';
            app.RefEB_EditField.ValueChangedFcn = createCallbackFcn(app, @RefEB_EditFieldValueChanged, true);
            app.AtomicweightamuLabel_2 = uilabel(app.ARPESGeometry_GridLayout);
            app.AtomicweightamuLabel_2.Text = 'ThtM (deg.)';
            app.ThtM_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.ThtM_EditField.ValueDisplayFormat = '%.4f';
            app.ThtM_EditField.ValueChangedFcn = createCallbackFcn(app, @ThtM_EditFieldValueChanged, true);
            app.Bulkdensitygcm3Label_7 = uilabel(app.ARPESGeometry_GridLayout);
            app.Bulkdensitygcm3Label_7.Text = 'Ref_ThtA (deg.):';
            app.RefThtA_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.RefThtA_EditField.Limits = [-180 180];
            app.RefThtA_EditField.ValueDisplayFormat = '%.4f';
            app.RefThtA_EditField.ValueChangedFcn = createCallbackFcn(app, @RefThtA_EditFieldValueChanged, true);
            app.AtomicweightamuLabel_3 = uilabel(app.ARPESGeometry_GridLayout);
            app.AtomicweightamuLabel_3.Text = 'TltM (deg.):';
            app.TltM_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.TltM_EditField.ValueDisplayFormat = '%.4f';
            app.TltM_EditField.ValueChangedFcn = createCallbackFcn(app, @TltM_EditFieldValueChanged, true);
            app.Bulkdensitygcm3Label_8 = uilabel(app.ARPESGeometry_GridLayout);
            app.Bulkdensitygcm3Label_8.Text = 'Ref_Kx (Ang.):)';
            app.RefKx_EditField = uieditfield(app.ARPESGeometry_GridLayout, 'numeric');
            app.RefKx_EditField.ValueDisplayFormat = '%.4f';
            app.RefKx_EditField.ValueChangedFcn = createCallbackFcn(app, @RefKx_EditFieldValueChanged, true);
            app.ExtractCut_Panel = uipanel(app.GridLayout);
            app.ExtractCut_Panel.Title = 'Extracting ARPES Cut';
            app.ExtractCut_GridLayout = uigridlayout(app.ExtractCut_Panel);
            app.ViewARPESCut_Button = uibutton(app.ExtractCut_GridLayout, 'push');
            app.ViewARPESCut_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewARPESCut_ButtonPushed, true);
            app.ViewARPESCut_Button.Tooltip = {'Extract and view the 2D Brilluoin Zone slice.'};
            app.ViewARPESCut_Button.Text = 'View ARPES Cut';
            app.hveVEditFieldLabel = uilabel(app.ExtractCut_GridLayout);
            app.hveVEditFieldLabel.Text = 'hv (eV):';
            app.hv_EditField = uieditfield(app.ExtractCut_GridLayout, 'text');
            app.hv_EditField.ValueChangedFcn = createCallbackFcn(app, @hv_EditFieldValueChanged, true);
            app.hv_EditField.Value = '500';
            app.Image_GridLayout = uigridlayout(app.Global_GridLayout);
            app.Crystal_Image = uiimage(app.Image_GridLayout);
            app.Crystal_Image.Tooltip = {'Image of the real-space unit cell selected.'};
            app.Crystal_Image.ImageSource = 'CUB-cF-Oh_Face-Centered Cubic.png';
            app.Crystal_Image_Caption = uilabel(app.Image_GridLayout);
            app.Crystal_Image_Caption.Text = 'Real-space unit-cell';
            app.ARPES_Geometry_Image = uiimage(app.Image_GridLayout);
            app.ARPES_Geometry_Image.ImageSource = 'arpes_geometry.png';
            app.ARPES_Geometry_Caption = uilabel(app.Image_GridLayout);
            app.ARPES_Geometry_Caption.Text = 'Experimental Geometry';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ARPEScape_BZNAVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end